classdef dockChannelsSatellite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        FeixeDownListLabel_2       matlab.ui.control.Label
        RefLocation                matlab.ui.control.ListBox
        FeixeDownList              matlab.ui.control.ListBox
        PolarizationList           matlab.ui.control.ListBox
        FeixeDownListLabel         matlab.ui.control.Label
        PolarizationListLabel      matlab.ui.control.Label
        SatelliteID                matlab.ui.control.DropDown
        SatelliteIDLabel           matlab.ui.control.Label
        AddChannelButton           matlab.ui.control.Button
        ARQUIVOLIDOEditField       matlab.ui.control.EditField
        ARQUIVOLIDOEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initialValues(app, fileFullPath, channels)
            app.ARQUIVOLIDOEditField.Value = fileFullPath;

            satelliteList = cellstr(unique(channels.DESIG_INT));
            if isscalar(satelliteList)
                app.SatelliteID.Items = satelliteList;
            else
                app.SatelliteID.Items = [{''}; satelliteList];
            end

            onSatelliteIDValueChanged(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, fileFullPath, specData, channels)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs = struct('context', context, 'specData', specData, 'channels', channels);
                initialValues(app, fileFullPath, channels)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Value changed function: SatelliteID
        function onSatelliteIDValueChanged(app, event)

            if ~isempty(app.SatelliteID.Value)
                channels = app.inputArgs.channels;
                satelliteIdx = strcmp(cellstr(channels.DESIG_INT), app.SatelliteID.Value);
                app.PolarizationList.Items = cellstr(unique(channels.FEIXE_POLARIZ_DOWN(satelliteIdx)));
                app.PolarizationList.Value = app.PolarizationList.Items(1);
                
            else
                app.FeixeDownList.Items    = {};
                app.PolarizationList.Items = {};
            end

            onPolarizationValueChanged(app)
            
        end

        % Value changed function: PolarizationList
        function onPolarizationValueChanged(app, event)
            
            if ~isempty(app.PolarizationList.Value)
                channels = app.inputArgs.channels;
                feixeIdx = strcmp(cellstr(channels.DESIG_INT), app.SatelliteID.Value) & ...
                           ismember(cellstr(channels.FEIXE_POLARIZ_DOWN), app.PolarizationList.Value);

                app.FeixeDownList.Items = cellstr(unique(channels.FEIXE_DOWN(feixeIdx)));
                app.FeixeDownList.Value = app.FeixeDownList.Items(1);

            else
                app.FeixeDownList.Items = {};
            end

            onFeixeListValueChanged(app)
            
        end

        % Value changed function: FeixeDownList
        function onFeixeListValueChanged(app, event)
            
            if isempty(app.FeixeDownList.Value)
                app.RefLocation.Items = {};

            else
                channels = app.inputArgs.channels;
                channelIdxs = ...
                    strcmp(cellstr(channels.DESIG_INT), app.SatelliteID.Value) & ...
                    ismember(cellstr(channels.FEIXE_POLARIZ_DOWN), app.PolarizationList.Value) & ...
                    ismember(cellstr(channels.FEIXE_DOWN), app.FeixeDownList.Value);

                if any(channelIdxs)
                    channels = channels(channelIdxs, :);
                    channelList = arrayfun(@(x, y) util.HtmlTextGenerator.createTag('Channel', x, y), channels.FREQ_CENTRAL_DOWN, channels.BW, 'UniformOutput', false);
                else
                    channelList = {};
                end

                app.RefLocation.Items = channelList;
            end

            app.AddChannelButton.Enable = ~isempty(app.RefLocation.Items);
            
        end

        % Button pushed function: AddChannelButton
        function onAddButtonClicked(app, event)
            
            specData = app.inputArgs.specData;

            pushedButtonTag = event.Source.Tag;
            switch pushedButtonTag
                case 'OK'
                    channels = app.inputArgs.channels;
                    channelIdx = strcmp(cellstr(channels.DESIG_INT), app.SatelliteID.Value)      & ...
                                 ismember(cellstr(channels.FEIXE_DOWN), app.FeixeDownList.Value) & ...
                                 ismember(cellstr(channels.FEIXE_POLARIZ_DOWN), app.PolarizationList.Value);
                    channelList = class.EMSatDataHubLib.importSatelliteChannels(channels(channelIdx, :));

                    questionMsg = sprintf([ ...
                        'Foram extraídos os registros %s, os quais serão incluídos na lista de canais manuais do ' ...
                        'fluxo espectral selecionado, caso se sobreponham à faixa de frequência, substituindo '    ...
                        'eventuais canalizações inseridas manualmente que tenham um dos supracitados nomes.\n\n'   ...
                        'Deseja analisar a inclusão desses registros para os outros fluxos?' ...
                    ], strjoin({channelList.Name}, ', '));
                    userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Sim', 'Não'}, 2, 2);

                    switch userSelection
                        case 'Não'
                            idxThreads = app.idxThread;
                        case 'Sim'
                            specData = app.mainApp.specData;
                    end
                    typeOfChannel  = 'manual';    

                    inputArguments = {channelList, typeOfChannel, idxThreads};
                    updateFlag = true;                    

                case 'Close'
                    inputArguments = {};
                    updateFlag = false;
            end

            callingMainApp(app, updateFlag, false, inputArguments{:})
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ARQUIVOLIDOEditFieldLabel = uilabel(app.GridLayout);
            app.ARQUIVOLIDOEditFieldLabel.Text = 'ARQUIVO LIDO:';
            app.ARQUIVOLIDOEditField = uieditfield(app.GridLayout, 'text');
            app.ARQUIVOLIDOEditField.Editable = 'off';
            app.AddChannelButton = uibutton(app.GridLayout, 'push');
            app.AddChannelButton.ButtonPushedFcn = createCallbackFcn(app, @onAddButtonClicked, true);
            app.AddChannelButton.Icon = 'Add_16.png';
            app.AddChannelButton.Enable = 'off';
            app.AddChannelButton.Text = 'Incluir';
            app.SatelliteIDLabel = uilabel(app.GridLayout);
            app.SatelliteIDLabel.Interpreter = 'html';
            app.SatelliteIDLabel.Text = {'Satélite:'; '<font style="color: gray; font-size: 9px;">(DESIG_INT)</font>'};
            app.SatelliteID = uidropdown(app.GridLayout);
            app.SatelliteID.Items = {};
            app.SatelliteID.ValueChangedFcn = createCallbackFcn(app, @onSatelliteIDValueChanged, true);
            app.SatelliteID.Value = {};
            app.PolarizationListLabel = uilabel(app.GridLayout);
            app.PolarizationListLabel.Interpreter = 'html';
            app.PolarizationListLabel.Text = {'Polarização:'; '<font style="color: gray; font-size: 9px;">(FEIXE_POLARIZ_DOWN)</font>'};
            app.FeixeDownListLabel = uilabel(app.GridLayout);
            app.FeixeDownListLabel.Interpreter = 'html';
            app.FeixeDownListLabel.Text = {'Identificação do feixe de descida:'; '<font style="color: gray; font-size: 9px;">(FEIXE_DOWN)</font>'};
            app.PolarizationList = uilistbox(app.GridLayout);
            app.PolarizationList.Items = {};
            app.PolarizationList.Multiselect = 'on';
            app.PolarizationList.ValueChangedFcn = createCallbackFcn(app, @onPolarizationValueChanged, true);
            app.PolarizationList.Value = {};
            app.FeixeDownList = uilistbox(app.GridLayout);
            app.FeixeDownList.Items = {};
            app.FeixeDownList.Multiselect = 'on';
            app.FeixeDownList.ValueChangedFcn = createCallbackFcn(app, @onFeixeListValueChanged, true);
            app.FeixeDownList.Value = {};
            app.RefLocation = uilistbox(app.GridLayout);
            app.RefLocation.Items = {};
            app.RefLocation.Multiselect = 'on';
            app.RefLocation.Value = {};
            app.FeixeDownListLabel_2 = uilabel(app.GridLayout);
            app.FeixeDownListLabel_2.Interpreter = 'html';
            app.FeixeDownListLabel_2.Text = {'Identificação dos canais:'; '<font style="color: gray; font-size: 9px;">(FREQUÊNCIA E LARGURA)</font>'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockChannelsSatellite(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end