classdef dockRepoFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Toolbar                matlab.ui.container.GridLayout
        FilterDescription      matlab.ui.control.Label
        DownloadButton         matlab.ui.control.Image
        ToolbarSeparator       matlab.ui.control.Image
        FileDetailsButton      matlab.ui.control.Image
        PanelVisibilityButton  matlab.ui.control.Image
        UITable1               matlab.ui.control.Table
        UITable2Warning        matlab.ui.control.Label
        UITable2               matlab.ui.control.Table
        UITable2Label          matlab.ui.control.Label
        UITable2Icon           matlab.ui.control.Image
        FilterPanel            matlab.ui.container.Panel
        FilterGrid             matlab.ui.container.GridLayout
        SearchButton           matlab.ui.control.Button
        Description            matlab.ui.control.EditField
        DescriptionLabel       matlab.ui.control.Label
        FreqStop               matlab.ui.control.NumericEditField
        FreqStopLabel          matlab.ui.control.Label
        FreqStart              matlab.ui.control.NumericEditField
        FreqStartLabel         matlab.ui.control.Label
        PeriodEnd              matlab.ui.control.DatePicker
        PeriodEndLabel         matlab.ui.control.Label
        PeriodBegin            matlab.ui.control.DatePicker
        PeriodBeginLabel       matlab.ui.control.Label
        Receiver               matlab.ui.control.DropDown
        ReceiverLabel          matlab.ui.control.Label
        Location               matlab.ui.control.DropDown
        LocationLabel          matlab.ui.control.Label
        State                  matlab.ui.control.DropDown
        StateLabel             matlab.ui.control.Label
        ModuleIntro            matlab.ui.control.Label
        ModuleIcon             matlab.ui.control.Image
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true
        mainApp
        callingApp
        jsBackDoor
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        webFusionHandlerObj
        dbCacheData
        dbReference
        dbMatchMask
    end


    % properties (Access = private, Constant)
    %     %-----------------------------------------------------------------%
    %     TABLE_RESULTS = table( ...
    %         'Size', [0, 9], ...
    %         'VariableTypes', {'string', 'string', 'string', 'cell', 'datetime', 'datetime', 'string', 'cell'}, ...
    %         'VariableNames', {'REGIÃO', 'SENSOR', 'QTD. FLUXOS', 'LIMITES (MHz)', 'INÍCIO', 'FIM', 'ARQUIVO', 'TAMANHO'} ...
    %     )
    % 
    %     TABLE_FILE_DETAILS = table( ...
    %         'Size', [0, 4], ...
    %         'VariableTypes', {'cell', 'datetime', 'datetime', 'string'}, ...
    %         'VariableNames', {'FAIXA (MHz)', 'INÍCIO', 'FIM', 'DESCRIÇÃO'} ...
    %     );
    % end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function applyJSCustomization(app)
            appName = class(app);
            elToModify = {
                app.State;
                app.PanelVisibilityButton;
                app.DownloadButton;
                app.FileDetailsButton;
                app.UITable1
            };
            ui.CustomizationBase.getElementsDataTag(elToModify);

            try
                sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                    struct('appName', appName, 'dataTag', app.State.UserData.id, 'selector', 'input', 'dropDownBackgroundColor', struct('items', 'rgba(183, 49, 44, 0.75)', 'selectedItem', 'rgb(108, 4, 4)')), ...
                    struct('appName', appName, 'dataTag', app.PanelVisibilityButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Alterna visibilidade do painel à esquerda', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.DownloadButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Download de arquivos', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.FileDetailsButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Detalhes do arquivo selecionado', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.UITable1.UserData.id, 'tableMultiline', struct('status', true, 'verticalCenter', false)) ...
                });
            catch
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app, dbCacheData, dbReference)
            app.webFusionHandlerObj = app.mainApp.webFusionHandlerObj;
            app.dbCacheData = dbCacheData;
            app.dbReference = dbReference;
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            app.UITable2.RowName = 'numbered';
            app.FileDetailsButton.UserData.status = false;
            app.UITable1.UserData.fileIds = [];

            limits = [datetime([2010 1 1]), datetime('tomorrow')-seconds(1)];
            set([app.PeriodBegin, app.PeriodEnd], 'Limits', limits)
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app, filterContext)
            set(app.State, 'Items', [{''}, cellstr(unique([app.dbCacheData.points.state_code]))], 'Value', char(filterContext.state))

            matchMask = applyFilter(app);
            dbFilteredReference = app.dbReference(matchMask, :);

            refreshLocationDropDown(app, dbFilteredReference, '')
            refreshReceiverDropDown(app, dbFilteredReference, filterContext.receiver)

            applyFilter(app);
        end

        %-----------------------------------------------------------------%
        function matchMask = applyFilter(app)
            % app.dbReference é uma tabela com as colunas "DistrictId", "StateCode", 
            % "Location", "StationNames" e "StationDetails".
            matchMask = true(height(app.dbReference), 1);

            % Filtragem "UF"
            stateCode = app.State.Value;
            if ~isempty(stateCode)
                matchMask = matchMask & app.dbReference.StateCode == string(stateCode);
            end

            % Filtragem "Localidade"
            location = app.Location.Value;
            if ~isempty(location)
                matchMask = matchMask & app.dbReference.Location == string(location);
            end

            % Filtragem "Sensor"
            receiver = app.Receiver.Value;
            if ~isempty(receiver)
                matchMask = matchMask & cellfun(@(x) any(contains(x, string(receiver))), app.dbReference.StationNames);
            end

            app.dbMatchMask = matchMask;
        end

        %-----------------------------------------------------------------%
        function refreshLocationDropDown(app, dbFilteredReference, previousValue)
            app.Location.Items = [{''}, cellstr(unique(dbFilteredReference.Location))'];

            if ~isempty(previousValue) && ismember(previousValue, app.Location.Items) && ~isequal(previousValue, app.Location.Value)
                app.Location.Value = previousValue;
            end
        end

        %-----------------------------------------------------------------%
        function refreshReceiverDropDown(app, dbFilteredReference, previousValue)
            app.Receiver.Items = [{''}, cellstr(unique(vertcat(dbFilteredReference.StationNames{:})))'];

            if ~isempty(previousValue) && ismember(previousValue, app.Receiver.Items) && ~isequal(previousValue, app.Receiver.Value)
                app.Receiver.Value = previousValue;
            end
        end

        %-----------------------------------------------------------------%
        function refreshTable(app, type, resultData)
            arguments
                app 
                type {mustBeMember(type, {'files', 'fileDetails'})}
                resultData
            end

            switch type
                case 'files'
                    resultData.("LIMITES (MHz)") = arrayfun(@(x, y) sprintf('%.3f – %.3f', x, y), resultData.("NU_FREQ_START"), resultData.("NU_FREQ_END"), 'UniformOutput', false);
                    resultData.("DT_TIME_START").Format = 'dd/MM/yyyy';
                    resultData.("DT_TIME_END").Format = 'dd/MM/yyyy';
                    resultData.("VL_FILE_SIZE_KB") = arrayfun(@(x) textFormatGUI.bytes2human(x), 1024*resultData.("VL_FILE_SIZE_KB"), "UniformOutput", false);
                    resultData.("ARQUIVO") = extractAfter(resultData.("NA_PATH"), app.mainApp.General.context.REPOSFI.containerPathPrefixToRemove) + "/" + resultData.("NA_FILE");
                    fileIds = uint32(resultData.ID_FILE);

                    resultData = renamevars(resultData(:, {'LOCALITY_LABELS', 'EQUIPMENT_LABELS', 'LIMITES (MHz)', 'NU_SPECTRA', 'DT_TIME_START', 'DT_TIME_END', 'ARQUIVO', 'VL_FILE_SIZE_KB'}), ...
                        {'LOCALITY_LABELS', 'EQUIPMENT_LABELS', 'NU_SPECTRA', 'DT_TIME_START', 'DT_TIME_END', 'VL_FILE_SIZE_KB'}, ...
                        {'REGIÃO', 'SENSOR', 'QTD. FLUXOS', 'INÍCIO', 'FIM', 'TAMANHO'} ...
                    );

                    set(app.UITable1, 'Data', resultData, 'Selection', [])
                    app.UITable1.UserData.fileIds = fileIds;

                    set(app.UITable2, 'Data', [], 'Enable', 'off')
                    app.UITable2Warning.Visible = 'on';

                case 'fileDetails'
                    resultData = sortrows(resultData, {'NU_FREQ_START', 'NU_FREQ_END'});
                    resultData.("FAIXA (MHz)") = arrayfun(@(x, y) sprintf('%.3f – %.3f', x, y), resultData.("NU_FREQ_START"), resultData.("NU_FREQ_END"), 'UniformOutput', false);
                    resultData.("DT_TIME_START").Format = 'dd/MM/yyyy HH:mm:ss';
                    resultData.("DT_TIME_END").Format = 'dd/MM/yyyy HH:mm:ss';
                    resultData.("NA_DESCRIPTION")(resultData.("NA_DESCRIPTION") == "" | ismissing(resultData.("NA_DESCRIPTION"))) = "-";
                    resultData = renamevars(resultData(:, {'FAIXA (MHz)', 'DT_TIME_START', 'DT_TIME_END', 'NA_DESCRIPTION'}), ...
                        {'DT_TIME_START', 'DT_TIME_END', 'NA_DESCRIPTION'}, ...
                        {'INÍCIO', 'FIM', 'DESCRIÇÃO'} ...
                    );
    
                    set(app.UITable2, 'Data', resultData, 'Selection', [], 'Enable', 'on')
                    app.UITable2Warning.Visible = 'off';
            end
        end

        %-----------------------------------------------------------------%
        function refreshTableFootnote(app, currentFilter)
            description = {};
            if ~isempty(currentFilter.stateCode)
                description{end+1} = sprintf('UF: <b>%s</b>', currentFilter.stateCode);
            end

            if ~isempty(currentFilter.location)
                description{end+1} = sprintf('Localidade: <b>%s</b>', currentFilter.location);
            end

            if ~isempty(currentFilter.receiver)
                description{end+1} = sprintf('Sensor:<b>%s</b>', currentFilter.receiver);
            end

            if ~isnat(currentFilter.startDate) && ~isnat(currentFilter.endDate)
                description{end+1} = sprintf('Observação: <b>%s a %s</b>', string(currentFilter.startDate), string(currentFilter.endDate));
            elseif ~isnat(currentFilter.startDate)
                description{end+1} = sprintf('Observação: <b>a partir de %s</b>', string(currentFilter.startDate));
            elseif ~isnat(currentFilter.endDate)
                description{end+1} = sprintf('Observação: <b>até %s</b>', string(currentFilter.endDate));
            end

            if ~isnan(currentFilter.freqStart) && ~isnan(currentFilter.freqEnd)
                description{end+1} = sprintf('Faixa: <b>%.3f – %.3f MHz</b>', currentFilter.freqStart, currentFilter.freqEnd);
            end

            app.FilterDescription.Text = sprintf('%s \n%d arquivos ', strjoin(description, ', '), height(app.UITable1.Data));
        end

        %-----------------------------------------------------------------%
        function [currentFilter, warningMsg] = getCurrentFilter(app)
            currentFilter = [];
            warningMsg = '';

            if isempty(app.State.Value) && isempty(app.Location.Value) && isempty(app.Receiver.Value)
                warningMsg = 'Selecione ao menos um dos filtros primários (UF, localidade ou sensor).';
                return
            end

            issues = {};

            isPeriodBeginNaT = isnat(app.PeriodBegin.Value);
            isPeriodEndNaT   = isnat(app.PeriodEnd.Value);
            
            % Os limites são independentes: início sem fim significa "a partir de"
            % e fim sem início significa "até". A ordem só é verificável quando
            % ambos os controles possuem uma data.
                        
            if ~isPeriodBeginNaT && ~isPeriodEndNaT && app.PeriodEnd.Value < app.PeriodBegin.Value
                issues{end+1} = 'período de observação';
            end

            isFreqStartEmpty = isempty(app.FreqStart.Value);
            isFreqStopEmpty  = isempty(app.FreqStop.Value);
            if xor(isFreqStartEmpty, isFreqStopEmpty) || (~isFreqStartEmpty && ~isFreqStopEmpty && app.FreqStop.Value <= app.FreqStart.Value)
                issues{end+1} = 'faixa de frequência';
            end

            if ~isempty(issues)
                warningMsg = sprintf('Filtragem inválida. Corrija os valores de %s.', textFormatGUI.cellstr2FriendlyListWithQuotes(issues));
                return
            end

            currentFilter = struct( ...
                'stateCode', app.State.Value, ...
                'location', app.Location.Value, ...
                'receiver', app.Receiver.Value, ...
                'districtId', getParameterId(app, 'location'), ...
                'siteId', NaN, ...
                'equipmentId', getParameterId(app, 'receiver'), ...
                'startDate', app.PeriodBegin.Value, ...
                'endDate', app.PeriodEnd.Value, ...
                'freqStart', NaN, ...
                'freqEnd', NaN, ...
                'description', strtrim(app.Description.Value) ...
            );

            if ~isFreqStartEmpty
                currentFilter.freqStart = round(app.FreqStart.Value, 3);
            end

            if ~isFreqStopEmpty
                currentFilter.freqEnd = round(app.FreqStop.Value, 3);
            end
        end

        %-----------------------------------------------------------------%
        function id = getParameterId(app, name)
            arguments
                app
                name {mustBeMember(name, {'state', 'location', 'receiver'})}
            end

            id = NaN;

            matchMask = app.dbMatchMask;
            dbFilteredReference = app.dbReference(matchMask, :);

            switch name
                case 'state'
                    value = string(app.State.Value);
                    [~, valueIdx] = ismember(value, dbFilteredReference.StateCode);
                    if valueIdx
                        id = dbFilteredReference.StateId(valueIdx);
                    end

                case 'location'
                    value = string(app.Location.Value);
                    valueIdx = find(dbFilteredReference.Location == value);
                    if ~isempty(valueIdx)
                        id = unique(dbFilteredReference.DistrictId(valueIdx));
                    end

                otherwise % 'receiver'
                    value = string(app.Receiver.Value);
                    valueIdx = find(cellfun(@(x) any(contains(x, value)), dbFilteredReference.StationNames), 1);
                    if ~isempty(valueIdx)
                        stationDetails = dbFilteredReference.StationDetails{valueIdx};
                        stationDetailsMask = strcmpi([stationDetails.host_name], value) | strcmpi([stationDetails.equipment_name], value);
                        if any(stationDetailsMask)
                            stationDetailsIdx = find(stationDetailsMask, 1);
                            id = stationDetails(stationDetailsIdx).equipment_id;
                        end
                    end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, dbCacheData, dbReference, filterContext)

            % ## ToDo ##
            % Esse app deve migrado para operar um secondaryApp, mas depende 
            % de evolução em auxApp.winRepoSFI para que ambos compartilhem a 
            % mesma aba de app.mainApp.TabGroup. Controle interno, da própria 
            % aba, para chavear entre duas telas. Outra opção é migrar esse 
            % app para auxApp.winRepoSFI.

            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                applyJSCustomization(app)
                initializeAppProperties(app, dbCacheData, dbReference)
                initializeUIComponents(app)
                applyInitialLayout(app, filterContext)

            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)

        end

        % Value changed function: Description, FreqStart, FreqStop, 
        % ...and 5 other components
        function onFilterValueChanged(app, event)
           
            if isequal(event.Value, event.PreviousValue) || (isprop(event, 'ValueIndex') && isempty(event.ValueIndex))
                event.Source.Value = event.PreviousValue;
                return
            end

            switch event.Source
                case app.State
                    previousLocationValue = app.Location.Value;
                    previousReceiverValue = app.Receiver.Value;
                    
                    app.Location.Value = '';
                    app.Receiver.Value = '';

                    matchMask = applyFilter(app);
                    dbFilteredReference = app.dbReference(matchMask, :);

                    refreshLocationDropDown(app, dbFilteredReference, previousLocationValue)
                    refreshReceiverDropDown(app, dbFilteredReference, previousReceiverValue)

                    if ~isempty(app.Location.Value) || ~isempty(app.Receiver.Value)
                        applyFilter(app);
                    end

                case app.Location
                    % Mantém o sensor quando ele também existir na nova localidade.
                    % A lista é refeita com o recorte UF + localidade; caso não haja
                    % correspondência, refreshReceiverDropDown o deixa vazio.
                    previousReceiverValue = app.Receiver.Value;
                    app.Receiver.Value = '';

                    matchMask = applyFilter(app);
                    dbFilteredReference = app.dbReference(matchMask, :);

                    refreshReceiverDropDown(app, dbFilteredReference, previousReceiverValue)
                    applyFilter(app);

                case app.Receiver
                    % Aplica a mesma regra no sentido inverso: ao trocar o sensor,
                    % preserva a localidade atual somente se ela contiver o sensor.
                    previousLocationValue = app.Location.Value;
                    app.Location.Value = '';

                    matchMask = applyFilter(app);
                    dbFilteredReference = app.dbReference(matchMask, :);

                    refreshLocationDropDown(app, dbFilteredReference, previousLocationValue)
                    applyFilter(app);

                case {app.FreqStart, app.FreqStop}
                    event.Source.Value = round(event.Source.Value, 3);

                case app.Description
                    app.Description.Value = strtrim(app.Description.Value);
            end

            app.SearchButton.Enable = ~isempty(app.State.Value) || ~isempty(app.Location.Value) || ~isempty(app.Receiver.Value);
            
        end

        % Button pushed function: SearchButton
        function onSearchButtonClicked(app, event)
            
            [currentFilter, warningMsg] = getCurrentFilter(app);
            if ~isempty(warningMsg)
                ui.Dialog(app.UIFigure, 'warning', warningMsg);
                return
            end

            app.progressDialog.Visible = 'visible';

            try
                pageSizeTotal = getSpectrumFileDataCount(app.webFusionHandlerObj, currentFilter);
                currentFilter.page = 1;
                currentFilter.pageSize = max(pageSizeTotal, 1);
        
                resultData = getSpectrumFileData(app.webFusionHandlerObj, currentFilter);

                if ~istable(resultData)
                    error('auxApp:dockRepoFiles:UnexpectedValue', 'Unexpected value')

                elseif isempty(resultData)
                    msg = [ ...
                        'Nenhum arquivo de espectro foi encontrado para os ' ...
                        'filtros selecionados.' ...
                    ];
                    ui.Dialog(app.UIFigure, 'warning', msg);
                end

                refreshTable(app, 'files', resultData)
                refreshTableFootnote(app, currentFilter)

            catch ME
                msg = ME.message;

                if strcmp(ME.identifier, 'MATLAB:networklib:tcpclient:cannotCreateObject')
                    msg = [ ...
                        'Não foi possível consultar as informações do RF.Fusion. ' ...
                        'Esse recurso requer acesso à rede interna da Anatel. ' ...
                        'Se estiver fora da rede, conecte-se à VPN da Anatel e tente ' ...
                        'novamente.' ...
                    ];
                end

                ui.Dialog(app.UIFigure, 'error', msg);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Selection changed function: UITable1
        function onTableSelectionValueChanged(app, event)
            
            if ~app.FileDetailsButton.UserData.status
                return
            end

            selectedRows = app.UITable1.Selection;
            
            if ~isempty(selectedRows) && isscalar(selectedRows)
                fileId = app.UITable1.UserData.fileIds(selectedRows);

                app.progressDialog.Visible = 'visible';

                try
                    resultData = getSpectraByFileId(app.webFusionHandlerObj, fileId);
                    
                    if ~istable(resultData) || isempty(resultData)
                        error('auxApp:dockRepoFiles:UnexpectedValue', 'Unexpected value')
                    end

                    refreshTable(app, 'fileDetails', resultData)
                    
                catch ME
                    ui.Dialog(app.UIFigure, 'error', ME.message);
                    
                    app.UITable1.Selection = [];
                    onTableSelectionValueChanged(app)
                end

                app.progressDialog.Visible = 'hidden';

            else
                set(app.UITable2, 'Data', [], 'Enable', 'off')
                app.UITable2Warning.Visible = 'on';
            end
            
        end

        % Image clicked function: PanelVisibilityButton
        function onPanelVisibilityButtonClicked(app, event)
            
            if app.GridLayout.ColumnWidth{1}
                app.GridLayout.ColumnWidth{1} = 0;
            else
                app.GridLayout.ColumnWidth{1} = 232;
            end

        end

        % Image clicked function: FileDetailsButton
        function onToolbarFileDetailsButtonClicked(app, event)
            
            app.FileDetailsButton.UserData.status = ~app.FileDetailsButton.UserData.status;

            set([ ...
                app.UITable2Icon, ...
                app.UITable2Label, ...
                app.UITable2 ...
            ], 'Visible', app.FileDetailsButton.UserData.status)

            app.UITable2Warning.Visible = 'off';
            
            if app.FileDetailsButton.UserData.status
                app.UITable1.Layout.Row = 1;
                onTableSelectionValueChanged(app)
            else
                app.UITable1.Layout.Row = [1 5];
            end

        end

        % Image clicked function: DownloadButton
        function onToolbarDownloadButtonClicked(app, event)
            
            selectedRows = app.UITable1.Selection;
            numSelectedRows = numel(selectedRows);
            
            if isempty(selectedRows)
                ui.Dialog(app.UIFigure, 'warning', 'Selecione ao menos um arquivo para continuar.');
                return
            end

            questionMsg = sprintf('O que deseja fazer com o(s) %d arquivo(s) selecionado(s)?', numSelectedRows);
            userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Apenas download', 'Visualizar', 'Cancelar'}, 1, 3);
            if userSelection == "Cancelar"
                return
            end

            if userSelection == "Apenas download"
                appName = class.Constants.appName;
                [~, zipFileBase] = appEngine.util.DefaultFileName('', [appName '_RepoSFI'], -1);
                zipFile = ui.Dialog(app.UIFigure, 'uiputfile', '', {'*.zip', [appName ' (*.zip)']}, fullfile(app.mainApp.General.fileFolder.userPath, [zipFileBase '.zip']));
                if isempty(zipFile)
                    return
                end                 
            end

            d = ui.Dialog(app.UIFigure, 'progressdlg', 'Em andamento...', 'Cancelable', 'on');

            zipFileList = {};
            errorMsg = {};

            httpBaseUrl = sprintf('%s://%s:%d%s', app.mainApp.General.context.REPOSFI.protocol, app.mainApp.General.context.REPOSFI.host, app.mainApp.General.context.REPOSFI.httpPort, app.mainApp.General.context.REPOSFI.httpBasePath);

            for ii = 1:numSelectedRows
                if d.CancelRequested
                    break
                end

                d.Message = sprintf('Em andamento o download do arquivo %d de %d.', ii, numSelectedRows);

                originFilePath = char(app.UITable1.Data.("ARQUIVO")(selectedRows(ii)));
                [~, fileName, fileExt] = fileparts(originFilePath);
                fileName = [fileName, fileExt];

                httpFileUrl = sprintf('%s%s', httpBaseUrl, originFilePath);
                destFilePath = fullfile(app.mainApp.General.fileFolder.tempPath, fileName);

                if isfile(destFilePath)
                    zipFileList{end+1} = destFilePath;
                    continue
                end

                try
                    zipFileList{end+1} = websave(destFilePath, httpFileUrl);
                catch ME
                    errorMsg{end+1} = sprintf('%s: %s', fileName, ME.message);
                end
            end

            if ~isempty(errorMsg)
                ui.Dialog(app.UIFigure, 'error', sprintf('Evidenciado <font style="color: red;"><b>ERRO</b></font> na leitura do(s) arquivo(s) indicado(s) a seguir.\n%s', textFormatGUI.cellstr2Bullets(errorMsg)))
            end

            if ~isempty(zipFileList)
                switch userSelection 
                    case 'Apenas download'
                        zip(zipFile, zipFileList)
                        delete(d)

                    otherwise % 'Visualizar'
                        delete(d)
                        ipcMainMatlabCallsHandler(app.mainApp, app, 'onImportFilesFromPaths', zipFileList);
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FilterPanel = uipanel(app.GridLayout);
            app.FilterGrid = uigridlayout(app.FilterPanel);
            app.ModuleIcon = uiimage(app.FilterGrid);
            app.ModuleIcon.ImageSource = 'library-24px.svg';
            app.ModuleIntro = uilabel(app.FilterGrid);
            app.ModuleIntro.Interpreter = 'html';
            app.ModuleIntro.Text = {'<b>RF.Fusion</b>'; '<p style="padding: 2px; text-align: justify; font-size: 10px; color: gray; line-height: 13px">Centraliza a coleta, organiza e disponibiliza dados de monitoramento de espectro, integrando arquivos de estações remotas, fluxos de espectro e artefatos analíticos.</p>'};
            app.StateLabel = uilabel(app.FilterGrid);
            app.StateLabel.Text = 'UF:';
            app.State = uidropdown(app.FilterGrid);
            app.State.Items = {};
            app.State.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.State.Value = {};
            app.LocationLabel = uilabel(app.FilterGrid);
            app.LocationLabel.Text = 'Localidade:';
            app.Location = uidropdown(app.FilterGrid);
            app.Location.Items = {};
            app.Location.Editable = 'on';
            app.Location.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.Location.Value = {};
            app.ReceiverLabel = uilabel(app.FilterGrid);
            app.ReceiverLabel.Text = 'Sensor:';
            app.Receiver = uidropdown(app.FilterGrid);
            app.Receiver.Items = {};
            app.Receiver.Editable = 'on';
            app.Receiver.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.Receiver.Value = {};
            app.PeriodBeginLabel = uilabel(app.FilterGrid);
            app.PeriodBeginLabel.Text = 'Período inicial:';
            app.PeriodBegin = uidatepicker(app.FilterGrid);
            app.PeriodBegin.DisplayFormat = 'dd/MM/uuuu';
            app.PeriodBegin.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.PeriodEndLabel = uilabel(app.FilterGrid);
            app.PeriodEndLabel.Text = 'Período final:';
            app.PeriodEnd = uidatepicker(app.FilterGrid);
            app.PeriodEnd.DisplayFormat = 'dd/MM/uuuu';
            app.PeriodEnd.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.FreqStartLabel = uilabel(app.FilterGrid);
            app.FreqStartLabel.Text = 'Freq. inicial (MHz):';
            app.FreqStart = uieditfield(app.FilterGrid, 'numeric');
            app.FreqStart.Limits = [20 18000];
            app.FreqStart.ValueDisplayFormat = '%.3f';
            app.FreqStart.AllowEmpty = 'on';
            app.FreqStart.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.FreqStart.Value = [];
            app.FreqStopLabel = uilabel(app.FilterGrid);
            app.FreqStopLabel.Text = 'Freq. final (MHz):';
            app.FreqStop = uieditfield(app.FilterGrid, 'numeric');
            app.FreqStop.Limits = [20 18000];
            app.FreqStop.ValueDisplayFormat = '%.3f';
            app.FreqStop.AllowEmpty = 'on';
            app.FreqStop.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.FreqStop.Value = [];
            app.DescriptionLabel = uilabel(app.FilterGrid);
            app.DescriptionLabel.Text = 'Descrição:';
            app.Description = uieditfield(app.FilterGrid, 'text');
            app.Description.ValueChangedFcn = createCallbackFcn(app, @onFilterValueChanged, true);
            app.SearchButton = uibutton(app.FilterGrid, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @onSearchButtonClicked, true);
            app.SearchButton.Icon = 'search-20px-white.svg';
            app.SearchButton.Text = '   Consultar';
            app.UITable2Icon = uiimage(app.GridLayout);
            app.UITable2Icon.Visible = 'off';
            app.UITable2Icon.ImageSource = 'selected-row-16px.png';
            app.UITable2Label = uilabel(app.GridLayout);
            app.UITable2Label.Visible = 'off';
            app.UITable2Label.Text = 'FLUXOS ESPECTRAIS DO ARQUIVO SELECIONADO';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'FAIXA (MHz)'; 'INÍCIO'; 'FIM'; 'DESCRIÇÃO'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.SelectionType = 'row';
            app.UITable2.Enable = 'off';
            app.UITable2.Visible = 'off';
            app.UITable2Warning = uilabel(app.GridLayout);
            app.UITable2Warning.Visible = 'off';
            app.UITable2Warning.Interpreter = 'html';
            app.UITable2Warning.Text = {'❗'; '<p style="font-size: 12px;">SELECIONE UM REGISTRO'; 'NA TABELA ACIMA</p>'};
            app.UITable1 = uitable(app.GridLayout);
            app.UITable1.ColumnName = {'REGIÃO'; 'SENSOR'; 'LIMITES (MHz)'; 'FLUXOS'; 'INÍCIO'; 'FIM'; 'ARQUIVO'; 'TAMANHO'};
            app.UITable1.RowName = {};
            app.UITable1.ColumnSortable = [true true true false true true false false];
            app.UITable1.SelectionType = 'row';
            app.UITable1.SelectionChangedFcn = createCallbackFcn(app, @onTableSelectionValueChanged, true);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.PanelVisibilityButton = uiimage(app.Toolbar);
            app.PanelVisibilityButton.ImageClickedFcn = createCallbackFcn(app, @onPanelVisibilityButtonClicked, true);
            app.PanelVisibilityButton.ImageSource = 'layout-sidebar-left.svg';
            app.FileDetailsButton = uiimage(app.Toolbar);
            app.FileDetailsButton.ImageClickedFcn = createCallbackFcn(app, @onToolbarFileDetailsButtonClicked, true);
            app.FileDetailsButton.ImageSource = 'split_top_bottom_24.png';
            app.ToolbarSeparator = uiimage(app.Toolbar);
            app.ToolbarSeparator.Enable = 'off';
            app.ToolbarSeparator.ImageSource = 'LineV.svg';
            app.DownloadButton = uiimage(app.Toolbar);
            app.DownloadButton.ImageClickedFcn = createCallbackFcn(app, @onToolbarDownloadButtonClicked, true);
            app.DownloadButton.ImageSource = 'Import_16.png';
            app.FilterDescription = uilabel(app.Toolbar);
            app.FilterDescription.Interpreter = 'html';
            app.FilterDescription.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockRepoFiles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end