classdef dockReportLib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        SpectrumFlowTree        matlab.ui.container.CheckBoxTree
        SpectrumFlowTreeLabel   matlab.ui.control.Label
        reportPanel             matlab.ui.container.Panel
        reportGrid              matlab.ui.container.GridLayout
        reportEntityPanel       matlab.ui.container.Panel
        reportEntityGrid        matlab.ui.container.GridLayout
        reportEntityName        matlab.ui.control.EditField
        reportEntityNameLabel   matlab.ui.control.Label
        reportEntityId          matlab.ui.control.EditField
        reportEntityIdCheck     matlab.ui.control.Image
        reportEntityIdLabel     matlab.ui.control.Label
        reportEntityType        matlab.ui.control.DropDown
        reportEntityTypeLabel   matlab.ui.control.Label
        reportEntityPanelLabel  matlab.ui.control.Label
        reportVersion           matlab.ui.control.DropDown
        reportVersionLabel      matlab.ui.control.Label
        reportModel             matlab.ui.control.DropDown
        reportModelButton       matlab.ui.control.Image
        reportModelLabel        matlab.ui.control.Label
        reportLabel             matlab.ui.control.Label
        eFiscalizaPanel         matlab.ui.container.Panel
        eFiscalizaGrid          matlab.ui.container.GridLayout
        eFiscalizaIssueDetails  matlab.ui.control.Image
        eFiscalizaIssue         matlab.ui.control.NumericEditField
        eFiscalizaIssueLabel    matlab.ui.control.Label
        eFiscalizaUnit          matlab.ui.control.DropDown
        eFiscalizaUnitLabel     matlab.ui.control.Label
        eFiscalizaSystem        matlab.ui.control.DropDown
        eFiscalizaSystemLabel   matlab.ui.control.Label
        eFiscalizaLabel         matlab.ui.control.Label
        prjPanel                matlab.ui.container.Panel
        prjGrid                 matlab.ui.container.GridLayout
        prjLastReportDelete     matlab.ui.control.Image
        prjLastReportView       matlab.ui.control.Image
        prjLastReport           matlab.ui.control.EditField
        prjLastReportLabel      matlab.ui.control.Label
        prjFile                 matlab.ui.control.Label
        prjName                 matlab.ui.control.EditField
        prjNameLabel            matlab.ui.control.Label
        prjNewProjectButton     matlab.ui.control.Image
        prjSaveButton           matlab.ui.control.Image
        prjOpenFileButton       matlab.ui.control.Image
        prjLabel                matlab.ui.control.Label
        Title                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
        jsBackDoor
        progressDialog
        projectData
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function applyJSCustomization(app)
            appName = class(app);
            elToModify = {
                app.prjOpenFileButton;
                app.prjSaveButton;
                app.prjNewProjectButton;
                app.prjLastReportView;
                app.prjLastReportDelete;
                app.eFiscalizaIssueDetails;
                app.reportModelButton;
                app.reportEntityIdCheck
            };
            ui.CustomizationBase.getElementsDataTag(elToModify);

            try
                sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                    struct('appName', appName, 'dataTag', app.prjOpenFileButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Abre projeto', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.prjSaveButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Salva projeto', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.prjNewProjectButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Cria novo projeto', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.prjLastReportView.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Visualiza relatório', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.prjLastReportDelete.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exclui relatório', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.eFiscalizaIssueDetails.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Detalhes da inspeção', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.reportModelButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Adiciona arquivos externos', 'zIndex', 901)), ... 
                    struct('appName', appName, 'dataTag', app.reportEntityIdCheck.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Consulta', 'zIndex', 901)) ... 
                });
            catch
            end
        end

        %-----------------------------------------------------------------%
        function updatePanel(app, context)
            % PROJECT INITIALIZATION
            if isempty(app.projectData.modules.(context).ui.system) && ~isequal(app.projectData.modules.(context).ui.system, app.mainApp.General.reportLib.system)
                updateUiInfo(app.projectData, context, 'system', app.mainApp.General.reportLib.system)
            end
            
            if isempty(app.projectData.modules.(context).ui.unit) && ~isequal(app.projectData.modules.(context).ui.unit, app.mainApp.General.reportLib.unit)
                updateUiInfo(app.projectData, context, 'unit', app.mainApp.General.reportLib.unit)
            end

            if isempty(app.projectData.modules.(context).ui.entityTypes)
                updateUiInfo(app.projectData, context, 'entityTypes', app.mainApp.General.reportLib.entityType.options)
                app.projectData.modules.(context).ui.entity.type = app.mainApp.General.reportLib.entityType.default;
            end

            % PROJECT PANEL
            app.prjName.Value = app.projectData.name;
            app.prjSaveButton.Enable = ~isempty(app.projectData.name);
            
            prjFileText = '(projeto ainda não exportado)';
            if isfile(app.projectData.file)
                [~, prjFileName, prjFileExt] = fileparts(app.projectData.file);
                prjFileText = [prjFileName prjFileExt];
            end
            app.prjFile.Text = prjFileText;

            lastHTMLDocFullPath = getGeneratedDocumentFileName(app.projectData, '.html', context);
            if isfile(lastHTMLDocFullPath)
                [~, fileName, fileExt] = fileparts(lastHTMLDocFullPath);
                lastHTMLDocFullPath = [fileName, fileExt];
            end
            app.prjLastReport.Value = lastHTMLDocFullPath;
            set([app.prjLastReportView, app.prjLastReportDelete], 'Enable', ~isempty(app.prjLastReport.Value))

            % eFISCALIZA PANEL
            app.eFiscalizaSystem.Value = app.projectData.modules.(context).ui.system;
            app.eFiscalizaIssue.Value  = app.projectData.modules.(context).ui.issue;
            set(app.eFiscalizaUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, 'Value', app.projectData.modules.(context).ui.unit)
            app.eFiscalizaIssueDetails.Enable = (app.eFiscalizaIssue.Value > 0) && ~isinf(app.eFiscalizaIssue.Value);

            % REPORT PANEL
            set(app.reportModel, 'Items', app.projectData.modules.(context).ui.templates, 'Value', app.projectData.modules.(context).ui.reportModel)
            app.reportVersion.Value    = app.projectData.modules.(context).ui.reportVersion;

            [~, reportModelIdx] = ismember(app.reportModel.Value, {app.mainApp.projectData.report.templates.Name});
            app.reportModelButton.Enable = reportModelIdx && ~isempty(app.mainApp.projectData.report.templates(reportModelIdx).ExternalFiles);

            set(app.reportEntityType, 'Items', app.projectData.modules.(context).ui.entityTypes, 'Value', app.projectData.modules.(context).ui.entity.type)
            app.reportEntityName.Value = app.projectData.modules.(context).ui.entity.name;
            
            if app.projectData.modules.(context).ui.entity.status
                backgroundColor = [1,1,1];
                fontColor       = [0,0,0];
            else
                backgroundColor = '#c80b0f';
                fontColor       = [1,1,1];
            end
            set(app.reportEntityId, 'Value', app.projectData.modules.(context).ui.entity.id, 'BackgroundColor', backgroundColor, 'FontColor', fontColor)
            app.reportEntityIdCheck.Enable = ~isempty(app.reportEntityId.Value);

            buildTree(app)
        end

        %-----------------------------------------------------------------%
        function buildTree(app)
            if ~isempty(app.SpectrumFlowTree.Children)
                delete(app.SpectrumFlowTree.Children)
            end

            if ~isempty(app.mainApp.specData)
                [receiverList, ~, receiverListIdxs] = unique({app.mainApp.specData.Receiver});
                checkedNodes = [];
    
                for ii = 1:numel(receiverList)
                    receiverIdxs  = find(receiverListIdxs == ii)';
                    occupancyIdxs = arrayfun(@(x) ismember(x.MetaData.DataType, class.Constants.occDataTypes), app.mainApp.specData(receiverIdxs));
                    receiverIdxs(occupancyIdxs) = [];

                    if isempty(receiverIdxs)
                        continue
                    end

                    receiverNode = uitreenode(app.SpectrumFlowTree, 'Text',  util.layoutTreeNodeText(receiverList{ii}, 'play_TreeBuilding'), ...
                                                                    'NodeData', receiverIdxs, 'Icon', util.layoutTreeNodeIcon(receiverList{ii}), 'Tag', 'RECEIVER');

                    for jj = receiverIdxs
                        treeNode = uitreenode(receiverNode, 'Text', util.HtmlTextGenerator.createTag('Flow', app.mainApp.specData(jj)), ...
                                                            'NodeData', jj, 'Tag', 'BAND');

                        if app.mainApp.specData(jj).UserData.ReportInclude
                            checkedNodes = [checkedNodes; treeNode];
                        end
                    end
                end

                app.SpectrumFlowTree.CheckedNodes = checkedNodes;
                expand(app.SpectrumFlowTree, 'all')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                applyJSCustomization(app)
                app.inputArgs = struct('context', context);
                updatePanel(app, context)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Image clicked function: prjNewProjectButton
        function onProjectRestart(app, event)
            
            msgQuestion = 'Deseja excluir todas as referências do projeto e iniciar um novo projeto?';
            selection   = ui.Dialog(app.UIFigure, "uiconfirm", msgQuestion, {'Sim', 'Não'}, 1, 2, {'Icon', 'error'});
            if strcmp(selection, 'Não')
                return
            end

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')
    
            context = app.inputArgs.context;
            restart(app.projectData, {context}, 'onProjectRestart', app.mainApp.General)
            update(app.mainApp.specData, 'UserData:ReportInclude', [])
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onProjectRestart', context)
            updatePanel(app, context)

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')

        end

        % Image clicked function: prjOpenFileButton
        function onProjectLoad(app, event)
            
            appName = class.Constants.appName;
            
            [fileFullPath, fileFolder] = ui.Dialog(app.UIFigure, 'uigetfile', '', {'*.mat', [appName ' (*.mat)']}, app.mainApp.General.fileFolder.lastVisited, {'MultiSelect', 'off'});
            if isempty(fileFullPath)
                return
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onUpdateLastVisitedFolder', fileFolder)
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onProjectLoad', {fileFullPath});

            context = app.inputArgs.context;
            updatePanel(app, context)

        end

        % Image clicked function: prjSaveButton
        function onProjectSave(app, event)
            
            appName  = class.Constants.appName;
            context  = app.inputArgs.context;

            flowIdxs = getReportIncludeIdxs(app.mainApp.specData);
            specData = app.mainApp.specData(flowIdxs);

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')

            for ii = 1:numel(specData)
                if ~isempty(specData(ii)) && (isempty(specData(ii).Data) || (numel(specData(ii).Data{1}) ~= sum(specData(ii).RelatedFiles.NumSweeps)))    
                    try
                        populateSpectrum(specData(ii), app.mainApp.metaData, app.mainApp.projectData, app.mainApp.channelObj, app.mainApp.General)
                        
                        relatedHases = specData(ii).UserData.OccupancyComputationMode.RelatedHashes;
                        if ~isempty(relatedHases)
                            relatedHashIdxs = find(ismember({app.mainApp.specData.Hash}, relatedHases));
                            populateSpectrum(app.mainApp.specData(relatedHashIdxs), app.mainApp.metaData, app.mainApp.projectData, app.mainApp.channelObj, app.mainApp.General)
                        end
    
                    catch ME
                        ui.Dialog(app.UIFigure, 'warning', ME.message);
                        requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
                        return
                    end
                end
            end

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')

            if isempty(specData)
                warningMsg = [ ...
                    'Não é possível criar um projeto sem ao menos um fluxo ' ...
                    'espectral selecionado a incluir no relatório.' ...
                ];

                ui.Dialog(app.UIFigure, 'warning', warningMsg);
                return

            elseif isfile(app.projectData.file)
                if ~checkIfUpdateNeeded(app.projectData, specData)
                    questionMsg = sprintf([ ...
                        'Ao que parece, o projeto "<b>%s</b>" não sofreu ' ...
                        'alterações.<br><br>Deseja continuar mesmo assim?' ...
                    ], app.projectData.name);

                    userSelection = ui.Dialog(app.UIFigure, "uiconfirm", questionMsg, {'Sim', 'Não'}, 1, 2);
                    if strcmp(userSelection, 'Não')
                        return
                    end
                end

                [defaultPath, defaultFile] = fileparts(app.projectData.file);
                defaultName = fullfile(defaultPath, defaultFile);

            else
                defaultName = appEngine.util.DefaultFileName(app.mainApp.General.fileFolder.userPath, [appName '_ProjectData'], -1);
            end
            
            projectName = app.projectData.name;
            projectFile = ui.Dialog(app.UIFigure, 'uiputfile', '', {'*.mat', [appName ' (*.mat)']}, defaultName);
            if isempty(projectFile)
                return
            end

            % Fluxos que irão compor o .MAT englobam, também, os de
            % ocupação...
            occupancyFlows = arrayfun(@(x) x.UserData.OccupancyComputationMode.RelatedHashes, specData, 'UniformOutput', false);
            occupancyFlows = horzcat(occupancyFlows{:});

            if ~isempty(occupancyFlows)
                occupancyFlowsIdxs = find(strcmp(occupancyFlows, {specData.Hash}));
                specData = [specData, app.mainApp.specData(occupancyFlowsIdxs)];
            end

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')

            try
                save(app.projectData, 'ProjectData', context, projectName, projectFile, app.mainApp.General.reportLib.outputCompressionMode, specData)
                updatePanel(app, context)
            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')

        end

        % Value changing function: prjName
        function onProjectNameChanging(app, event)
            
            changingValue = event.Value;
            app.prjSaveButton.Enable = ~isempty(strtrim(changingValue));
            
        end

        % Value changed function: eFiscalizaIssue, eFiscalizaSystem, 
        % ...and 7 other components
        function onProjectInfoUpdate(app, event)

            context = app.inputArgs.context;

            fieldValue = event.Value;
            switch event.Source
                case app.prjName
                    fieldName = 'name';
                    fieldValue = strtrim(fieldValue);

                case app.eFiscalizaSystem
                    fieldName = 'system';

                case app.eFiscalizaUnit
                    fieldName = 'unit';

                case app.eFiscalizaIssue
                    fieldName = 'issue';

                case app.reportModel
                    fieldName = 'reportModel';

                case app.reportVersion
                    fieldName = 'reportVersion';

                case {app.reportEntityType, app.reportEntityId, app.reportEntityName}
                    [entityId, status] = checkCNPJOrCPF(app.reportEntityId.Value, 'NumberValidation');

                    fieldName = 'entity';
                    fieldValue = struct( ...
                        'type', strtrim(app.reportEntityType.Value), ...
                        'name', strtrim(app.reportEntityName.Value), ...
                        'id', entityId, ...
                        'status', status ...
                    );

                case app.reportEntityIdCheck
                    fieldName = 'entityDetails';
            end

            updateUiInfo(app.projectData, context, fieldName, fieldValue)
            updatePanel(app, context)
            
        end

        % Image clicked function: eFiscalizaIssueDetails
        function onFetchIssueDetails(app, event)
            
            % <VALIDAÇÕES>
            context = app.inputArgs.context;
            issue = app.projectData.modules.(context).ui.issue;
            
            if ~validateReportRequirements(app.projectData, context, 'issue')
                msg = sprintf('O número da inspeção "%.0f" é inválido.', issue);
                ui.Dialog(app.UIFigure, 'warning', msg);
                return
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            system  = app.projectData.modules.(context).ui.system;
            details = getIssueDetailsFromCache(app.projectData, system, issue);

            if ~isempty(details)
                msg = util.HtmlTextGenerator.issueDetails(system, issue, details);
                ui.Dialog(app.UIFigure, 'info', msg);

            else
                eventName = 'onFetchIssueDetails';
                if isempty(app.mainApp.eFiscalizaObj) || ~isvalid(app.mainApp.eFiscalizaObj)
                    eventData = ws.eFiscaliza.getCredentials('auto', app.mainApp.executionMode, app.jsBackDoor, eventName, context);
                    if ~isempty(eventData)
                        eventData.uuid = eventName;
                        eventData.context = context;
    
                        ipcMainJSEventsHandler(app.mainApp, struct('HTMLEventName', 'customForm', 'HTMLEventData', eventData))
                    end
                else
                    ipcMainMatlabCallsHandler(app.mainApp, app, eventName, context)
                end
            end
            % </PROCESSO>

        end

        % Image clicked function: prjLastReportDelete, prjLastReportView
        function onFinalReportFileButtonClicked(app, event)
            
            context = app.inputArgs.context;
            updateNeeded = false;
            lastHTMLDocFullPath = getGeneratedDocumentFileName(app.projectData, '.html', context);

            try
                switch event.Source
                    case app.prjLastReportView                        
                        web(lastHTMLDocFullPath, '-new')

                    case app.prjLastReportDelete
                        delete(lastHTMLDocFullPath)

                        updateNeeded = true;
                        updateGeneratedFiles(app.projectData, context)                        
                end

            catch ME
                updateNeeded = true;
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

            if updateNeeded
                updatePanel(app, context)
                ipcMainMatlabCallsHandler(app.mainApp, app, 'onFinalReportFileChanged', context)
            end

        end

        % Image clicked function: reportEntityIdCheck
        function onEntityIdCheckButtonClicked(app, event)
            
            details = getEntityDetailsFromCache(app.projectData, app.reportEntityId.Value);

            if isempty(details)
                requestVisibilityChange(app.progressDialog, 'visible', 'locked')

                details = getOrFetchEntityDetails(app.projectData, app.reportEntityId.Value);
                
                requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
            end

            if ~isempty(details)                
                msg = util.HtmlTextGenerator.entityDetails(app.reportEntityId.Value, details);                
            else
                msg = 'Esta pesquisa está disponível apenas para CNPJs válidos.';
            end
            
            ui.Dialog(app.UIFigure, "none", msg);

        end

        % Callback function: SpectrumFlowTree
        function onReportFlowListChanged(app, event)
            
            checkedNodes = app.SpectrumFlowTree.CheckedNodes;
            flowIdxs = [];
            if ~isempty(checkedNodes)
                flowIdxs = unique([checkedNodes.NodeData]);
            end

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')

            update(app.mainApp.specData, 'UserData:ReportInclude', flowIdxs)
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onReportFlowListChanged', app.inputArgs.context)

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
            
        end

        % Image clicked function: reportModelButton
        function onExternalFileModuleOpenRequest(app, event)
            
            flowIdxs = getReportIncludeIdxs(app.mainApp.specData);

            if isempty(flowIdxs)
                warningMsg = [ ...
                    'Antes de incluir anexos ao projeto ou a um fluxo espectral, ' ...
                    'defina ao menos um fluxo como "Fluxo a incluir no relatório".' ...
                ];
                ui.Dialog(app.UIFigure, 'warning', warningMsg);
                return
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onExternalFileModuleOpenRequest')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.GridLayout);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b>Gerenciamento do projeto</b>'; '<font style="color: gray; font-size: 10px;">Salve e abra projetos, registre informações da atividade e configure o relatório</font>'};
            app.prjLabel = uilabel(app.GridLayout);
            app.prjLabel.Text = 'Projeto';
            app.prjOpenFileButton = uiimage(app.GridLayout);
            app.prjOpenFileButton.ImageClickedFcn = createCallbackFcn(app, @onProjectLoad, true);
            app.prjOpenFileButton.ImageSource = 'Import_16.png';
            app.prjSaveButton = uiimage(app.GridLayout);
            app.prjSaveButton.ImageClickedFcn = createCallbackFcn(app, @onProjectSave, true);
            app.prjSaveButton.Enable = 'off';
            app.prjSaveButton.ImageSource = 'save.svg';
            app.prjNewProjectButton = uiimage(app.GridLayout);
            app.prjNewProjectButton.ImageClickedFcn = createCallbackFcn(app, @onProjectRestart, true);
            app.prjNewProjectButton.ImageSource = 'new-project.svg';
            app.prjPanel = uipanel(app.GridLayout);
            app.prjGrid = uigridlayout(app.prjPanel);
            app.prjNameLabel = uilabel(app.prjGrid);
            app.prjNameLabel.Text = 'Nome do projeto:';
            app.prjName = uieditfield(app.prjGrid, 'text');
            app.prjName.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.prjName.ValueChangingFcn = createCallbackFcn(app, @onProjectNameChanging, true);
            app.prjFile = uilabel(app.prjGrid);
            app.prjFile.Text = '(projeto ainda não exportado)';
            app.prjLastReportLabel = uilabel(app.prjGrid);
            app.prjLastReportLabel.Text = 'Último relatório gerado:';
            app.prjLastReport = uieditfield(app.prjGrid, 'text');
            app.prjLastReport.Editable = 'off';
            app.prjLastReportView = uiimage(app.prjGrid);
            app.prjLastReportView.ImageClickedFcn = createCallbackFcn(app, @onFinalReportFileButtonClicked, true);
            app.prjLastReportView.Enable = 'off';
            app.prjLastReportView.ImageSource = 'eye.svg';
            app.prjLastReportDelete = uiimage(app.prjGrid);
            app.prjLastReportDelete.ImageClickedFcn = createCallbackFcn(app, @onFinalReportFileButtonClicked, true);
            app.prjLastReportDelete.Enable = 'off';
            app.prjLastReportDelete.ImageSource = 'close-16px-red.svg';
            app.eFiscalizaLabel = uilabel(app.GridLayout);
            app.eFiscalizaLabel.Text = 'Sistema de gestão à fiscalização - eFiscaliza';
            app.eFiscalizaPanel = uipanel(app.GridLayout);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.eFiscalizaSystemLabel = uilabel(app.eFiscalizaGrid);
            app.eFiscalizaSystemLabel.Text = 'Ambiente:';
            app.eFiscalizaSystem = uidropdown(app.eFiscalizaGrid);
            app.eFiscalizaSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
            app.eFiscalizaSystem.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.eFiscalizaSystem.Value = 'eFiscaliza';
            app.eFiscalizaUnitLabel = uilabel(app.eFiscalizaGrid);
            app.eFiscalizaUnitLabel.Text = 'Unidade:';
            app.eFiscalizaUnit = uidropdown(app.eFiscalizaGrid);
            app.eFiscalizaUnit.Items = {};
            app.eFiscalizaUnit.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.eFiscalizaUnit.Value = {};
            app.eFiscalizaIssueLabel = uilabel(app.eFiscalizaGrid);
            app.eFiscalizaIssueLabel.Text = 'Atividade de inspeção (#ID):';
            app.eFiscalizaIssue = uieditfield(app.eFiscalizaGrid, 'numeric');
            app.eFiscalizaIssue.Limits = [-1 Inf];
            app.eFiscalizaIssue.RoundFractionalValues = 'on';
            app.eFiscalizaIssue.ValueDisplayFormat = '%d';
            app.eFiscalizaIssue.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.eFiscalizaIssue.Value = -1;
            app.eFiscalizaIssueDetails = uiimage(app.eFiscalizaGrid);
            app.eFiscalizaIssueDetails.ImageClickedFcn = createCallbackFcn(app, @onFetchIssueDetails, true);
            app.eFiscalizaIssueDetails.Enable = 'off';
            app.eFiscalizaIssueDetails.ImageSource = 'eye.svg';
            app.reportLabel = uilabel(app.GridLayout);
            app.reportLabel.Text = 'Relatório';
            app.reportPanel = uipanel(app.GridLayout);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportModelLabel = uilabel(app.reportGrid);
            app.reportModelLabel.Text = 'Modelo (.json):';
            app.reportModelButton = uiimage(app.reportGrid);
            app.reportModelButton.ImageClickedFcn = createCallbackFcn(app, @onExternalFileModuleOpenRequest, true);
            app.reportModelButton.Enable = 'off';
            app.reportModelButton.ImageSource = 'attach_32.png';
            app.reportModel = uidropdown(app.reportGrid);
            app.reportModel.Items = {''};
            app.reportModel.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.reportModel.Value = '';
            app.reportVersionLabel = uilabel(app.reportGrid);
            app.reportVersionLabel.Text = 'Versão:';
            app.reportVersion = uidropdown(app.reportGrid);
            app.reportVersion.Items = {'Preliminar', 'Definitiva'};
            app.reportVersion.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.reportVersion.Value = 'Preliminar';
            app.reportEntityPanelLabel = uilabel(app.reportGrid);
            app.reportEntityPanelLabel.Text = 'Fiscalizada';
            app.reportEntityPanel = uipanel(app.reportGrid);
            app.reportEntityGrid = uigridlayout(app.reportEntityPanel);
            app.reportEntityTypeLabel = uilabel(app.reportEntityGrid);
            app.reportEntityTypeLabel.Text = 'Tipo:';
            app.reportEntityType = uidropdown(app.reportEntityGrid);
            app.reportEntityType.Items = {};
            app.reportEntityType.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.reportEntityType.Value = {};
            app.reportEntityIdLabel = uilabel(app.reportEntityGrid);
            app.reportEntityIdLabel.Text = 'CNPJ/CPF:';
            app.reportEntityIdCheck = uiimage(app.reportEntityGrid);
            app.reportEntityIdCheck.ImageClickedFcn = createCallbackFcn(app, @onEntityIdCheckButtonClicked, true);
            app.reportEntityIdCheck.Enable = 'off';
            app.reportEntityIdCheck.ImageSource = 'Info_32.png';
            app.reportEntityId = uieditfield(app.reportEntityGrid, 'text');
            app.reportEntityId.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.reportEntityNameLabel = uilabel(app.reportEntityGrid);
            app.reportEntityNameLabel.Text = 'Nome da fiscalizada:';
            app.reportEntityName = uieditfield(app.reportEntityGrid, 'text');
            app.reportEntityName.ValueChangedFcn = createCallbackFcn(app, @onProjectInfoUpdate, true);
            app.SpectrumFlowTreeLabel = uilabel(app.GridLayout);
            app.SpectrumFlowTreeLabel.Text = 'Fluxos espectrais a incluir no relatório';
            app.SpectrumFlowTree = uitree(app.GridLayout, 'checkbox');
            % Assign Checked Nodes
            app.SpectrumFlowTree.CheckedNodesChangedFcn = createCallbackFcn(app, @onReportFlowListChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockReportLib(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end