classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DockModule                   matlab.ui.container.GridLayout
        DockCloseButton              matlab.ui.control.Image
        DockUndockButton             matlab.ui.control.Image
        SubTabGroup                  matlab.ui.container.TabGroup
        SubTab1                      matlab.ui.container.Tab
        SubGrid1                     matlab.ui.container.GridLayout
        openAuxiliarApp2Debug        matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked      matlab.ui.control.CheckBox
        versionInfo                  matlab.ui.control.Label
        tool_RFDataHubButton         matlab.ui.control.Image
        tool_versionInfoRefresh      matlab.ui.control.Image
        versionInfoLabel             matlab.ui.control.Label
        SubTab2                      matlab.ui.container.Tab
        SubGrid2                     matlab.ui.container.GridLayout
        analysis_ElevationPanel      matlab.ui.container.Panel
        analysis_ElevationGrid       matlab.ui.container.GridLayout
        elevationForceSearch         matlab.ui.control.CheckBox
        elevationNPoints             matlab.ui.control.DropDown
        elevationNPointsLabel        matlab.ui.control.Label
        elevationAPIServer           matlab.ui.control.DropDown
        elevationAPIServerLabel      matlab.ui.control.Label
        configAnalysisLabel3         matlab.ui.control.Label
        analysis_GraphicsPanel       matlab.ui.container.Panel
        analysis_GraphicsGrid        matlab.ui.container.GridLayout
        InitialBW_kHz                matlab.ui.control.Spinner
        InitialBW_kHzLabel           matlab.ui.control.Label
        yOccupancyScale              matlab.ui.control.DropDown
        yOccupancyScaleLabel         matlab.ui.control.Label
        configAnalysisLabel2         matlab.ui.control.Label
        analysis_FilePanel           matlab.ui.container.Panel
        analysis_FileGrid            matlab.ui.container.GridLayout
        emissionClassification       matlab.ui.control.CheckBox
        channelManualMode            matlab.ui.control.CheckBox
        mergeDistance                matlab.ui.control.Spinner
        mergeLabel2                  matlab.ui.control.Label
        mergeAntenna                 matlab.ui.control.CheckBox
        mergeDataType                matlab.ui.control.CheckBox
        mergeLabel1                  matlab.ui.control.Label
        configAnalysisRefresh        matlab.ui.control.Image
        configAnalysisLabel1         matlab.ui.control.Label
        SubTab3                      matlab.ui.container.Tab
        SubGrid3                     matlab.ui.container.GridLayout
        reportPanel                  matlab.ui.container.Panel
        reportGrid_2                 matlab.ui.container.GridLayout
        prjFileCompressionMode       matlab.ui.control.DropDown
        prjFileCompressionModeLabel  matlab.ui.control.Label
        reportBinningPanel           matlab.ui.container.Panel
        reportBinningGrid            matlab.ui.container.GridLayout
        reportBinningFcn             matlab.ui.control.DropDown
        reportBinningFcnLabel        matlab.ui.control.Label
        reportBinningLength          matlab.ui.control.Spinner
        reportBinningLengthLabel     matlab.ui.control.Label
        reportBinningLabel           matlab.ui.control.Label
        reportImgDpi                 matlab.ui.control.DropDown
        reportImgFormat              matlab.ui.control.DropDown
        reportImageLabel             matlab.ui.control.Label
        reportBasemap                matlab.ui.control.DropDown
        reportBasemapLabel           matlab.ui.control.Label
        reportLabel                  matlab.ui.control.Label
        eFiscalizaPanel              matlab.ui.container.Panel
        eFiscalizaGrid               matlab.ui.container.GridLayout
        MacrothemesList              matlab.ui.control.EditField
        MacrothemesButton            matlab.ui.control.Image
        MacrothemesLabel             matlab.ui.control.Label
        reportUnit                   matlab.ui.control.DropDown
        reportUnitLabel              matlab.ui.control.Label
        reportSystem                 matlab.ui.control.DropDown
        reportSystemLabel            matlab.ui.control.Label
        eFiscalizaRefresh            matlab.ui.control.Image
        eFiscalizaLabel              matlab.ui.control.Label
        SubTab4                      matlab.ui.container.Tab
        SubGrid4                     matlab.ui.container.GridLayout
        userPathButton               matlab.ui.control.Image
        userPath                     matlab.ui.control.EditField
        userPathLabel                matlab.ui.control.Label
        DataHubPOSTButton            matlab.ui.control.Image
        DataHubPOST                  matlab.ui.control.EditField
        DATAHUBPOSTLabel             matlab.ui.control.Label
        Toolbar                      matlab.ui.container.GridLayout
        tool_openDevTools            matlab.ui.control.Image
        tool_simulationMode          matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'CONFIG'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        defaultValues
        stableVersion
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)                        

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            arguments
                app 
                tabIndex = 1
            end

            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;
            appName = class(app);

            switch tabIndex
                case 1
                    elToModify = {
                        app.versionInfo;
                        app.tool_versionInfoRefresh;
                        app.tool_RFDataHubButton;
                        app.tool_simulationMode;
                        app.tool_openDevTools;
                        app.DockUndockButton;
                        app.DockCloseButton
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);
                    
                    try
                        ui.TextView.startup(app.jsBackDoor, app.versionInfo, class(app));
                    catch
                    end

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.tool_versionInfoRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Verifica atualizações')), ...
                            struct('appName', appName, 'dataTag', app.tool_RFDataHubButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Atualiza RF.DataHub')), ...
                            struct('appName', appName, 'dataTag', app.tool_simulationMode.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Leitura arquivos de simulação')), ...
                            struct('appName', appName, 'dataTag', app.tool_openDevTools.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Abre DevTools')), ...
                            struct('appName', appName, 'dataTag', app.DockUndockButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.DockCloseButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                case 2
                    ui.CustomizationBase.getElementsDataTag({app.configAnalysisRefresh});

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.configAnalysisRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Retorna às configurações iniciais')) ...
                        });
                    catch
                    end

                    updatePanel_Analysis(app)

                case 3
                    ui.CustomizationBase.getElementsDataTag({app.eFiscalizaRefresh, app.MacrothemesButton});

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.eFiscalizaRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Retorna às configurações iniciais')), ...
                            struct('appName', appName, 'dataTag', app.MacrothemesButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Habilita ou desabilita a edição da lista de macrotemas<br>Ex: "PM-RNI", "UTE", ""')) ...
                        });
                    catch
                    end

                    updatePanel_Report(app)

                case 4
                    if ~strcmp(app.mainApp.executionMode, 'webApp')
                        set([app.DataHubPOSTButton, app.userPathButton], 'Enable', 1)
                    end
                    updatePanel_Folder(app)
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % Lê a versão de "GeneralSettings.json" que vem junto ao
            % projeto (e não a versão armazenada em "ProgramData").
            projectFolder   = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath = fullfile(projectFolder, 'GeneralSettings.json');
            projectGeneral  = jsondecode(fileread(projectFilePath));

            app.defaultValues = struct('elevation', projectGeneral.elevation, ...
                                       'Merge',     projectGeneral.context.FILE.spectrumConsolidationPolicy, ...
                                       'Channel',   projectGeneral.context.PLAYBACK.channel, ...
                                       'Detection', projectGeneral.context.PLAYBACK.detection, ...
                                       'Plot',      projectGeneral.plot, ...
                                       'reportLib', projectGeneral.reportLib);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.DockUndockButton.Enable       = 1;
                app.tool_openDevTools.Enable       = 1;                
                app.tool_versionInfoRefresh.Enable = 1;
                app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            % Versão:
            appInfo = util.HtmlTextGenerator.getAppInfo( ...
                app.mainApp.General, ...
                app.mainApp.rootFolder, ...
                app.mainApp.executionMode, ...
                app.mainApp.renderCount, ...
                app.mainApp.eFiscalizaObj, ...
                "textview" ...
            );
            ui.TextView.update(app.versionInfo, appInfo);

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value   = app.mainApp.General.operationMode.Debug;
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel_Analysis(app)
            % Mesclagem de fluxos espectrais
            switch app.mainApp.General.context.FILE.spectrumConsolidationPolicy.dataTypePolicy
                case 'keep';   app.mergeDataType.Value = 0;
                case 'remove'; app.mergeDataType.Value = 1;
            end

            switch app.mainApp.General.context.FILE.spectrumConsolidationPolicy.antennaPolicy
                case 'keep';   app.mergeAntenna.Value = 0;
                case 'remove'; app.mergeAntenna.Value = 1;
            end

            app.mergeDistance.Value        = app.mainApp.General.context.FILE.spectrumConsolidationPolicy.maxCoLocationDistanceMeters;

            % PLAYBACK AXES
            app.yOccupancyScale.Value      = app.mainApp.General.plot.cartesianAxes.yOccupancyScale;
            
            % DETECTION
            app.channelManualMode.Value    = app.mainApp.General.context.PLAYBACK.channel.manualMode;
            app.emissionClassification.Value = app.mainApp.General.context.PLAYBACK.detection.enableEmissionClassification;
            app.InitialBW_kHz.Value        = app.mainApp.General.context.PLAYBACK.detection.datatipBandWidthkHz;

            % ELEVATION
            app.elevationNPoints.Value     = num2str(app.mainApp.General.elevation.pointCount);
            app.elevationForceSearch.Value = app.mainApp.General.elevation.forceRefresh;
            app.elevationAPIServer.Value   = app.mainApp.General.elevation.provider;

            app.configAnalysisRefresh.Visible = checkEdition(app, 'ANALYSIS');
        end

        %-----------------------------------------------------------------%
        function updatePanel_Report(app)
            app.reportSystem.Value = app.mainApp.General.reportLib.system;
            set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, 'Value', app.mainApp.General.reportLib.unit)
            
            app.MacrothemesButton.UserData.status = false;
            app.MacrothemesButton.ImageSource = 'Edit_32.png';
            set(app.MacrothemesList, 'Editable', 'off', 'FontColor', [0.65,0.65,0.65], 'Value', textFormatGUI.cellstr2ListWithQuotes(app.mainApp.General.reportLib.allowedMacrothemes, 'none'))
            
            app.reportBasemap.Value       = app.mainApp.General.reportLib.basemap;
            app.reportImgFormat.Value     = app.mainApp.General.reportLib.image.format;
            app.reportImgDpi.Value        = num2str(app.mainApp.General.reportLib.image.resolutionDpi);
            app.reportBinningLength.Value = app.mainApp.General.context.DRIVETEST.dataBinning.binLengthMeters;
            app.reportBinningFcn.Value    = app.mainApp.General.context.DRIVETEST.dataBinning.aggregationFunction;

            if ismember(app.mainApp.General.reportLib.outputCompressionMode, app.prjFileCompressionMode.Items)
                app.prjFileCompressionMode.Value = app.mainApp.General.reportLib.outputCompressionMode;
            end

            app.eFiscalizaRefresh.Visible = checkEdition(app, 'REPORT');
        end

        %-----------------------------------------------------------------%
        function updatePanel_Folder(app)
            DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
            if isfolder(DataHub_POST)
                app.DataHubPOST.Value = DataHub_POST;
            end

            app.userPath.Value = app.mainApp.General.fileFolder.userPath;                
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app, tabName)
            editionFlag   = false;
            currentValues = struct('elevation', app.mainApp.General.elevation, ...
                                   'Merge',     app.mainApp.General.context.FILE.spectrumConsolidationPolicy, ...
                                   'Channel',   app.mainApp.General.context.PLAYBACK.channel, ...
                                   'Detection', app.mainApp.General.context.PLAYBACK.detection, ...
                                   'Plot',      app.mainApp.General.plot, ...
                                   'reportLib', app.mainApp.General.reportLib);

            switch tabName
                case 'ANALYSIS'
                    if ~isequal(rmfield(currentValues, 'reportLib'), rmfield(app.defaultValues, 'reportLib'))
                        editionFlag = true;
                    end
                case 'REPORT'
                    if ~isequal(currentValues.reportLib, app.defaultValues.reportLib)
                        editionFlag = true;
                    end
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: DockCloseButton, DockUndockButton
        function onDockModuleGroupButtonClicked(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.DockUndockButton
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.DockCloseButton
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection change function: SubTabGroup
        function onTabSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.SubTabGroup.SelectedTab, app.SubTabGroup.Children);
            applyJSCustomizations(app, tabIndex)

        end

        % Image clicked function: tool_versionInfoRefresh
        function onAppEnvRefreshButtonClicked(app, event)
            
            app.progressDialog.Visible = 'visible';

            [htmlContent, app.stableVersion, updatedModule] = util.HtmlTextGenerator.checkAvailableUpdate(app.mainApp.General, app.mainApp.rootFolder);
            ui.Dialog(app.UIFigure, "info", htmlContent);
            app.tool_RFDataHubButton.Enable = ~ismember('RFDataHub', updatedModule);

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: tool_simulationMode
        function onSimulationModeButtonClicked(app, event)
            
            msgQuestion   = 'Deseja abrir arquivos de <b>simulação</b>?';
            userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
            
            if strcmp(userSelection, 'Não')
                return
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onSimulationMode')
            
        end

        % Image clicked function: tool_openDevTools
        function onOpenDevToolsButtonClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end

        % Image clicked function: tool_RFDataHubButton
        function onRFDataHubButtonClicked(app, event)
            
            if isequal(rmfield(app.mainApp.General.AppVersion.database, 'name'),  app.stableVersion.rfDataHub)
                app.tool_RFDataHubButton.Enable = 0;
                ui.Dialog(app.UIFigure, 'warning', 'Módulo RFDataHub já atualizado!');
                return
            end

            d = ui.Dialog(app.UIFigure, "progressdlg", 'Em andamento... esse processo pode demorar alguns minutos!');

            try
                appName = class.Constants.appName;
                rfDataHubLink = util.publicLink(appName, app.mainApp.rootFolder, 'RFDataHub');
                model.RFDataHub.update(appName, app.mainApp.rootFolder, app.mainApp.General.fileFolder.tempPath, rfDataHubLink)

                % Atualiza versão.
                global RFDataHub_info

                app.mainApp.General.AppVersion.database      = RFDataHub_info;
                app.mainApp.General.AppVersion.database.name = 'RFDataHub';
                app.stableVersion.rfDataHub = RFDataHub_info;
                app.tool_RFDataHubButton.Enable = 0;

                ipcMainMatlabCallsHandler(app.mainApp, app, 'onRFDataHubUpdate')
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

            applyInitialLayout(app)
            delete(d)

        end

        % Value changed function: openAuxiliarApp2Debug, 
        % ...and 1 other component
        function onGeneralParameterValueChanged(app, event)
            
            switch event.Source
                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;
            saveGeneralSettings(app)

        end

        % Image clicked function: configAnalysisRefresh
        function onAnalysisRefreshImageClicked(app, event)
            
            if ~checkEdition(app, 'ANALYSIS')
                app.configAnalysisRefresh.Visible = 0;
                return

            else
                app.mainApp.General.context.FILE.spectrumConsolidationPolicy = app.defaultValues.Merge;                    
                app.mainApp.General.context.PLAYBACK.channel = app.defaultValues.Channel;
                app.mainApp.General.context.PLAYBACK.detection = app.defaultValues.Detection;
                app.mainApp.General.elevation = app.defaultValues.elevation;
                app.mainApp.General.plot = app.defaultValues.Plot;

                updatePanel_Analysis(app)
                saveGeneralSettings(app)
            end

        end

        % Value changed function: InitialBW_kHz, channelManualMode, 
        % ...and 8 other components
        function onAnalysisParameterValueChanged(app, event)
            
            switch event.Source
                case {app.mergeDataType, app.mergeAntenna, app.mergeDistance}                    
                    switch app.mergeDataType.Value
                        case 0; DataTypeStatus = 'keep';
                        case 1; DataTypeStatus = 'remove';
                    end
                    
                    switch app.mergeAntenna.Value
                        case 0; AntennaStatus  = 'keep';
                        case 1; AntennaStatus  = 'remove';
                    end
                                
                    app.mainApp.General.context.FILE.spectrumConsolidationPolicy.dataTypePolicy = DataTypeStatus;
                    app.mainApp.General.context.FILE.spectrumConsolidationPolicy.antennaPolicy = AntennaStatus;
                    app.mainApp.General.context.FILE.spectrumConsolidationPolicy.maxCoLocationDistanceMeters = app.mergeDistance.Value;

                case app.channelManualMode
                    app.mainApp.General.context.PLAYBACK.channel.manualMode = app.channelManualMode.Value;

                case app.emissionClassification
                    app.mainApp.General.context.PLAYBACK.detection.enableEmissionClassification = app.emissionClassification.Value;

                case app.yOccupancyScale
                    app.mainApp.General.plot.cartesianAxes.yOccupancyScale = app.yOccupancyScale.Value;
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'onYAxesScaleChange')

                case app.InitialBW_kHz
                    app.mainApp.General.context.PLAYBACK.detection.datatipBandWidthkHz  = app.InitialBW_kHz.Value;

                case app.elevationNPoints
                    app.mainApp.General.elevation.pointCount = str2double(app.elevationNPoints.Value);

                case app.elevationForceSearch
                    app.mainApp.General.elevation.forceRefresh = app.elevationForceSearch.Value;

                case app.elevationAPIServer
                    app.mainApp.General.elevation.provider = app.elevationAPIServer.Value;
            end

            app.mainApp.General_I.context.FILE.spectrumConsolidationPolicy = app.mainApp.General.context.FILE.spectrumConsolidationPolicy;
            app.mainApp.General_I.context.PLAYBACK.channel = app.mainApp.General.context.PLAYBACK.channel;
            app.mainApp.General_I.context.PLAYBACK.detection = app.mainApp.General.context.PLAYBACK.detection;
            app.mainApp.General_I.elevation = app.mainApp.General.elevation;
            app.mainApp.General_I.plot = app.mainApp.General.plot;

            saveGeneralSettings(app)
            updatePanel_Analysis(app)  

        end

        % Image clicked function: eFiscalizaRefresh
        function onProjectRefreshImageClicked(app, event)
            
            if ~checkEdition(app, 'REPORT')
                app.eFiscalizaRefresh.Visible = 0;
                return
            
            else
                app.mainApp.General.reportLib   = app.defaultValues.reportLib;
                app.mainApp.General_I.reportLib = app.mainApp.General.reportLib;
                
                updatePanel_Report(app)
                saveGeneralSettings(app)
            end

        end

        % Value changed function: prjFileCompressionMode, reportBasemap, 
        % ...and 6 other components
        function onProjectParameterValueChanged(app, event)
            
            switch event.Source
                case app.reportSystem
                    app.mainApp.General.reportLib.system = event.Value;

                case app.reportUnit
                    app.mainApp.General.reportLib.unit = event.Value;

                case app.reportBasemap
                    app.mainApp.General.reportLib.basemap = event.Value;

                case app.reportImgFormat
                    app.mainApp.General.reportLib.image.format = event.Value;

                case app.reportImgDpi
                    app.mainApp.General.reportLib.image.resolutionDpi = str2double(event.Value);

                case app.reportBinningLength
                    app.mainApp.General.context.DRIVETEST.dataBinning.binLengthMeters = event.Value;

                case app.reportBinningFcn
                    app.mainApp.General.context.DRIVETEST.dataBinning.aggregationFunction = event.Value;

                case app.prjFileCompressionMode
                    app.mainApp.General.reportLib.outputCompressionMode = event.Value;
            end

            app.mainApp.General_I.reportLib = app.mainApp.General.reportLib;

            updatePanel_Report(app)
            saveGeneralSettings(app)

        end

        % Image clicked function: DataHubPOSTButton, userPathButton
        function onFolderButtonClicked(app, event)
            
            try
                relatedFolder = eval(sprintf('app.%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                figure(app.UIFigure)
            end

            if selectedFolder
                switch event.Source
                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.appanalise_post', {selectedFolderFiles.name})
                                ui.Dialog(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do appAnalise.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                updatePanel_Folder(app)
            end

        end

        % Callback function: MacrothemesButton, MacrothemesList
        function Config_MacrothemesEdition(app, event)
            
            switch event.Source
                case app.MacrothemesButton
                    app.MacrothemesButton.UserData.status = ~app.MacrothemesButton.UserData.status;
                    if app.MacrothemesButton.UserData.status
                        app.MacrothemesButton.ImageSource = 'Edit_32Filled.png';
                        set(app.MacrothemesList, 'Editable', 'on', 'FontColor', [0,0,0])
                    else
                        app.MacrothemesButton.ImageSource = 'Edit_32.png';
                        set(app.MacrothemesList, 'Editable', 'off', 'FontColor', [0.65,0.65,0.65])
                    end

                case app.MacrothemesList
                    parts = strsplit(event.Value, ',');
                    try
                        requiredMacrothemes = unique(strtrim(extractBetween(parts, '"', '"')));
                    catch
                        app.MacrothemesList.Value = event.PreviousValue;
                        return
                    end

                    currentMacrothemes = sort(app.mainApp.General.reportLib.allowedMacrothemes);

                    if numel(requiredMacrothemes) ~= numel(parts) || isequal(currentMacrothemes, requiredMacrothemes)
                        app.MacrothemesList.Value = event.PreviousValue;
                        return
                    end

                    msgQuestion = sprintf([ ...
                        'LISTA ATUAL:\n%s\n\nLISTA PROPOSTA:\n%s\n\n' ...
                        'Confirma a troca da lista de macrotemas?' ...
                    ], textFormatGUI.cellstr2ListWithQuotes(currentMacrothemes, 'none'), textFormatGUI.cellstr2ListWithQuotes(requiredMacrothemes, 'none'));

                    userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                    if userSelection == "Não"
                        app.MacrothemesList.Value = event.PreviousValue;
                        return
                    end

                    app.mainApp.General.reportLib.allowedMacrothemes = requiredMacrothemes;
                    app.mainApp.General_I.reportLib = app.mainApp.General.reportLib;

                    updatePanel_Report(app)
                    saveGeneralSettings(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_simulationMode = uiimage(app.Toolbar);
            app.tool_simulationMode.ImageClickedFcn = createCallbackFcn(app, @onSimulationModeButtonClicked, true);
            app.tool_simulationMode.ImageSource = 'Import_16.png';
            app.tool_openDevTools = uiimage(app.Toolbar);
            app.tool_openDevTools.ImageClickedFcn = createCallbackFcn(app, @onOpenDevToolsButtonClicked, true);
            app.tool_openDevTools.Enable = 'off';
            app.tool_openDevTools.ImageSource = 'Debug_18.png';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTabGroup.SelectionChangedFcn = createCallbackFcn(app, @onTabSelectionChanged, true);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'ASPECTOS GERAIS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.versionInfoLabel = uilabel(app.SubGrid1);
            app.versionInfoLabel.Text = 'AMBIENTE:';
            app.tool_versionInfoRefresh = uiimage(app.SubGrid1);
            app.tool_versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @onAppEnvRefreshButtonClicked, true);
            app.tool_versionInfoRefresh.Enable = 'off';
            app.tool_versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.tool_RFDataHubButton = uiimage(app.SubGrid1);
            app.tool_RFDataHubButton.ImageClickedFcn = createCallbackFcn(app, @onRFDataHubButtonClicked, true);
            app.tool_RFDataHubButton.Enable = 'off';
            app.tool_RFDataHubButton.ImageSource = 'mosaic_32.png';
            app.versionInfo = uilabel(app.SubGrid1);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.openAuxiliarAppAsDocked = uicheckbox(app.SubGrid1);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @onGeneralParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.SubGrid1);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @onGeneralParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.SubTab2 = uitab(app.SubTabGroup);
            app.SubTab2.Title = 'ANÁLISE';
            app.SubGrid2 = uigridlayout(app.SubTab2);
            app.configAnalysisLabel1 = uilabel(app.SubGrid2);
            app.configAnalysisLabel1.Text = 'LEITURA DOS ARQUIVOS + IDENTIFICAÇÃO AUTOMÁTICA DE CANAIS E EMISSÕES';
            app.configAnalysisRefresh = uiimage(app.SubGrid2);
            app.configAnalysisRefresh.ImageClickedFcn = createCallbackFcn(app, @onAnalysisRefreshImageClicked, true);
            app.configAnalysisRefresh.Visible = 'off';
            app.configAnalysisRefresh.ImageSource = 'Refresh_18.png';
            app.analysis_FilePanel = uipanel(app.SubGrid2);
            app.analysis_FileGrid = uigridlayout(app.analysis_FilePanel);
            app.mergeLabel1 = uilabel(app.analysis_FileGrid);
            app.mergeLabel1.Text = 'Metadados a serem ignorados na mesclagem de fluxos espectrais:';
            app.mergeDataType = uicheckbox(app.analysis_FileGrid);
            app.mergeDataType.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.mergeDataType.Text = 'DataType';
            app.mergeDataType.Value = true;
            app.mergeAntenna = uicheckbox(app.analysis_FileGrid);
            app.mergeAntenna.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.mergeAntenna.Text = 'Antenna';
            app.mergeLabel2 = uilabel(app.analysis_FileGrid);
            app.mergeLabel2.Text = 'Distância máxima permitida entre pontos de monitoração em arquivos diferentes para mesclar fluxos espectrais (em metros):';
            app.mergeDistance = uispinner(app.analysis_FileGrid);
            app.mergeDistance.Step = 50;
            app.mergeDistance.Limits = [50 Inf];
            app.mergeDistance.RoundFractionalValues = 'on';
            app.mergeDistance.ValueDisplayFormat = '%.0f';
            app.mergeDistance.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.mergeDistance.Value = 100;
            app.channelManualMode = uicheckbox(app.analysis_FileGrid);
            app.channelManualMode.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.channelManualMode.Text = 'Não identificar automaticamente canais relacionados aos fluxos espectrais na leitura de arquivos.';
            app.emissionClassification = uicheckbox(app.analysis_FileGrid);
            app.emissionClassification.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.emissionClassification.Text = 'Tornar obrigatória a identificação das emissões inicialmente classificadas como "Pendente identificação".';
            app.emissionClassification.Value = true;
            app.configAnalysisLabel2 = uilabel(app.SubGrid2);
            app.configAnalysisLabel2.Text = 'ESCALA PLOT + LARGURA PADRÃO DE EMISSÃO';
            app.analysis_GraphicsPanel = uipanel(app.SubGrid2);
            app.analysis_GraphicsGrid = uigridlayout(app.analysis_GraphicsPanel);
            app.yOccupancyScaleLabel = uilabel(app.analysis_GraphicsGrid);
            app.yOccupancyScaleLabel.Text = 'Escala de ocupação do plot:';
            app.yOccupancyScale = uidropdown(app.analysis_GraphicsGrid);
            app.yOccupancyScale.Items = {'linear', 'log'};
            app.yOccupancyScale.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.yOccupancyScale.Value = 'log';
            app.InitialBW_kHzLabel = uilabel(app.analysis_GraphicsGrid);
            app.InitialBW_kHzLabel.Text = 'Largura de emissão em kHz para emissão criada pelo método "DataTip":';
            app.InitialBW_kHz = uispinner(app.analysis_GraphicsGrid);
            app.InitialBW_kHz.Step = 50;
            app.InitialBW_kHz.Limits = [0 1000];
            app.InitialBW_kHz.RoundFractionalValues = 'on';
            app.InitialBW_kHz.ValueDisplayFormat = '%.0f';
            app.InitialBW_kHz.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.configAnalysisLabel3 = uilabel(app.SubGrid2);
            app.configAnalysisLabel3.Text = 'ELEVAÇÃO';
            app.analysis_ElevationPanel = uipanel(app.SubGrid2);
            app.analysis_ElevationGrid = uigridlayout(app.analysis_ElevationPanel);
            app.elevationAPIServerLabel = uilabel(app.analysis_ElevationGrid);
            app.elevationAPIServerLabel.Text = 'Fonte:';
            app.elevationAPIServer = uidropdown(app.analysis_ElevationGrid);
            app.elevationAPIServer.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.elevationAPIServer.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.elevationAPIServer.Value = 'Open-Elevation';
            app.elevationNPointsLabel = uilabel(app.analysis_ElevationGrid);
            app.elevationNPointsLabel.Text = 'Pontos enlace:';
            app.elevationNPoints = uidropdown(app.analysis_ElevationGrid);
            app.elevationNPoints.Items = {'64', '128', '256', '512', '1024'};
            app.elevationNPoints.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.elevationNPoints.Value = '256';
            app.elevationForceSearch = uicheckbox(app.analysis_ElevationGrid);
            app.elevationForceSearch.ValueChangedFcn = createCallbackFcn(app, @onAnalysisParameterValueChanged, true);
            app.elevationForceSearch.Text = 'Força consulta ao servidor (ignorando eventual informação em cache).';
            app.SubTab3 = uitab(app.SubTabGroup);
            app.SubTab3.Title = 'PROJETO';
            app.SubGrid3 = uigridlayout(app.SubTab3);
            app.eFiscalizaLabel = uilabel(app.SubGrid3);
            app.eFiscalizaLabel.Text = 'eFISCALIZA';
            app.eFiscalizaRefresh = uiimage(app.SubGrid3);
            app.eFiscalizaRefresh.ImageClickedFcn = createCallbackFcn(app, @onProjectRefreshImageClicked, true);
            app.eFiscalizaRefresh.Visible = 'off';
            app.eFiscalizaRefresh.ImageSource = 'Refresh_18.png';
            app.eFiscalizaPanel = uipanel(app.SubGrid3);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = {'Ambiente do sistema de gestão à '; 'fiscalização:'};
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = {'Unidade responsável '; 'pela fiscalização:'};
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportUnit.Value = {};
            app.MacrothemesLabel = uilabel(app.eFiscalizaGrid);
            app.MacrothemesLabel.Text = {'Macrotemas aceitos para fins de'; 'elaboração do relato:'};
            app.MacrothemesButton = uiimage(app.eFiscalizaGrid);
            app.MacrothemesButton.ImageClickedFcn = createCallbackFcn(app, @Config_MacrothemesEdition, true);
            app.MacrothemesButton.ImageSource = 'Edit_32.png';
            app.MacrothemesList = uieditfield(app.eFiscalizaGrid, 'text');
            app.MacrothemesList.ValueChangedFcn = createCallbackFcn(app, @Config_MacrothemesEdition, true);
            app.MacrothemesList.Editable = 'off';
            app.reportLabel = uilabel(app.SubGrid3);
            app.reportLabel.Text = 'RELATÓRIO + BASEMAP + IMAGEM + DATABINNING';
            app.reportPanel = uipanel(app.SubGrid3);
            app.reportGrid_2 = uigridlayout(app.reportPanel);
            app.reportBasemapLabel = uilabel(app.reportGrid_2);
            app.reportBasemapLabel.Text = 'Basemap do eixo geográfico dos plots:';
            app.reportBasemap = uidropdown(app.reportGrid_2);
            app.reportBasemap.Items = {'darkwater', 'none', 'satellite', 'streets-dark', 'streets-light', 'topographic'};
            app.reportBasemap.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportBasemap.Value = 'darkwater';
            app.reportImageLabel = uilabel(app.reportGrid_2);
            app.reportImageLabel.Text = 'Formato e resolução (dpi) das imagens:';
            app.reportImgFormat = uidropdown(app.reportGrid_2);
            app.reportImgFormat.Items = {'jpeg', 'png'};
            app.reportImgFormat.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportImgFormat.Value = 'jpeg';
            app.reportImgDpi = uidropdown(app.reportGrid_2);
            app.reportImgDpi.Items = {'100', '120', '150', '200'};
            app.reportImgDpi.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportImgDpi.Value = '100';
            app.reportBinningLabel = uilabel(app.reportGrid_2);
            app.reportBinningLabel.Text = {'Sumarização de pontos com níveis superiores ao limiar:'; '(Data-Binning)'};
            app.reportBinningPanel = uipanel(app.reportGrid_2);
            app.reportBinningGrid = uigridlayout(app.reportBinningPanel);
            app.reportBinningLengthLabel = uilabel(app.reportBinningGrid);
            app.reportBinningLengthLabel.Text = {'Comprimento '; 'quadrícula (metros):'};
            app.reportBinningLength = uispinner(app.reportBinningGrid);
            app.reportBinningLength.Step = 50;
            app.reportBinningLength.Limits = [50 1500];
            app.reportBinningLength.RoundFractionalValues = 'on';
            app.reportBinningLength.ValueDisplayFormat = '%.0f';
            app.reportBinningLength.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportBinningLength.Value = 100;
            app.reportBinningFcnLabel = uilabel(app.reportBinningGrid);
            app.reportBinningFcnLabel.Text = {'Função'; 'estatística:'};
            app.reportBinningFcn = uidropdown(app.reportBinningGrid);
            app.reportBinningFcn.Items = {'min', 'mean', 'median', 'rms', 'max'};
            app.reportBinningFcn.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.reportBinningFcn.Value = 'rms';
            app.prjFileCompressionModeLabel = uilabel(app.reportGrid_2);
            app.prjFileCompressionModeLabel.Text = 'Compressão aplicada ao arquivo de saída do projeto?';
            app.prjFileCompressionMode = uidropdown(app.reportGrid_2);
            app.prjFileCompressionMode.Items = {'Não', 'Sim'};
            app.prjFileCompressionMode.ValueChangedFcn = createCallbackFcn(app, @onProjectParameterValueChanged, true);
            app.prjFileCompressionMode.Value = 'Não';
            app.SubTab4 = uitab(app.SubTabGroup);
            app.SubTab4.Title = 'MAPEAMENTO DE PASTAS';
            app.SubGrid4 = uigridlayout(app.SubTab4);
            app.DATAHUBPOSTLabel = uilabel(app.SubGrid4);
            app.DATAHUBPOSTLabel.Text = 'DATAHUB - POST:';
            app.DataHubPOST = uieditfield(app.SubGrid4, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.SubGrid4);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @onFolderButtonClicked, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.Enable = 'off';
            app.DataHubPOSTButton.ImageSource = 'folder-opened-16px.svg';
            app.userPathLabel = uilabel(app.SubGrid4);
            app.userPathLabel.Text = 'PASTA DO USUÁRIO:';
            app.userPath = uieditfield(app.SubGrid4, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.SubGrid4);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @onFolderButtonClicked, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.Enable = 'off';
            app.userPathButton.ImageSource = 'folder-opened-16px.svg';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.DockUndockButton = uiimage(app.DockModule);
            app.DockUndockButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockUndockButton.Enable = 'off';
            app.DockUndockButton.ImageSource = 'Undock_18White.png';
            app.DockCloseButton = uiimage(app.DockModule);
            app.DockCloseButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockCloseButton.ImageSource = 'Delete_12SVG_white.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end