classdef winPlayback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DockModule                     matlab.ui.container.GridLayout
        dockModule_Close               matlab.ui.control.Image
        dockModule_Undock              matlab.ui.control.Image
        Document                       matlab.ui.container.GridLayout
        AxesToolbar                    matlab.ui.container.GridLayout
        axesTool_DataTip               matlab.ui.control.Image
        axesTool_waterfall             matlab.ui.control.Image
        axesTool_Separator3            matlab.ui.control.Image
        axesTool_occupancy             matlab.ui.control.Image
        axesTool_Separator2            matlab.ui.control.Image
        axesTool_persistence           matlab.ui.control.Image
        axesTool_emissions             matlab.ui.control.Image
        axesTool_maxHold               matlab.ui.control.Image
        axesTool_average               matlab.ui.control.Image
        axesTool_minHold               matlab.ui.control.Image
        axesTool_crearWrite            matlab.ui.control.Image
        axesTool_Separator1            matlab.ui.control.Image
        axesTool_ExportGraphics        matlab.ui.control.Image
        axesTool_Pan                   matlab.ui.control.Image
        axesTool_RestoreView           matlab.ui.control.Image
        AxesAnnotation                 matlab.ui.control.Label
        AxesContainer                  matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.GridLayout
        WaterfallPanel                 matlab.ui.container.Panel
        WaterfallPanelGrid             matlab.ui.container.GridLayout
        WaterfallCLim2                 matlab.ui.control.Spinner
        WaterfallCLim1                 matlab.ui.control.Spinner
        WaterfallCLimRefresh           matlab.ui.control.Image
        WaterfallCLimLabel             matlab.ui.control.Label
        WaterfallMeshStyle             matlab.ui.control.DropDown
        WaterfallMeshStyleLabel        matlab.ui.control.Label
        WaterfallColormap              matlab.ui.control.DropDown
        WaterfallColormapLabel         matlab.ui.control.Label
        WaterfallDecimation            matlab.ui.control.DropDown
        WaterfallDecimationValue       matlab.ui.control.Label
        WaterfallDecimationLabel       matlab.ui.control.Label
        WaterfallFunction              matlab.ui.control.DropDown
        WaterfallFunctionLabel         matlab.ui.control.Label
        WaterfallPanelLabel            matlab.ui.control.Label
        WaterfallPanelIcon             matlab.ui.control.Image
        PersistencePanel               matlab.ui.container.Panel
        PersistencePanelGrid           matlab.ui.container.GridLayout
        PersistenceCLim2               matlab.ui.control.Spinner
        PersistenceCLim1               matlab.ui.control.Spinner
        PersistenceCLimRefresh         matlab.ui.control.Image
        PersistenceCLim_Label          matlab.ui.control.Label
        PersistenceTransparency        matlab.ui.control.Spinner
        PersistenceTransparencyLabel   matlab.ui.control.Label
        PersistenceColormap            matlab.ui.control.DropDown
        PersistenceColormapLabel       matlab.ui.control.Label
        PersistenceWindowSize          matlab.ui.control.DropDown
        PersistenceWindowSizeValue     matlab.ui.control.Label
        PersistenceWindowSizeLabel     matlab.ui.control.Label
        PersistenceInterpolation       matlab.ui.control.DropDown
        PersistenceInterpolationLabel  matlab.ui.control.Label
        PersistencePanelLabel          matlab.ui.control.Label
        PersistencePanelIcon           matlab.ui.control.Image
        GeneralPanel                   matlab.ui.container.Panel
        GeneralPanelGrid               matlab.ui.container.GridLayout
        LimitsYLim2                    matlab.ui.control.Spinner
        LimitsYLim1                    matlab.ui.control.Spinner
        LimitsYLimLabel                matlab.ui.control.Label
        LimitsXLim2                    matlab.ui.control.Spinner
        LimitsXLim1                    matlab.ui.control.Spinner
        LimitsXLimLabel                matlab.ui.control.Label
        LimitsRefresh                  matlab.ui.control.Image
        LimitsPanelLabel               matlab.ui.control.Label
        LayoutRatio                    matlab.ui.control.DropDown
        LayoutRatioLabel               matlab.ui.control.Label
        GeneralPanelLabel              matlab.ui.control.Label
        GeneralPanelIcon               matlab.ui.control.Image
        LeftPanel                      matlab.ui.container.GridLayout
        FlowPanel                      matlab.ui.container.Panel
        FlowPanelGrid                  matlab.ui.container.GridLayout
        FlowOccupancy                  matlab.ui.control.ListBox
        FlowOccupancyEdit              matlab.ui.control.Image
        FlowOccupancyLabel             matlab.ui.control.Label
        FlowEmissions                  matlab.ui.control.Table
        FlowEmissionsAdd               matlab.ui.control.Image
        FlowEmissionsDrawRoi           matlab.ui.control.Image
        FlowEmissionsDataTips          matlab.ui.control.Image
        FlowEmissionsFileImport        matlab.ui.control.Image
        FlowEmissionsLabel             matlab.ui.control.Label
        FlowDetectionLimits            matlab.ui.control.ListBox
        FlowDetectionLimitsEdit        matlab.ui.control.Image
        FlowDetectionLabel             matlab.ui.control.Label
        FlowChannel                    matlab.ui.control.ListBox
        FlowChannelFileImport          matlab.ui.control.Image
        FlowChannelRefresh             matlab.ui.control.Image
        FlowChannelLabel               matlab.ui.control.Label
        FlowMetadata                   matlab.ui.control.Label
        FlowAttributesPanelRightBtn    matlab.ui.control.Image
        FlowAttributesPanelLeftBtn     matlab.ui.control.Image
        FlowAttributesPanelVisibleIdx  matlab.ui.control.Label
        FlowPanelLabel                 matlab.ui.control.Label
        SpectrumFlowList               matlab.ui.control.DropDown
        Toolbar                        matlab.ui.container.GridLayout
        tool_LayoutRight               matlab.ui.control.Image
        tool_Separator3                matlab.ui.control.Image
        tool_UploadFinalFile           matlab.ui.control.Image
        tool_GenerateReport            matlab.ui.control.Image
        tool_OpenPopupProject          matlab.ui.control.Image
        tool_Separator2                matlab.ui.control.Image
        tool_OpenPopupMerge            matlab.ui.control.Image
        tool_TimestampLabel            matlab.ui.control.Label
        tool_TimestampSlider           matlab.ui.control.Slider
        tool_LoopControl               matlab.ui.control.Image
        tool_Play                      matlab.ui.control.Image
        tool_Separator1                matlab.ui.control.Image
        tool_LayoutLeft                matlab.ui.control.Image
        ContextMenuChannels            matlab.ui.container.ContextMenu
        ContextMenuChannelInfo         matlab.ui.container.Menu
        ContextMenuDeleteChannel       matlab.ui.container.Menu
        ContextMenuDeleteChannels      matlab.ui.container.Menu
        ContextMenuAddDetectionLimits  matlab.ui.container.Menu
        ContextMenuAddChannelAsEmission  matlab.ui.container.Menu
        ContextMenuEmissions           matlab.ui.container.ContextMenu
        ContextMenuWhoIsEmission       matlab.ui.container.Menu
        ContextMenuDeleteEmission      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'PLAYBACK'
        AppHandleNameInBase
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
        popupContainer

        SubTabGroup = struct('Children', -1, 'UserData', [])

        % Handles dos eixos cartesianos utilizados por este módulo. No futuro,
        % simplificar para uma lista de handles, permitindo a criação dinâmica
        % de quantos eixos forem necessários, como ocorre na geração do relatório.
        UIAxes1
        UIAxes2
        UIAxes3

        % Informações relacionadas ao specData selecionado, com atalhos para 
        % os principais metadados e a definição dos limites dos eixos x, y e 
        % z dos eixos cartesianos. No futuro, remover essa propriedade.
        bandObj

        % Armazena limites padrão dos eixos cartesianos computados em método 
        % de model.Band (app.bandObj).
        restoreView = struct( ...
            'ID', {}, ...
            'xLim', {}, ...
            'yLim', {}, ...
            'cLim', {} ...
        )

        % Estado do playback:
        %  -1: foi solicitada a troca ou recarga do fluxo durante a execução
        %   0: pausado ou parado
        %   1: em execução
        plotUpdateEvent = 0

        % Handles dos objetos gráficos do plot.
        plotHandles

        % Índice da varredura atual.
        sweepTimeIdx
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        projectData

        % Armazena o hash da emissão em edição, de forma a mantê-la selecionada 
        % após alteração das suas características (via tabela ou no próprio plot, 
        % ajustando a ROI).
        emissionSelectedHash = ''
    end


    properties (Constant)
        %-----------------------------------------------------------------%
        WEEK_DAYS_MAP = dictionary( ...
            [1, 2, 3, 4, 5, 6, 7], ...
            ["Domingo", "Segunda-feira", "Terça-feira", "Quarta-feira", "Quinta-feira", "Sexta-feira", "Sábado"] ...
        )
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    case 'onLocationEditRequested'
                        flowIdx = findSpecDataIndex(app);
                        ipcMainMatlabOpenPopupApp(app.mainApp, app, 'Location', app.Context, flowIdx)

                    case 'onFilterRequested'
                        flowIdx = findSpecDataIndex(app);

                        questionMsg = [ ...
                            'É possível aplicar filtros para remover varreduras com base ' ...
                            'em critérios temporais ou de nível. E, além disso, é possível aplicar ' ...
                            'uma curva de calibração.<br><br>' ...
                            '•&thinsp;Filtro temporal: remove varreduras com base em quando ' ...
                            'foram coletadas (ex.: data ou dia da semana).<br><br>' ...
                            '•&thinsp;Filtros de nível: remove varreduras com base nos valores das ' ...
                            'amostras, considerando variação ou limiares (mínimo, máximo ou diferença ' ...
                            'entre valores).<br><br>' ...
                            '•&thinsp;Calibração: aplica uma curva de calibração da medida, mudando, ou não, ' ...
                            'a unidade de medida.<br><br>' ...
                            'O que deseja configurar?' ...
                        ];
                        userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Filtro temporal', 'Filtro nível', 'Calibração', 'Cancelar'}, 1, 4);

                        switch userSelection
                            case 'Filtro temporal'
                                stopPlaybackIfRunning(app)
                                ipcMainMatlabOpenPopupApp(app.mainApp, app, 'FilterByTime', app.Context, flowIdx)

                            case 'Filtro nível'
                                stopPlaybackIfRunning(app)
                                ipcMainMatlabOpenPopupApp(app.mainApp, app, 'FilterByLevel', app.Context, flowIdx)

                            case 'Calibração'
                                stopPlaybackIfRunning(app)
                                ipcMainMatlabOpenPopupApp(app.mainApp, app, 'Calibration', app.Context, flowIdx)

                            case 'Cancelar'
                                return
                        end

                    case 'onFlowUnlockRequested'
                        questionMsg = [ ...
                            'Este fluxo está bloqueado por ter sido modificado manualmente, por meio ' ...
                            'de operações de filtragem ou mesclagem. Ao desbloqueá-lo, ele poderá ser ' ...
                            'alterado ou removido automaticamente.<br><br>Deseja continuar?'
                        ];
                        userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Sim', 'Não'}, 1, 2);

                        if userSelection == "Não"
                            return
                        end

                        flowIdx = findSpecDataIndex(app);
                        ipcMainMatlabCallsHandler(app.mainApp, app, 'onFlowUnlockRequested', flowIdx)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, varargin)
            try
                switch class(callingApp)
                    case {'winAppAnalise', 'winAppAnalise_exported'}
                        eventName = varargin{1};

                        switch eventName
                            case {'onFileListAdded', ...
                                  'onFileListRemoved', ...
                                  'onFileFilterChanged', ...
                                  'onProjectLoad', ...
                                  'onFlowMergeRequested', ...
                                  'onReportFlowListChanged', ...
                                  'onFlowUnlockRequested', ...
                                  'onFilterByLevelRequested', ...
                                  'onFilterByTimeRequested', ...
                                  'onCalibrationCurveApplied', ...
                                  'onSpectralDataReadError'}
                                refreshFlowDropDown(app)

                                if app.plotUpdateEvent
                                    app.plotUpdateEvent = -1;
                                else
                                    onFlowDropDownValueChanged(app)
                                end

                            case 'onLocationChanged'
                                updateUIPanelContent(app)

                            case {'onEmissionAdded', ...
                                  'onEmissionParameterValueChanged', ...
                                  'onEmissionDeleted', ...
                                  'onDetectionSubBandsChanged'}
                                updateUIPanelContent(app)
                                updateEmissionsPlot(app)

                                if strcmp(eventName, 'onDetectionSubBandsChanged')
                                    updateDetectionLimitsPlot(app)
                                end

                            case 'onChannelAdded'
                                specData = app.bandObj.SpecData;

                                updateUIControlsState(app, specData)
                                updateUIPanelContent(app)
                                updateChannelsPlot(app)
            
                            case {'onTabNavigatorButtonPushed', ...
                                  'onPlaybackStarted'}
                                stopPlaybackIfRunning(app)

                            case {'onReportGenerate', 'onFinalReportFileChanged'}
                                specData = app.bandObj.SpecData;
                                updateToolbarState(app, specData)

                            case 'onFetchIssueDetails'
                                system   = varargin{2};
                                issue    = varargin{3};
                                details  = varargin{4};
                                msgError = varargin{5};

                                if ~isempty(msgError)
                                    error(msgError)
                                end

                                msg = util.HtmlTextGenerator.issueDetails(system, issue, details);
                                ui.Dialog(app.UIFigure, 'info', msg);

                            case 'onExternalFileModuleOpenRequest'
                                ipcMainMatlabOpenPopupApp(app.mainApp, app, 'ExternalFiles', 'PLAYBACK', varargin{2:end})

                            case 'onYAxesScaleChange'
                                set(app.UIAxes2, 'YScale', app.mainApp.General.plot.cartesianAxes.yOccupancyScale)
                            
                            case 'onKeyPressPlaybackControl'
                                key = varargin{2};
                                modifiers = varargin{3};
                                handlePlaybackKey(app, key, modifiers)
                             
                            otherwise
                                error('auxApp:winPlayback:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end

                    otherwise
                        error('auxApp:winPlayback:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            appName = class(app);
            switch tabIndex
                case 1 % PLAYBACK
                    elToModify = {
                        app.AxesToolbar;
                        app.AxesAnnotation;
                        app.SpectrumFlowList;
                        app.FlowPanelLabel;
                        app.FlowMetadata;
                        app.FlowDetectionLimitsEdit;
                        app.FlowEmissionsFileImport;
                        app.FlowEmissionsDataTips;
                        app.FlowEmissionsDrawRoi;
                        app.FlowEmissionsAdd;
                        app.FlowChannelFileImport;
                        app.FlowChannelRefresh;
                        app.FlowOccupancyEdit;
                        ...
                        app.tool_LayoutLeft;
                        app.tool_Play;
                        app.tool_LoopControl;
                        app.tool_OpenPopupMerge;
                        app.tool_OpenPopupProject;
                        app.tool_GenerateReport;
                        app.tool_UploadFinalFile;
                        app.tool_LayoutRight;
                        ...
                        app.axesTool_RestoreView;
                        app.axesTool_Pan;
                        app.axesTool_ExportGraphics;
                        app.axesTool_crearWrite;
                        app.axesTool_minHold;
                        app.axesTool_average;
                        app.axesTool_maxHold;
                        app.axesTool_emissions;
                        app.axesTool_persistence;
                        app.axesTool_occupancy;
                        app.axesTool_waterfall;
                        app.axesTool_DataTip;
                        ....
                        app.dockModule_Undock;
                        app.dockModule_Close
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.AxesToolbar.UserData.id, 'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                            struct('appName', appName, 'dataTag', app.AxesAnnotation.UserData.id, 'styleImportant', struct('pointerEvents', 'none')), ...
                            struct('appName', appName, 'dataTag', app.SpectrumFlowList.UserData.id, 'selector', 'input', 'styleImportant', struct('height', '44px'), 'dropDownBackgroundColor', struct('items', 'rgba(183, 49, 44, 0.75)', 'selectedItem', 'rgb(108, 4, 4)')), ...
                            struct('appName', appName, 'dataTag', app.FlowPanelLabel.UserData.id, 'styleImportant', struct('borderLeft', '3px solid #b7312c', 'paddingLeft', '8px')), ...
                            ...
                            struct('appName', appName, 'dataTag', app.tool_LayoutLeft.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Alterna visibilidade do painel à esquerda')), ...
                            struct('appName', appName, 'dataTag', app.tool_Play.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Controla execução do playback da monitoração')), ...
                            struct('appName', appName, 'dataTag', app.tool_LoopControl.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Controla loop da execução do playback')), ...
                            struct('appName', appName, 'dataTag', app.tool_OpenPopupMerge.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Mescla fluxos')), ...
                            struct('appName', appName, 'dataTag', app.tool_OpenPopupProject.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Edita informações do projeto<br>(fiscalizada, arquivo de backup etc)')), ...
                            struct('appName', appName, 'dataTag', app.tool_GenerateReport.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Gera relatório')), ...
                            struct('appName', appName, 'dataTag', app.tool_UploadFinalFile.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Upload relatório')), ...
                            struct('appName', appName, 'dataTag', app.tool_LayoutRight.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Alterna visibilidade do painel à direita')), ...
                            ...
                            struct('appName', appName, 'dataTag', app.axesTool_RestoreView.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Restaura limites iniciais dos eixos')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_Pan.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Habilita/desabilita pan')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_ExportGraphics.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exporta plot')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_crearWrite.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "ClearWrite"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_minHold.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "MinHold"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_average.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "Average"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_maxHold.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "MaxHold"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_emissions.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe todas as emissões')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_persistence.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe persistência')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_occupancy.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe ocupação do espectro')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_waterfall.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe waterfall')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_DataTip.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Habilita modo DataCursor (no waterfall)')), ...
                            ...
                            struct('appName', appName, 'dataTag', app.dockModule_Undock.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Close.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')), ...
                            ...
                            struct('appName', appName, 'dataTag', app.FlowDetectionLimitsEdit.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Edita os limites de detecção')), ...
                            struct('appName', appName, 'dataTag', app.FlowEmissionsFileImport.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Importar emissões')), ...
                            struct('appName', appName, 'dataTag', app.FlowEmissionsDataTips.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Converter datatips em emissões')), ...
                            struct('appName', appName, 'dataTag', app.FlowEmissionsDrawRoi.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Desenhar contorno de emissão')), ...
                            struct('appName', appName, 'dataTag', app.FlowEmissionsAdd.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Detectar emissões automaticamente')), ...
                            struct('appName', appName, 'dataTag', app.FlowChannelFileImport.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Importar canais')), ...
                            struct('appName', appName, 'dataTag', app.FlowChannelRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Volta às configurações iniciais')), ...
                            struct('appName', appName, 'dataTag', app.FlowOccupancyEdit.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Afere ocupação por outro método')) ...
                        });
                    catch
                    end

                    try
                        ui.TextView.startup(app.jsBackDoor, app.FlowMetadata, appName, struct('class', {{'textview--borderless', 'textview--wordbreak'}}));
                    catch
                    end

                otherwise
                     % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            app.projectData = app.mainApp.projectData;
            app.bandObj = model.Band('appAnalise:PLAYBACK', app.mainApp);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            app.FlowAttributesPanelVisibleIdx.UserData.index = 1;
            app.FlowEmissions.UserData.selectedRow = [];
            
            app.axesTool_Pan.UserData.status = false;
            app.axesTool_DataTip.UserData.status = false;
            app.axesTool_minHold.UserData = struct('status', false, 'imageSource', {{'MinHold_32Filled.png', 'MinHold_32.png'}});
            app.axesTool_average.UserData = struct('status', false, 'imageSource', {{'Average_32Filled.png', 'Average_32.png'}});
            app.axesTool_maxHold.UserData = struct('status', false, 'imageSource', {{'MaxHold_32Filled.png', 'MaxHold_32.png'}});
            app.axesTool_persistence.UserData.status = false;
            app.axesTool_occupancy.UserData.status = false;
            app.axesTool_waterfall.UserData.status = false;

            app.tool_LayoutLeft.UserData.status = true;
            app.tool_LayoutRight.UserData.status = false;
            app.tool_LoopControl.UserData.loopMode = true;

            initializeAxes(app)
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            refreshFlowDropDown(app)
            onFlowDropDownValueChanged(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initializeAxes(app)
            hParent = tiledlayout(app.AxesContainer, 4, 1, "Padding", "compact", "TileSpacing", "compact");

            app.UIAxes1 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [2 1];
            
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'YScale', app.mainApp.General.plot.cartesianAxes.yOccupancyScale});
            app.UIAxes2.Layout.Tile = 3;
            
            app.UIAxes3 = plot.axes.Creation(hParent, 'Cartesian', {'Layer', 'top', 'Box', 'on', 'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes3.Layout.Tile = 4;            

            plot.axes.Colormap(app.UIAxes1, app.PersistenceColormap.Value)
            plot.axes.Colormap(app.UIAxes3, app.WaterfallColormap.Value)
            plot.axes.Colorbar(app.UIAxes3, app.mainApp.General.plot.waterfall.Colorbar)

            ylabel(app.UIAxes1, 'Nível (dB)')
            ylabel(app.UIAxes2, 'Ocupação (%)')
            xlabel(app.UIAxes3, 'Frequência (MHz)')
            ylabel(app.UIAxes3, 'Instante')

            plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
            plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)

            linkaxes([app.UIAxes1, app.UIAxes2, app.UIAxes3], 'x')
            addlistener(app.UIAxes1, 'XLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes1, 'YLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes1, 'CLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes2, 'YLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes3, 'YLim', 'PostSet', @syncUIAxesLimitsWithSpinners);
            addlistener(app.UIAxes3, 'CLim', 'PostSet', @syncUIAxesLimitsWithSpinners);

            plot.axes.Interactivity.DefaultCreation([app.UIAxes1, app.UIAxes2], [dataTipInteraction, regionZoomInteraction, rulerPanInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes3,                [dataTipInteraction, regionZoomInteraction])

            function syncUIAxesLimitsWithSpinners(src, evt)
                switch evt.AffectedObject
                    case app.UIAxes1
                        switch src.Name
                            case 'XLim'
                                evtName  = app.LimitsXLim1.Tag;
                                evtValue = round(evt.AffectedObject.XLim, 3);

                                if app.LimitsXLim1.Value == evtValue(1) && app.LimitsXLim2.Value == evtValue(2)
                                    return
                                end

                                app.LimitsXLim1.Value = evtValue(1);
                                app.LimitsXLim2.Value = evtValue(2);

                            case 'YLim'
                                evtName  = app.LimitsYLim1.Tag;
                                evtValue = round(evt.AffectedObject.YLim, 1);

                                if app.LimitsYLim1.Value == evtValue(1) && app.LimitsYLim2.Value == evtValue(2)
                                    return
                                end

                                app.LimitsYLim1.Value = evtValue(1);
                                app.LimitsYLim2.Value = evtValue(2);

                            case 'CLim'
                                evtName  = app.PersistenceCLim1.Tag;
                                evtValue = round(evt.AffectedObject.CLim, 3);

                                if app.PersistenceCLim1.Value == evtValue(1) && app.PersistenceCLim2.Value == evtValue(2)
                                    return
                                end

                                app.PersistenceCLim1.Value = evtValue(1);
                                app.PersistenceCLim2.Value = evtValue(2);

                            otherwise
                                return
                        end

                    case app.UIAxes2
                        checkAxesLimitsCustomizations(app)
                        return

                    case app.UIAxes3
                        switch src.Name
                            case 'YLim'
                                checkAxesLimitsCustomizations(app)
                                return

                            case 'CLim'
                                evtName  = app.WaterfallCLim1.Tag;
                                evtValue = round(evt.AffectedObject.CLim);

                                if app.WaterfallCLim1.Value == evtValue(1) && app.WaterfallCLim2.Value == evtValue(2)
                                    return
                                end

                                app.WaterfallCLim1.Value = evtValue(1);
                                app.WaterfallCLim2.Value = evtValue(2);

                            otherwise
                                return
                        end

                    otherwise
                        return
                end

                specData = app.bandObj.SpecData;
                if ~isempty(specData)
                    update(specData, 'UserData:PlotDisplayConfig', evtName, evtValue)
                end

                checkAxesLimitsCustomizations(app)
            end
        end

        %-----------------------------------------------------------------%
        % ## LAYOUT ##
        %-----------------------------------------------------------------%
        function refreshFlowDropDown(app)
            specData = app.mainApp.specData;

            % Aberto, em 10/03/2026, reporte de BUG relacionado ao uidropdown, 
            % quando aplicado estilo "html". Ao apagar lista, o MATLAB não
            % apaga o valor atual do elemento na GUI. Assim que resolver
            % isso, basta inserir o addStyle uma única vez, não precisando
            % removê-lo.

            if ~isempty(specData)
                if isempty(app.SpectrumFlowList.StyleConfigurations)
                    addStyle(app.SpectrumFlowList, uistyle('Interpreter', 'html'))
                end

                previousValue = app.SpectrumFlowList.Value;
            
                [receivers, ~, indexes] = unique({specData.Receiver});
                items = {};
                itemsData = [];
                
                for ii = 1:numel(receivers)
                    receiverIdxs = find(indexes == ii)';
                    receiverName = util.layoutTreeNodeText(receivers{ii}, 'play_TreeBuilding');
                    
                    for jj = receiverIdxs
                        freqStart = specData(jj).MetaData.FreqStart / 1e+6;
                        freqStop  = specData(jj).MetaData.FreqStop  / 1e+6;

                        occupancyStatus = '';
                        if ismember(specData(jj).MetaData.DataType, class.Constants.occDataTypes)
                            occupancyStatus = ' (Ocupação)';
                        end
                        
                        reportStatus = '';
                        if ~isempty(specData(jj).UserData) && specData(jj).UserData.ReportInclude
                            reportStatus = '&emsp;&#x1F7E2;';
                        end

                        lockStatus = '';
                        if specData(jj).IsUserModified
                            lockStatus = '&emsp;&#x1F512;&#xFE0E;';
                        end
            
                        items{end+1} = sprintf('%s<br>└── %.3f&ensp;a&ensp;%.3f MHz%s%s%s', receiverName, freqStart, freqStop, occupancyStatus, reportStatus, lockStatus);
                        itemsData(end+1) = jj;
                    end
                end
            
                currentValue = {};    
                if ~isempty(previousValue)
                    if isnumeric(previousValue) && ismember(previousValue, itemsData)
                        currentValue = {'Value', previousValue};
                    end
                end
            
                set(app.SpectrumFlowList, 'Items', items, 'ItemsData', itemsData, currentValue{:})

            else             
                removeStyle(app.SpectrumFlowList)
                app.SpectrumFlowList.Items = {};
            end
        end

        %-----------------------------------------------------------------%
        function loadSelectedFlow(app, flowIdx)
            % Caso não esteja em cache os dados do fluxo espectral selecionado, 
            % procede-se a sua leitura, preenchendo a propriedade "Data" com
            % timestamps, níveis das varreduras, azimutes e notas de qualidade.
            % Caso ocorra erro na leitura, o registro é excluído e o layout é
            % reinicializado.
            specData = [];
            if ~isempty(flowIdx)
                specData = app.mainApp.specData(flowIdx);
            end

            if ~isempty(specData) 
                if isempty(specData.Data) || (numel(specData.Data{1}) ~= sum(specData.RelatedFiles.NumSweeps))
                    requestVisibilityChange(app.progressDialog, 'visible', 'unlocked')
    
                    try
                        populateSpectrum(specData, app.mainApp.metaData, app.projectData, app.mainApp.channelObj, app.mainApp.General)
                        
                        relatedHases = specData.UserData.OccupancyComputationMode.RelatedHashes;
                        if ~isempty(relatedHases)
                            relatedHashIdxs = find(ismember({app.mainApp.specData.Hash}, relatedHases));
                            populateSpectrum(app.mainApp.specData(relatedHashIdxs), app.mainApp.metaData, app.projectData, app.mainApp.channelObj, app.mainApp.General)
                        end
    
                    catch ME
                        ui.Dialog(app.UIFigure, 'error', ME.message);
    
                        delete(app.mainApp.specData(flowIdx))
                        app.mainApp.specData(flowIdx) = [];
                        refreshFlowDropDown(app)
    
                        ipcMainMatlabCallsHandler(app.mainApp, app, 'onSpectralDataReadError', app.Context)
                        requestVisibilityChange(app.progressDialog, 'hidden', 'unlocked')
                        return
                    end
    
                    requestVisibilityChange(app.progressDialog, 'hidden', 'unlocked')
                end

                initializeUnsetProperties(specData, app.mainApp.channelObj, app.mainApp.General)
            end

            % Atualiza app.bandObj, instância de model.Band, que guarda
            % informações derivadas da instância de model.SpecData sob análise.
            % A partir desse momento, a referência ao specData selecionado
            % se dará por app.bandObj.SpecData.
            updateSpectrumInfo(app.bandObj, specData);

            % Atualiza PAINEL À ESQUERDA com metadados do fluxo, algoritmos de
            % ocupação, detecção e classificação, além da lista de emissões.
            updateUIControlsState(app, specData)
            updateUIPanelContent(app)

            % Reseta o PLOT e atualiza informações que suportam o plot, como 
            % as dispostas no painel à direita.            
            resetPlotState(app, specData)

            % Atualiza PAINEL À DIREITA.
            applyCustomPlaybackSettings(app, specData)
        end

        %-----------------------------------------------------------------%
        function [flowIdx, emissionIdx] = findSpecDataIndex(app)
            flowIdx = app.SpectrumFlowList.Value;
            emissionIdx = app.FlowEmissions.UserData.selectedRow;
        end

        %-----------------------------------------------------------------%
        function updateUIControlsState(app, specData)
            % Atualiza atributos "Enable" e "Visible" dos componentes
            % afetados pelo estado atual de specData.
            nonEmptySpecData = ~isempty(specData);            
            hasMoreThanTwoSamples = nonEmptySpecData && sum(specData.RelatedFiles.NumSweeps) > 2;
            isWaterfallRenderedAsImage = hasMoreThanTwoSamples && strcmp(specData.UserData.PlotDisplayConfig.waterfall.function, 'image');
            isOccupancyFlow = nonEmptySpecData && ismember(specData.MetaData.DataType, class.Constants.occDataTypes);
            unchangedFlowChannels = ~nonEmptySpecData || (isempty(specData.UserData.ChannelUserDefined) && isequal(specData.UserData.ChannelLibraryRelatedIndexes, getRelatedChannelIndexes(app.mainApp.channelObj, specData, app.mainApp.General)));

            set([
                app.axesTool_crearWrite;
                app.axesTool_minHold;
                app.axesTool_average;
                app.axesTool_maxHold;
                app.axesTool_emissions
            ], 'Enable', nonEmptySpecData)

            set([
                app.FlowDetectionLimitsEdit;
                app.FlowEmissionsFileImport;
                app.FlowEmissionsDataTips;
                app.FlowEmissionsDrawRoi;
                app.FlowEmissionsAdd;
                app.FlowChannelFileImport
            ], 'Enable', nonEmptySpecData && ~isOccupancyFlow)

            app.FlowChannelRefresh.Visible = ~unchangedFlowChannels;

            set([
                app.FlowOccupancyEdit;
                app.axesTool_occupancy
            ], 'Enable', hasMoreThanTwoSamples && ~isOccupancyFlow)

            app.axesTool_persistence.Enable = hasMoreThanTwoSamples;
            app.axesTool_waterfall.Enable   = hasMoreThanTwoSamples;

            % DataCursorMode
            % O DataCursorMode é, de forma geral, uma interação ruim p/ eixos
            % cartesianos por bloquear as outras (Pan, RegionZoom etc). Por
            % essa razão, restringir o DataCursorMode apenas ao eixo específico
            % em que está sendo plotado a imagem (que não suporta a interação 
            % padrão de DataTip).
            app.axesTool_DataTip.Enable = isWaterfallRenderedAsImage;

            if ~isWaterfallRenderedAsImage && app.axesTool_DataTip.UserData.status
                onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_DataTip))
            end

            if isOccupancyFlow
                app.LimitsYLimLabel.Text = '%  ';
            else
                app.LimitsYLimLabel.Text = 'dB  ';
            end

            updateToolbarState(app, specData)
        end

        %-----------------------------------------------------------------%
        function updateToolbarState(app, specData)
            nonEmptySpecData = ~isempty(specData);

            set([
                app.tool_Play;
                app.tool_LoopControl;
                app.tool_TimestampSlider;
                app.tool_OpenPopupMerge
            ], 'Enable', nonEmptySpecData)

            app.tool_GenerateReport.Enable  = nonEmptySpecData && any(arrayfun(@(x) x.UserData.ReportInclude, app.mainApp.specData));
            app.tool_UploadFinalFile.Enable = ~isempty(app.projectData.modules.(app.Context).generatedFiles.lastHTMLDocFullPath);
        end

        %-----------------------------------------------------------------%
        function updateContextMenuState(app, tag)
            arguments
                app 
                tag {mustBeMember(tag, {'channels', 'emissions'})}
            end

            switch tag
                case 'channels'
                    hasChannel = ~isempty(app.FlowChannel.Items);
                    hasScalarChannel = isscalar(app.FlowChannel.Value);
                    hasChannelSelected = ~isempty(app.FlowChannel.Value);
                    
                    set(app.ContextMenuDeleteChannels, 'Enable', hasChannel)
                    set(app.ContextMenuChannelInfo, 'Enable', hasScalarChannel)
        
                    set([app.ContextMenuAddDetectionLimits, ...
                         app.ContextMenuAddChannelAsEmission, ...
                         app.ContextMenuDeleteChannel], 'Enable', hasChannelSelected)

                case 'emissions'
                    hasEmissions = ~isempty(app.FlowEmissions.Data);
                    numSelectedRows = numel(app.FlowEmissions.Selection);

                    set(app.ContextMenuWhoIsEmission,  'Enable', isscalar(numSelectedRows))
                    set(app.ContextMenuDeleteEmission, 'Enable', hasEmissions)
            end
        end

        %-----------------------------------------------------------------%
        function updateUIPanelContent(app)
            specData = app.bandObj.SpecData;

            hasData = ~isempty(specData);
            isSpectralData = hasData && ~ismember(specData.MetaData.DataType, class.Constants.occDataTypes);
            hasEmissions = isSpectralData && ~isempty(specData.UserData.Emissions);

            if hasData
                % Verifica se o handle para o app continua ativo no workspace
                % base do MATLAB, possibilitando que clicks no ui.TextView sejam 
                % capturados corretamente.
                appHandleNameInBase = app.AppHandleNameInBase;
                if isempty(appHandleNameInBase) || ~evalin('base', sprintf('exist("%s", "var") && isa(%s, "%s") && isvalid(%s)', appHandleNameInBase, appHandleNameInBase, class(app), appHandleNameInBase))
                    app.AppHandleNameInBase = ui.Table.exportAppHandleToBaseWorkspace(app);
                end

                app.FlowMetadata.Text = util.HtmlTextGenerator.getSelectedFlowMetadata(specData, app.AppHandleNameInBase, app.mainApp.General);

            else
                app.FlowMetadata.Text = '';
            end

            if isSpectralData
                channels = {};
                
                channelLibIndex = specData.UserData.ChannelLibraryRelatedIndexes;
                if ~isempty(channelLibIndex)
                    channels = arrayfun(@(x) sprintf('%.3f – %.3f MHz  •  %s', x.Band(1), x.Band(2), x.Name), app.mainApp.channelObj.Channel(channelLibIndex), 'UniformOutput', false);
                end

                channelUserDefined = specData.UserData.ChannelUserDefined;
                if ~isempty(channelUserDefined)
                    channels = [channels; arrayfun(@(x) sprintf('%.3f – %.3f MHz  •  %s', x.Band(1), x.Band(2), x.Name), channelUserDefined, 'UniformOutput', false)'];
                end

                app.FlowChannel.Items = channels;


                if specData.UserData.DetectionSubBandsEnabled && ~isempty(specData.UserData.DetectionSubBands)
                    app.FlowDetectionLimits.Items = cellstr(string(specData.UserData.DetectionSubBands.FreqStart) + " – " + string(specData.UserData.DetectionSubBands.FreqStop) + " MHz");
                else
                    app.FlowDetectionLimits.Items = {sprintf('%.3f – %.3f MHz (padrão)', specData.MetaData.FreqStart/1e+6, specData.MetaData.FreqStop/1e+6)};
                end

                occupancyList = {};
                cacheIndex = specData.UserData.OccupancyComputationMode.CacheIndex;
                selectedHash = specData.UserData.OccupancyComputationMode.SelectedHash;                
                selectedStatus = false;
                
                for ii = 1:numel(specData.UserData.OccupancyComputationMode.RelatedHashes)
                    relatedHash = specData.UserData.OccupancyComputationMode.RelatedHashes{ii};
                    
                    threshold = -1;
                    [~, occupancyFlowIdx] = ismember(relatedHash, {app.mainApp.specData.Hash});
                    if occupancyFlowIdx
                        threshold = app.mainApp.specData(occupancyFlowIdx).MetaData.Threshold;
                    end

                    levelUnit = app.mainApp.specData(occupancyFlowIdx).MetaData.LevelUnit;
                    occupancyRegister = sprintf('Limiar: %d %s (coleta) (%s...)', threshold, levelUnit, relatedHash(1:18));

                    if strcmp(relatedHash, selectedHash)
                        selectedStatus = true;
                        occupancyRegister = [occupancyRegister ' 🟢'];
                    end

                    occupancyList{end+1} = occupancyRegister;
                end

                for jj = 1:numel(specData.UserData.OccupancyFiniteIntegrationCache)
                    [minTHR, maxTHR] = bounds(specData.UserData.OccupancyFiniteIntegrationCache(jj).Threshold);
                    if minTHR == maxTHR
                        threshold = num2str(minTHR);
                    else
                        threshold = sprintf('%d a %d', minTHR, maxTHR);
                    end

                    levelUnit = specData.MetaData.LevelUnit;
                    occupancyRegister = sprintf('Limiar: %s %s', threshold, levelUnit);

                    if ~selectedStatus && isequal(jj, cacheIndex)
                        occupancyRegister = [occupancyRegister ' 🟢'];
                    end

                    occupancyList{end+1} = occupancyRegister;
                end
                app.FlowOccupancy.Items = occupancyList;

            else
                app.FlowChannel.Items = {};
                app.FlowDetectionLimits.Items = {};
                app.FlowOccupancy.Items = {};
            end

            % Subtab "CANAIS"
            updateContextMenuState(app, 'channels')

            % Subtab "EMISSÕES"
            emissionSelectedIdx = [];
            if hasEmissions
                [~, emissionSelectedIdx] = ismember(app.emissionSelectedHash, specData.UserData.Emissions.Uuid);
                if ~emissionSelectedIdx
                    emissionSelectedIdx = 1;
                end
                
                app.FlowEmissions.Data = specData.UserData.Emissions(:, {'Frequency', 'BandWidthkHz', 'Description'});
                app.FlowEmissions.Selection = emissionSelectedIdx;
                app.FlowEmissions.UserData.selectedRow = emissionSelectedIdx;
            else
                app.FlowEmissions.Data = [];
                app.FlowEmissions.UserData.selectedRow = [];
            end

            applyEmissionsTableStyle(app, hasEmissions, emissionSelectedIdx)
            updateContextMenuState(app, 'emissions')
        end

        %-----------------------------------------------------------------%
        function applyCustomPlaybackSettings(app, specData)
            if ~isempty(specData)
                iconDictionary = dictionary([true, false], [1, 2]);
    
                % CONTROLES AXESTOOLBAR
                if ~isequal(app.axesTool_minHold.UserData, specData.UserData.PlotDisplayConfig.controls.minHold)
                    app.axesTool_minHold.UserData.status  = specData.UserData.PlotDisplayConfig.controls.minHold;
                    app.axesTool_minHold.ImageSource      = app.axesTool_minHold.UserData.imageSource{iconDictionary(app.axesTool_minHold.UserData.status)};
                end
    
                if ~isequal(app.axesTool_average.UserData, specData.UserData.PlotDisplayConfig.controls.average)
                    app.axesTool_average.UserData.status  = specData.UserData.PlotDisplayConfig.controls.average;
                    app.axesTool_average.ImageSource      = app.axesTool_average.UserData.imageSource{iconDictionary(app.axesTool_average.UserData.status)};
                end
    
                if ~isequal(app.axesTool_maxHold.UserData, specData.UserData.PlotDisplayConfig.controls.maxHold)
                    app.axesTool_maxHold.UserData.status  = specData.UserData.PlotDisplayConfig.controls.maxHold;
                    app.axesTool_maxHold.ImageSource      = app.axesTool_maxHold.UserData.imageSource{iconDictionary(app.axesTool_maxHold.UserData.status)};
                end
    
                app.axesTool_persistence.UserData.status  = specData.UserData.PlotDisplayConfig.controls.persistence;
                app.axesTool_occupancy.UserData.status    = specData.UserData.PlotDisplayConfig.controls.occupancy;
                app.axesTool_waterfall.UserData.status    = specData.UserData.PlotDisplayConfig.controls.waterfall;
    
                % CONTROLES PAINEL À DIREITA
                app.LayoutRatio.Items                     = { specData.UserData.PlotDisplayConfig.layoutRatio };
                
                plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                plot.axes.Layout.RatioAspect([ app.UIAxes1, app.UIAxes2, app.UIAxes3 ], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)
                
                app.PersistenceInterpolation.Value        = specData.UserData.PlotDisplayConfig.persistence.interpolation;
                app.PersistenceWindowSize.Value           = specData.UserData.PlotDisplayConfig.persistence.windowSize;
                app.PersistenceWindowSizeValue.Text       = specData.UserData.PlotDisplayConfig.persistence.windowSize;
                app.PersistenceTransparency.Value         = specData.UserData.PlotDisplayConfig.persistence.transparency;
                app.PersistenceColormap.Value             = specData.UserData.PlotDisplayConfig.persistence.colormap;
    
                app.WaterfallFunction.Value               = specData.UserData.PlotDisplayConfig.waterfall.function;
                app.WaterfallDecimation.Value             = specData.UserData.PlotDisplayConfig.waterfall.decimation;
                app.WaterfallDecimationValue.Text         = specData.UserData.PlotDisplayConfig.waterfall.decimation;
                app.WaterfallMeshStyle.Value              = specData.UserData.PlotDisplayConfig.waterfall.meshStyle;            
                app.WaterfallColormap.Value               = specData.UserData.PlotDisplayConfig.waterfall.colormap;
    
                app.WaterfallCLim1.Value                  = specData.UserData.PlotDisplayConfig.limits.waterfall.current(1);
                app.WaterfallCLim2.Value                  = specData.UserData.PlotDisplayConfig.limits.waterfall.current(2);
            end

            updatePersistencePanel(app, 'initialization')
            updateWaterfallPanel(app)
            checkAxesLimitsCustomizations(app)
        end

        %-----------------------------------------------------------------%
        function checkAxesLimitsCustomizations(app)
            specData = app.bandObj.SpecData;
            
            if ~isempty(specData)
                switch class(app.UIAxes3.YAxis)
                    case 'matlab.graphics.axis.decorator.DatetimeRuler'
                        uiAxes3DefaultYLimits = [specData.Data{1}(1), specData.Data{1}(end)];
                    otherwise % 'matlab.graphics.axis.decorator.NumericRuler'
                        uiAxes3DefaultYLimits = [1, numel(specData.Data{1})];
                end       

                app.LimitsRefresh.Visible = ...
                    ~isequal(specData.UserData.PlotDisplayConfig.limits.frequency.initial, specData.UserData.PlotDisplayConfig.limits.frequency.current) || ...
                    ~isequal(specData.UserData.PlotDisplayConfig.limits.level.initial, specData.UserData.PlotDisplayConfig.limits.level.current) || ...
                    (specData.UserData.PlotDisplayConfig.controls.occupancy && ~isequal(app.UIAxes2.YLim, [0, 100])) || ...
                    (specData.UserData.PlotDisplayConfig.controls.waterfall && ~isequal(app.UIAxes3.YLim, uiAxes3DefaultYLimits));
                
                app.PersistenceCLimRefresh.Visible = ~strcmp(app.UIAxes1.UserData.CLimMode, 'auto') && strcmp(app.PersistenceWindowSize.Value, 'full');
                app.WaterfallCLimRefresh.Visible = ~strcmp(app.UIAxes3.UserData.CLimMode, 'auto');
                
            else
                set([
                    app.LimitsRefresh;
                    app.PersistenceCLimRefresh;
                    app.WaterfallCLimRefresh
                ], 'Visible', false)
            end
        end

        %-----------------------------------------------------------------%
        % ## PLOT CONTROLLER ##
        %-----------------------------------------------------------------%
        function resetPlotState(app, specData)
            cla([app.UIAxes1, app.UIAxes2, app.UIAxes3])
            
            ysecondarylabel(app.UIAxes2, '')
            ysecondarylabel(app.UIAxes3, '')

            app.UIAxes1.UserData.CLimMode = 'auto';
            app.UIAxes3.UserData.CLimMode = 'auto';

            app.plotHandles = struct( ...
                'clearWrite', [], ...
                'minHold', [], ...
                'average', [], ...
                'maxHold', [], ...
                'persistence', [], ...
                'thresholdLine', [], ...
                'thresholdLabel', [], ...
                'waterfall', [], ...
                'waterfallTime', [] ...
            );
    
            app.sweepTimeIdx = 1;
            app.tool_TimestampSlider.Value = 0;
            resetRestoreView(app)

            if ~isempty(specData)
                updateTimestamp(app, 1, app.bandObj.NumSweeps, app.bandObj.YLimitsTime(1))
                app.AxesAnnotation.Text = sprintf('%s    \n%.3f – %.3f MHz    ', app.bandObj.Receiver, app.bandObj.FreqStart, app.bandObj.FreqStop);

            else
                clearTimestamp(app)
                app.AxesAnnotation.Text = '';

                if app.axesTool_occupancy.UserData.status || app.axesTool_waterfall.UserData.status
                    app.axesTool_occupancy.UserData.status = false;
                    app.axesTool_waterfall.UserData.status = false;

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)
                end

                onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_RestoreView))
            end
        end

        %-----------------------------------------------------------------%
        function resetRestoreView(app)
            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', app.bandObj.XLimits, 'yLim', app.bandObj.YLimitsLevel, 'cLim', 'auto');
            app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', app.bandObj.XLimits, 'yLim', [0, 100],                 'cLim', 'auto');
            app.restoreView(3) = struct('ID', 'app.UIAxes3', 'xLim', app.bandObj.XLimits, 'yLim', app.bandObj.YLimitsTime,  'cLim', app.bandObj.CLimits);
        end

        %-----------------------------------------------------------------%
        function updatePlot(app)
            specData = app.bandObj.SpecData;
            if isempty(specData)
                return
            end
        
            if isempty(app.plotHandles.clearWrite)
                set(app.UIAxes1, 'XLim', app.restoreView(1).xLim, 'YLim', app.restoreView(1).yLim)
                ylabel(app.UIAxes1, sprintf('Nível (%s)', app.bandObj.LevelUnit))
        
                % ClearWrite, MinHold, Average, e MaxHold
                for plotTag = ["clearWrite", "minHold", "average", "maxHold"]
                    if ismember(plotTag, {'minHold', 'average', 'maxHold'}) && ~eval(sprintf('app.axesTool_%s.UserData.status', plotTag))
                        continue
                    end
        
                    app.plotHandles.(plotTag) = plot.draw2D.OrdinaryLine(app.UIAxes1, plotTag, app.bandObj, app.sweepTimeIdx);
                    plot.datatip.Template(app.plotHandles.(plotTag), "Frequency+Level", app.bandObj.LevelUnit)
                end
                
                % Persistence
                if app.axesTool_persistence.UserData.status
                    updatePersistencePlot(app, 'Creation')
                end

                % Emissions
                updateEmissionsPlot(app)

                % BandLimits & Channels
                updateDetectionLimitsPlot(app)
                updateChannelsPlot(app)
        
                % Occupancy
                if app.axesTool_occupancy.UserData.status
                    updateOccupancyPlot(app, 'Creation', specData)
                end
        
                % Waterfall
                if app.axesTool_waterfall.UserData.status
                    updateWaterfallPlot(app)
                end
        
                % DataTips
                if ~isempty(specData.UserData.PlotDisplayConfig.dataTips)
                    dtConfig = specData.UserData.PlotDisplayConfig.dataTips;
                    dtParent = [app.UIAxes1, app.UIAxes2, app.UIAxes3];
                    plot.datatip.Create('customPlayback', dtConfig, dtParent)
                end
        
            else
                % ClearWrite, MinHold, Average, e MaxHold
                for plotTag = ["clearWrite", "minHold", "average", "maxHold"]
                    if ismember(plotTag, {'minHold', 'average', 'maxHold'})
                        if ~eval(sprintf('app.axesTool_%s.UserData.status', plotTag)) || isinf(app.mainApp.General.context.PLAYBACK.integration.traceMode)
                            continue
                        end
                    end
        
                    plot.draw2D.OrdinaryLineUpdate(plotTag, app.plotHandles.(plotTag), app.bandObj, app.sweepTimeIdx);
                end
        
                % Persistence
                updatePersistencePlot(app, 'Update')

                % WaterfallTime
                if app.axesTool_waterfall.UserData.status && ~isempty(app.plotHandles.waterfallTime)
                    plot.draw2D.OrdinaryLineUpdate('waterfallTime', app.plotHandles.waterfallTime, app.bandObj, app.sweepTimeIdx);
                end
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        % ## PERSISTENCE ##
        %-----------------------------------------------------------------%
        function updatePersistencePanel(app, operationType)
            elementHandles = findobj(app.PersistencePanelGrid.Children, '-not', 'Type', 'uilabel', '-or', 'Type', 'uiimage');
            set(elementHandles, 'Enable', app.axesTool_persistence.UserData.status)

            switch operationType
                case 'initialization'
                    % ...

                case 'updateLimits'
                    spinnersHandles = [ app.PersistenceCLim1, app.PersistenceCLim2 ];
        
                    if ~isempty(app.plotHandles.persistence)
                        switch app.PersistenceWindowSize.Value
                            case 'full'
                                set(spinnersHandles, 'Enable', 1)
                            otherwise
                                set(spinnersHandles, 'Enable', 0)
                        end
        
                        app.restoreView(1).cLim = app.UIAxes1.CLim;
                        app.PersistenceCLim1.Value = round(app.UIAxes1.CLim(1), 3);
                        app.PersistenceCLim2.Value = round(app.UIAxes1.CLim(2), 3);
        
                    else
                        set(spinnersHandles, 'Enable', 0)
                    end
            end
        end

        %-----------------------------------------------------------------%
        function updatePersistencePlot(app, operationType)
            switch operationType
                case 'Creation'
                    [app.plotHandles.persistence, app.PersistenceWindowSizeValue.Text] = plot.Persistence('Creation', app.plotHandles.persistence, app.UIAxes1, app.bandObj, app.sweepTimeIdx);
                    updatePersistencePanel(app, 'updateLimits')

                case 'Update'
                    if app.axesTool_persistence.UserData.status && ~strcmp(app.PersistenceWindowSizeValue.Text, 'full')
                        app.plotHandles.persistence = plot.Persistence('Update', app.plotHandles.persistence, app.UIAxes1, app.bandObj, app.sweepTimeIdx);
                        updatePersistencePanel(app, 'updateLimits')
                    end

                case 'Delete'
                    app.plotHandles.persistence = plot.Persistence('Delete', app.plotHandles.persistence);
                    updatePersistencePanel(app, 'initialization')
            end
        end

        %-----------------------------------------------------------------%
        % ## EMISSIONS ## 
        %-----------------------------------------------------------------%
        function updateEmissionsPlot(app)
            delete(findobj(app.UIAxes1, 'Tag', 'emissions', '-or', 'Tag', 'emissionsTemp', '-or', 'Tag', 'emissionSelected'))
            app.emissionSelectedHash = '';

            specData = app.bandObj.SpecData;
            if isempty(specData)
                return
            end

            emissions = specData.UserData.Emissions;
            if isempty(emissions)
                if ~isempty(app.plotHandles.clearWrite) && isvalid(app.plotHandles.clearWrite)
                    app.plotHandles.clearWrite.MarkerIndices = [];
                end

                return
            end

            emissionSelectedIdx = app.FlowEmissions.UserData.selectedRow;
            app.emissionSelectedHash = emissions.Uuid(emissionSelectedIdx);

            app.plotHandles.clearWrite.MarkerIndices = emissions.FrequencyIdx;
            emissionSelectedHandle = plot.Emissions.draw(emissionSelectedIdx, app.UIAxes1, app.restoreView, app.bandObj);

            addlistener(emissionSelectedHandle, 'MovingROI', @(~, evt)emissionROI(evt));
            addlistener(emissionSelectedHandle, 'ROIMoved',  @(~, evt)emissionROI(evt));

            function emissionROI(evt)
                emissionSelectedIdx = app.FlowEmissions.UserData.selectedRow;
    
                switch(evt.EventName)
                    case 'MovingROI'
                        plot.axes.Interactivity.DefaultDisable([app.UIAxes1, app.UIAxes2, app.UIAxes3])
            
                        freqCenter = round(emissionSelectedHandle.Position(1) + emissionSelectedHandle.Position(3)/2, 3);
                        if (freqCenter*1e+6 < specData.MetaData.FreqStart) || (freqCenter*1e+6 > specData.MetaData.FreqStop)                        
                           return
                        end

                        bandWidthkHz = round(emissionSelectedHandle.Position(3) * 1000, 3);

                        app.FlowEmissions.Data(emissionSelectedIdx, {'Frequency', 'BandWidthkHz'}) = {freqCenter, bandWidthkHz};                        
                        app.plotHandles.clearWrite.MarkerIndices(emissionSelectedIdx) = freq2idx(app.bandObj, freqCenter*1e+6);
                        
                    case 'ROIMoved'
                        plot.axes.Interactivity.DefaultEnable([app.UIAxes1, app.UIAxes2, app.UIAxes3])
    
                        freqCenter   = app.FlowEmissions.Data.Frequency(emissionSelectedIdx);
                        frequencyIdx = freq2idx(app.bandObj, freqCenter * 1e+6);                        
                        bandWidthkHz = app.FlowEmissions.Data.BandWidthkHz(emissionSelectedIdx);

                        updateEmissionTable(app, specData, 'Edit', emissionSelectedIdx, 'Frequency|BandWidth', frequencyIdx, freqCenter, bandWidthkHz, app.mainApp.channelObj)
                end
            end
        end

        %-----------------------------------------------------------------%
        function updateTimestamp(app, sweepIdx, sweepCount, sweepTime)
            weekDay = app.WEEK_DAYS_MAP(weekday(sweepTime));
            app.tool_TimestampLabel.Text = sprintf('%d de %d\n%s • %s', sweepIdx, sweepCount, sweepTime, weekDay);
        end
        
        %-----------------------------------------------------------------%
        function clearTimestamp(app)
            app.tool_TimestampLabel.Text = '';
        end

        %-----------------------------------------------------------------%
        function updateEmissionTable(app, specData, updateType, emissionIdx, varargin)
            arguments
                app
                specData
                updateType {mustBeMember(updateType, {'Edit', 'Delete', 'DeleteAll'})}
                emissionIdx
            end

            arguments (Repeating)
                varargin
            end

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')

            switch updateType
                case 'Edit'
                    parameterName = varargin{1};
                    update(specData, 'UserData:Emissions', updateType, parameterName, emissionIdx, varargin{2:end})
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionParameterValueChanged', app.Context)

                case {'Delete', 'DeleteAll'}
                    update(specData, 'UserData:Emissions', 'Delete', emissionIdx)
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionDeleted', app.Context)
            end

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
        end

        %-----------------------------------------------------------------%
        function applyEmissionsTableStyle(app, hasEmissions, emissionSelectedIdx)
            if ~isempty(app.FlowEmissions.StyleConfigurations)
                removeStyle(app.FlowEmissions)
            end

            if hasEmissions
                addStyle(app.FlowEmissions, uistyle('Icon', 'eye.svg', 'IconAlignment', 'leftmargin'), 'cell', [emissionSelectedIdx, 1])
            end
        end

        %-----------------------------------------------------------------%
        function updateDetectionLimitsPlot(app)
            plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, 'bandLimits')
        end

        %-----------------------------------------------------------------%
        function updateChannelsPlot(app)
            delete(findobj(app.UIAxes1, 'Tag', 'channel'))

            specData = app.bandObj.SpecData;
            if isempty(specData) || isempty(app.FlowChannel.Value)
                return
            end

            channels = [];
            channelLibIndex = specData.UserData.ChannelLibraryRelatedIndexes;
            if ~isempty(channelLibIndex)
                channels = app.mainApp.channelObj.Channel(channelLibIndex);
            end

            channelUserDefined = specData.UserData.ChannelUserDefined;
            if ~isempty(channelUserDefined)
                channels = [channels; channelUserDefined'];
            end

            if ~isempty(channels)
                channels = channels(app.FlowChannel.ValueIndex, :);
                channelsTable = PreparingData2Plot(app.mainApp.channelObj, [], channels);
                plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, 'channel', channelsTable)
            end
        end

        %-----------------------------------------------------------------%
        % ## WATERFALL ##
        %-----------------------------------------------------------------%
        function updateWaterfallPanel(app)
            specData = app.bandObj.SpecData;
            elementHandles = findobj(app.WaterfallPanelGrid.Children, '-not', 'Type', 'uilabel', '-or', 'Type', 'uiimage');
            
            if app.axesTool_waterfall.UserData.status
                elementDisabled = [];
                if strcmp(app.WaterfallFunction.Value, 'image')
                    elementDisabled = app.WaterfallMeshStyle;
                end

                set(setdiff(elementHandles, elementDisabled), 'Enable', true)
                if ~isempty(elementDisabled)
                    set(elementDisabled, 'Enable', false)
                end

                app.restoreView(3).yLim = app.UIAxes3.YLim;
                app.restoreView(3).cLim = app.UIAxes3.CLim;

                app.WaterfallCLim1.Value = round(double(app.UIAxes3.CLim(1)));
                app.WaterfallCLim2.Value = round(double(app.UIAxes3.CLim(2)));

            else
                set(elementHandles, 'Enable', false)
            end

            updateUIControlsState(app, specData)
        end

        %-----------------------------------------------------------------%
        function updateWaterfallPlot(app)
            [app.plotHandles.waterfall, app.WaterfallDecimationValue.Text] = plot.Waterfall('Creation', app.plotHandles.waterfall, app.UIAxes3, app.bandObj);
            plot.axes.Layout.YLabel(app.plotHandles.waterfall, app.axesTool_waterfall.UserData.status)
            updateWaterfallPanel(app)

            % Timeline
            if app.mainApp.General.context.PLAYBACK.waterfallTimeVisibility
                app.plotHandles.waterfallTime = plot.draw2D.OrdinaryLine(app.UIAxes3, 'waterfallTime', app.bandObj, app.sweepTimeIdx);
            end
        end

        %-----------------------------------------------------------------%
        function updateOccupancyPlot(app, operationType, specData)
            switch operationType
                case 'Creation'
                    selectedHash = specData.UserData.OccupancyComputationMode.SelectedHash;
                    [~, selectedHashIdx] = ismember(selectedHash, {app.mainApp.specData.Hash});
        
                    if selectedHashIdx
                        occParameters  = struct( ...
                            'Method', 'Linear fixo (coleta)', ...
                            'Threshold', app.mainApp.specData(selectedHashIdx).MetaData.Threshold, ...
                            'IntegrationTime', app.mainApp.specData(selectedHashIdx).RelatedFiles.RevisitTime / 60 ... % sec >> min
                        );
                        occThreshold   = app.mainApp.specData(selectedHashIdx).MetaData.Threshold;
                        occAverageData = app.mainApp.specData(selectedHashIdx).Data{3}(:, 2);

                    else
                        cacheIdx = specData.UserData.OccupancyComputationMode.CacheIndex;
                        if ~isempty(cacheIdx)
                            occParameters  = specData.UserData.OccupancyFiniteIntegrationCache(cacheIdx).Parameters;
                            occThreshold   = specData.UserData.OccupancyFiniteIntegrationCache(cacheIdx).Threshold;
                            occAverageData = specData.UserData.OccupancyFiniteIntegrationCache(cacheIdx).Data{3}(:, 2);
                        else
                            occParameters  = specData.UserData.OccupancyCumulativeIntegration.Parameters;
                            occThreshold   = specData.UserData.OccupancyCumulativeIntegration.Threshold;
                            occMatrix      = specData.UserData.OccupancyCumulativeIntegration.Matrix;
                            occAverageData = 100 * sum(occMatrix, 2) / width(occMatrix);
                        end
                    end
        
                    plot.Occupancy('Creation', app.UIAxes1, app.UIAxes2, occParameters, occThreshold, occAverageData, app.bandObj, app.mainApp.General)

                case 'Delete'
                    plot.Occupancy('Delete', app.UIAxes1, app.UIAxes2)
            end
        end

        %-----------------------------------------------------------------%
        % ## PLAYBACK ##
        %-----------------------------------------------------------------%
        
        function handlePlaybackKey(app, key, modifiers)
            %--------------------------------------------------------------
            % Implementa controles via teclado para o playback
            %--------------------------------------------------------------
            if ~isempty(modifiers)
                return
            end

            flowIdx = findSpecDataIndex(app);

            switch key
                %----------------------------------------------------------
                % controle de play e pause
                case 'space'
                    togglePlayback(app)
                %----------------------------------------------------------
                % controle de retrocesso de um frame
                case 'leftarrow'
                    stepPlaybackFrame(app, -1)

                %----------------------------------------------------------
                % controle de avanço de 1 frame
                case 'rightarrow'
                    stepPlaybackFrame(app, 1)

            end

        end

        %------------------------------------------------------------------
        % Controle de avanço de retrocesso de frames no playback.
        % As setas só alteram o frame enquanto o playback está pausado.  
        function stepPlaybackFrame(app, direction)

            if app.plotUpdateEvent ~= 0
                return
            end
            flowIdx = findSpecDataIndex(app);
            if isempty(flowIdx) || ~isnumeric(flowIdx) || ...
                    ~isscalar(flowIdx) || ~isfinite(flowIdx) || ...
                    flowIdx ~= fix(flowIdx) || ...
                    flowIdx < 1 || flowIdx > numel(app.mainApp.specData)
                return
            end
            specData = app.mainApp.specData(flowIdx);
            if isempty(specData.Data) || numel(specData.Data) < 2 || ...
                    isempty(specData.Data{1}) || isempty(specData.Data{2})
                return
            end
            nSweeps = numel(specData.Data{1});
            % Os timestamps e as colunas de nível devem representar os mesmos frames.
            if nSweeps == 0 || size(specData.Data{2}, 2) ~= nSweeps
                return
            end
            if isempty(app.sweepTimeIdx) || ~isnumeric(app.sweepTimeIdx) || ...
                    ~isscalar(app.sweepTimeIdx) || ~isfinite(app.sweepTimeIdx)
                return
            end
            currentIdx = min(max(round(app.sweepTimeIdx), 1), nSweeps);
            nextIdx = min(max(currentIdx + direction, 1), nSweeps);
            if nextIdx == currentIdx
                return
            end
            app.sweepTimeIdx = nextIdx;
            updateCurrentFrame(app, flowIdx, nSweeps)
        end

        %------------------------------------------------------------------
        % Controle de inicio e pausa do playback
        function togglePlayback(app)
            % Uma segunda ativação pausa a execução ou cancela uma troca pendente.
            if app.plotUpdateEvent ~= 0
                app.plotUpdateEvent = 0;
                return
            end
            flowIdx = findSpecDataIndex(app);
            if isempty(flowIdx)
                return
            end
            specData = app.mainApp.specData(flowIdx);
            if isempty(specData.Data) || isempty(specData.Data{1})
                return
            end
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onPlaybackStarted')
            app.plotUpdateEvent = 1;
            runPlaybackLoop(app, flowIdx, numel(specData.Data{1}))
        end

        %------------------------------------------------------------------
        % funçao de reuso para atualizaçao frame a frame
        function updateCurrentFrame(app, flowIdx, nSweeps)

            updatePlot(app);

            updateTimestamp( ...
                app, ...
                app.sweepTimeIdx, ...
                nSweeps, ...
                app.mainApp.specData(flowIdx).Data{1}(app.sweepTimeIdx));

            app.tool_TimestampSlider.Value = ...
                round(100 * app.sweepTimeIdx / nSweeps, 1);

        end
        

        %------------------------------------------------------------------
        % funçao principal do playback
        function runPlaybackLoop(app, flowIdx, nSweeps)
            app.tool_Play.ImageSource = 'playback-stop-16px-gray.png';

            if ~app.plotHandles.clearWrite.Visible
                app.plotHandles.clearWrite.Visible = true;
            end

            while app.sweepTimeIdx <= nSweeps
                switch app.plotUpdateEvent
                    case -1
                        app.plotUpdateEvent = 1;

                        flowIdx = findSpecDataIndex(app);
                        loadSelectedFlow(app, flowIdx)

                        if isempty(flowIdx)
                            break
                        end

                        nSweeps = numel(app.mainApp.specData(flowIdx).Data{1});

                    case  0
                        break
                end

                sweepTic = tic;
                updateCurrentFrame(app, flowIdx, nSweeps);

                pause(max( ...
                    app.mainApp.General.context.PLAYBACK.minSweepTimeSeconds ...
                    - toc(sweepTic), ...
                    .025));
                % Se o playback foi pausado durante a espera, preserve o frame exibido.
                if app.plotUpdateEvent == 0
                    break
                end
                if app.sweepTimeIdx == nSweeps
                    if ~app.tool_LoopControl.UserData.loopMode
                        break
                    end
                    app.sweepTimeIdx = 1;
                else
                    app.sweepTimeIdx = app.sweepTimeIdx + 1;
                end
            end
            app.plotUpdateEvent = 0;
            app.tool_Play.ImageSource = 'playback-play-16px-gray.png';

        end

        %-----------------------------------------------------------------%
        function stopPlaybackIfRunning(app)
            if app.plotUpdateEvent
                app.plotUpdateEvent = 0;
            end
        end

        %-----------------------------------------------------------------%
        function reportDispatchOperation(app, eventName, varargin)
            arguments
                app
                eventName {mustBeMember(eventName, {'onReportGenerate', 'onUploadArtifacts'})}
            end

            arguments (Repeating)
                varargin
            end

            if isempty(app.mainApp.eFiscalizaObj) || ~isvalid(app.mainApp.eFiscalizaObj)
                dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');

                customFormData = struct('UUID', eventName, 'Fields', dialogBox, 'Context', app.Context);
                if ~isempty(varargin)
                    customFormData.Varargin = varargin;
                end

                sendEventToHTMLSource(app.jsBackDoor, 'customForm', customFormData)

            else
                ipcMainMatlabCallsHandler(app.mainApp, app, eventName, app.Context, varargin{:})
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if app.plotUpdateEvent
                app.plotUpdateEvent = 0;
                return
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function onDockModuleGroupButtonClicked(app, event)
            
            if app.plotUpdateEvent
                app.plotUpdateEvent = 0;
                return
            end

            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Image clicked function: FlowDetectionLimitsEdit, 
        % ...and 4 other components
        function onOpenPopupApp(app, event)
            
            switch event.Source
                case app.FlowDetectionLimitsEdit
                    dockAppTag = 'DetectionLimits';
                case app.FlowEmissionsAdd
                    dockAppTag = 'Detection';
                case app.FlowOccupancyEdit
                    dockAppTag = 'Occupancy';
                case app.tool_OpenPopupMerge
                    dockAppTag = 'FlowMerge';
                case app.tool_OpenPopupProject
                    dockAppTag = 'ReportLib';
            end

            stopPlaybackIfRunning(app)
            ipcMainMatlabOpenPopupApp(app.mainApp, app, dockAppTag, app.Context)

        end

        % Value changed function: SpectrumFlowList
        function onFlowDropDownValueChanged(app, event)

            if exist('event', 'var')
                [parentTag, dataIndex] = plot.datatip.Search([app.UIAxes1, app.UIAxes2, app.UIAxes3]);
                update(app.mainApp.specData(event.PreviousValue), 'UserData:PlotDisplayConfig', 'dataTip', parentTag, dataIndex)
            end

            if app.plotUpdateEvent
                app.plotUpdateEvent = -1;
            else
                idx = findSpecDataIndex(app);
                loadSelectedFlow(app, idx)
                updatePlot(app)
            end
            
        end

        % Image clicked function: FlowAttributesPanelLeftBtn, 
        % ...and 1 other component
        function onFlowPanelViewChanged(app, event)
            
            numPanels = 4;

            panelSubtitles = {'Metadados', 'Canais', 'Emissões', 'Ocupação'};
            panelBtnStatus = [false true; true true; true true; true false];
            columnWidths = {
                {'1x',0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0};
                {0,10,'1x',22,5,18,10,0,0,0,0,0,0,0,0,0,0,0,0};
                {0,0,0,0,0,0,10,'1x',22,10,22,10,22,5,22,10,0,0,0};
                {0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,10,'1x',18,10}
            };

            currentIndex = app.FlowAttributesPanelVisibleIdx.UserData.index;
            
            switch event.Source
                case app.FlowAttributesPanelLeftBtn
                    step = -1;
                case app.FlowAttributesPanelRightBtn
                    step = 1;
            end

            currentIndex = mod(currentIndex - 1 + step, numPanels) + 1;
            
            app.FlowAttributesPanelLeftBtn.Enable  = panelBtnStatus(currentIndex, 1);
            app.FlowAttributesPanelRightBtn.Enable = panelBtnStatus(currentIndex, 2);
            app.FlowAttributesPanelVisibleIdx.Text = sprintf('%d/%d', currentIndex, numPanels);

            app.FlowPanelGrid.ColumnWidth = columnWidths{currentIndex};
            app.FlowEmissions.Visible = strcmp(panelSubtitles{currentIndex}, 'Emissões');

            app.FlowPanelLabel.Text = replace(app.FlowPanelLabel.Text, extractBetween(app.FlowPanelLabel.Text, '<i>', '</i>'), panelSubtitles{currentIndex});
            app.FlowAttributesPanelVisibleIdx.UserData.index = currentIndex;

        end

        % Image clicked function: axesTool_DataTip, axesTool_Pan, 
        % ...and 9 other components
        function onAxesToolbarButtonClicked(app, event)
            
            specData = app.bandObj.SpecData;

            switch event.Source
                case app.axesTool_RestoreView
                    plot.axes.Interactivity.CustomRestoreViewFcn(app.UIAxes1, [app.UIAxes2, app.UIAxes3], app)

                case app.axesTool_Pan
                    app.axesTool_Pan.UserData.status = ~app.axesTool_Pan.UserData.status;
                    if app.axesTool_Pan.UserData.status
                        app.axesTool_Pan.ImageSource = 'pan-filled-32px.png';
                        
                        if app.axesTool_DataTip.UserData.status
                            onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_DataTip))
                        end
                    else
                        app.axesTool_Pan.ImageSource = 'pan-32px.png';
                    end

                    plot.axes.Interactivity.CustomPanFcn(struct('Value', app.axesTool_Pan.UserData.status), [app.UIAxes1, app.UIAxes2, app.UIAxes3]);

                case app.axesTool_crearWrite
                    if ~isempty(app.plotHandles.clearWrite) && isvalid(app.plotHandles.clearWrite)
                        app.plotHandles.clearWrite.Visible = ~app.plotHandles.clearWrite.Visible;
                    end

                    stopPlaybackIfRunning(app)

                case {app.axesTool_minHold, app.axesTool_average, app.axesTool_maxHold}
                    event.Source.UserData.status = ~event.Source.UserData.status;

                    plotTag = event.Source.Tag;
                    update(specData, 'UserData:PlotDisplayConfig', plotTag, event.Source.UserData.status)

                    if event.Source.UserData.status
                        event.Source.ImageSource = event.Source.UserData.imageSource{1};

                        hObject = plot.draw2D.OrdinaryLine(app.UIAxes1, plotTag, app.bandObj, app.sweepTimeIdx);
                        plot.datatip.Template(hObject, 'Frequency+Level', app.bandObj.LevelUnit)
                        plot.axes.StackingOrder.execute(app.UIAxes1, app.bandObj.Context)
                        app.plotHandles.(plotTag) = hObject;

                    else
                        event.Source.ImageSource = event.Source.UserData.imageSource{2};
                        delete(app.plotHandles.(plotTag))
                        app.plotHandles.(plotTag) = [];
                    end

                case app.axesTool_persistence
                    app.axesTool_persistence.UserData.status = ~app.axesTool_persistence.UserData.status;

                    update(specData, 'UserData:PlotDisplayConfig', 'persistence', event.Source.UserData.status)

                    if app.axesTool_persistence.UserData.status
                        updatePersistencePlot(app, 'Creation')
                    else
                        updatePersistencePlot(app, 'Delete')
                    end

                case app.axesTool_occupancy
                    app.axesTool_occupancy.UserData.status = ~app.axesTool_occupancy.UserData.status;

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)

                    update(specData, 'UserData:PlotDisplayConfig', 'occupancy', event.Source.UserData.status)

                    if app.axesTool_occupancy.UserData.status
                        updateOccupancyPlot(app, 'Creation', specData)
                    else
                        updateOccupancyPlot(app, 'Delete', specData)
                    end

                case app.axesTool_waterfall
                    app.axesTool_waterfall.UserData.status = ~app.axesTool_waterfall.UserData.status;

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_occupancy.UserData.status, app.axesTool_waterfall.UserData.status, app.LayoutRatio)

                    update(specData, 'UserData:PlotDisplayConfig', 'waterfall', event.Source.UserData.status)

                    if app.axesTool_waterfall.UserData.status
                        if isempty(app.plotHandles.waterfall)
                            updateWaterfallPlot(app)
                        else
                            updateWaterfallPanel(app)
                        end
                    else
                        updateWaterfallPanel(app)
                    end

                case app.axesTool_DataTip
                    app.axesTool_DataTip.UserData.status = ~app.axesTool_DataTip.UserData.status;
                    if app.axesTool_DataTip.UserData.status
                        app.axesTool_DataTip.ImageSource = 'datatip-filled-20px.png';
                    else
                        app.axesTool_DataTip.ImageSource = 'datatip-20px.png';
                    end

                    plot.axes.Interactivity.DataCursorMode(app.UIAxes3, app.axesTool_DataTip.UserData.status)

                case app.axesTool_emissions
                    emissionsHandle = findobj(app.UIAxes1, 'Tag', 'emissionsTemp');
                    if ~isempty(emissionsHandle)
                        delete(emissionsHandle)
                    else
                        if ~isempty(app.FlowEmissions.Data)
                            freqList = app.FlowEmissions.Data.Frequency;
                            widthkHzList = app.FlowEmissions.Data.BandWidthkHz;
                            util.Detection.drawEmission(app.UIAxes1, freqList, widthkHzList, app.restoreView(1).yLim)
                        end
                    end
            end
            drawnow

        end

        % Image clicked function: axesTool_ExportGraphics
        function onAxesToolbarExportGraphicsClicked(app, event)
            
            fileFormats = {'*.jpeg', '(*.jpeg)'};
            fileFullPath = ui.Dialog(app.UIFigure, 'uiputfile', '', fileFormats, app.mainApp.General.fileFolder.userPath);
            if isempty(fileFullPath)
                return
            end

            app.progressDialog.Visible = 'visible';

            try
                exportgraphics(app.AxesContainer, fileFullPath, 'ContentType', 'image', 'Resolution', app.mainApp.General.reportLib.image.resolutionDpi, 'BackgroundColor', [0,0,0])
            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Callback function: LimitsRefresh, LimitsXLim1, LimitsXLim2, 
        % ...and 8 other components
        function onAxesLimitsConfigChanged(app, event)
            
            specData = app.bandObj.SpecData;
            evtValue = [];

            switch event.Source
                case app.LimitsRefresh
                    if ~isempty(specData)
                        xLimits = specData.UserData.PlotDisplayConfig.limits.frequency.initial;
                        yLimits = specData.UserData.PlotDisplayConfig.limits.level.initial;
                    else
                        xLimits = [0, 1];
                        yLimits = [0, 1];
                    end
                    set(app.UIAxes1, 'XLim', xLimits, 'YLim', yLimits)
                    set(app.UIAxes2, 'YLim', [0, 100])

                    switch class(app.UIAxes3.YAxis)
                        case 'matlab.graphics.axis.decorator.DatetimeRuler'
                            set(app.UIAxes3, 'YLim', [specData.Data{1}(1), specData.Data{1}(end)])
                        otherwise % 'matlab.graphics.axis.decorator.NumericRuler'
                            set(app.UIAxes3, 'YLim', [1, numel(specData.Data{1})])
                    end                    

                case { app.LimitsXLim1, app.LimitsXLim2 }
                    evtValue = [app.LimitsXLim1.Value, app.LimitsXLim2.Value];                    
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes1.XLim = evtValue;
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case { app.LimitsYLim1, app.LimitsYLim2 }
                    evtValue = [app.LimitsYLim1.Value, app.LimitsYLim2.Value];
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes1.YLim = evtValue;
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case { app.PersistenceCLim1, app.PersistenceCLim2 }
                    evtValue = [app.PersistenceCLim1.Value, app.PersistenceCLim2.Value];
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes1.CLim = evtValue;
                        app.UIAxes1.UserData.CLimMode = 'manual';
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case app.PersistenceCLimRefresh
                    app.UIAxes1.CLimMode = 'auto';
                    app.UIAxes1.UserData.CLimMode = 'auto';

                    app.PersistenceCLim1.Value = round(app.UIAxes1.CLim(1), 3);
                    app.PersistenceCLim2.Value = round(app.UIAxes1.CLim(2), 3);

                case { app.WaterfallCLim1, app.WaterfallCLim2 }
                    evtValue = [app.WaterfallCLim1.Value, app.WaterfallCLim2.Value];
                    if issorted(evtValue, 'strictascend')
                        app.UIAxes3.CLim = evtValue;
                        app.UIAxes3.UserData.CLimMode = 'manual';
                    else
                        event.Source.Value = event.PreviousValue;
                        return
                    end

                case app.WaterfallCLimRefresh
                    app.UIAxes3.CLim = specData.UserData.PlotDisplayConfig.limits.waterfall.initial;
                    app.UIAxes3.UserData.CLimMode = 'auto';

                    app.WaterfallCLim1.Value = round(app.UIAxes3.CLim(1));
                    app.WaterfallCLim2.Value = round(app.UIAxes3.CLim(2));
            end

            if ~isempty(specData)
                update(specData, 'UserData:PlotDisplayConfig', event.Source.Tag, evtValue)
                updateSpectrumInfo(app.bandObj, specData);
                resetRestoreView(app)
            end
            checkAxesLimitsCustomizations(app)
            
        end

        % Value changed function: LayoutRatio, PersistenceColormap, 
        % ...and 7 other components
        function onDisplayPlotConfigChanged(app, event)
            
            specData = app.bandObj.SpecData;
            update(specData, 'UserData:PlotDisplayConfig', event.Source.Tag, event.Value)

            switch event.Source
                case app.LayoutRatio  
                    plot.axes.Layout.Visibility([app.UIAxes1, app.UIAxes2, app.UIAxes3], event.Value, app.bandObj.Context)

                case app.PersistenceInterpolation
                    app.plotHandles.persistence.handle.Interpolation = event.Value;

                case app.PersistenceWindowSize
                    updatePersistencePlot(app, 'Delete')
                    updatePersistencePlot(app, 'Creation')

                case app.PersistenceColormap
                    plot.axes.Colormap(app.UIAxes1, event.Value)

                case app.PersistenceTransparency
                    app.plotHandles.persistence.handle.CData(isnan(app.plotHandles.persistence.handle.CData)) = 0; 
                    app.plotHandles.persistence.handle.AlphaData = double(logical(app.plotHandles.persistence.handle.CData)) * event.Value;

                case { app.WaterfallFunction, app.WaterfallDecimation }
                    [~, ~, xData, yData] = plot.datatip.Search(app.UIAxes3);
                    app.plotHandles.waterfall = plot.Waterfall('Delete', app.plotHandles.waterfall);
                    updateWaterfallPlot(app)

                    if ~isempty(xData)
                        if event.Source == app.WaterfallFunction
                            for ii = 1:numel(yData)
                                switch event.Value
                                    case 'image'
                                        yData{ii} = timestamp2idx(app.bandObj, yData{ii});
                                    case 'mesh'
                                        yData{ii} = idx2timestamp(app.bandObj, yData{ii});
                                end
                            end
                        end

                        dtConfig = struct('xData', xData, 'yData', yData);
                        dtParent = app.plotHandles.waterfall;
                        plot.datatip.Create('redrawWaterfall', dtConfig, dtParent)                        
                    end                    

                case app.WaterfallColormap
                    plot.axes.Colormap(app.UIAxes3, event.Value)

                case app.WaterfallMeshStyle
                    app.plotHandles.waterfall.MeshStyle = event.Value;
            end
            
        end

        % Image clicked function: FlowChannelFileImport
        function onChannelFileImportButtonlicked(app, event)
            
            specData = app.bandObj.SpecData;

            if numel(app.mainApp.specData) > 1
                questionMsg = 'Você deseja importar uma lista de canais apenas para o fluxo espectral selecionado ou para todos os fluxos espectrais?';
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Apenas selecionado', 'Todos', 'Cancelar'}, 1, 3);

                switch userSelection
                    case 'Todos'
                        specData = app.mainApp.specData;
                    case 'Cancelar'
                        return
                end
            end

            fileFormats = {'*.json;*.csv', '(*.json,*.csv)'};
            [fileFullPath, fileFolder, fileExt] = ui.Dialog(app.UIFigure, 'uigetfile', '', fileFormats, app.mainApp.General.fileFolder.lastVisited);
            if isempty(fileFullPath)
                return
            end
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onUpdateLastVisitedFolder', fileFolder)

            try
                switch fileExt
                    case '.json'
                        channels = readFileWithChannel2Add(app.mainApp.channelObj, fileFullPath);
                        ipcMainMatlabOpenPopupApp(app.mainApp, app, 'ChannelsFileImport', app.Context, fileFullPath, specData, channels)

                    case '.csv'
                        channels = class.EMSatDataHubLib.importRawCSVFile(fileFullPath);
                        ipcMainMatlabOpenPopupApp(app.mainApp, app, 'ChannelsSatellite', app.Context, fileFullPath, specData, channels)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

        end

        % Image clicked function: FlowChannelRefresh
        function onChannelRefreshButtonClicked(app, event)
            
            specData = app.bandObj.SpecData;

            initialChannelLibIdxs = specData.UserData.ChannelLibraryRelatedIndexes;
            initialChannelUserDefined = specData.UserData.ChannelUserDefined;

            update(specData, 'UserData:Channel', 'InitialValue', app.mainApp.channelObj, app.mainApp.General)

            isChange = ~isequal(initialChannelLibIdxs,     specData.UserData.ChannelLibraryRelatedIndexes) || ...
                       ~isequal(initialChannelUserDefined, specData.UserData.ChannelUserDefined);
            
            if isChange
                updateUIControlsState(app, specData)
                updateUIPanelContent(app)
                updateChannelsPlot(app)
            end

        end

        % Value changed function: FlowChannel
        function onChannelSelectionChanged(app, event)
            
            updateChannelsPlot(app)
            updateContextMenuState(app, 'channels')
            
        end

        % Image clicked function: FlowEmissionsFileImport
        function onEmissionsFileImportButtonClicked(app, event)
            
            specData = app.bandObj.SpecData;

            if numel(app.mainApp.specData) > 1
                questionMsg = 'Você deseja importar uma lista de emissões apenas para o fluxo espectral selecionado ou para todos os fluxos espectrais?';
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Apenas selecionado', 'Todos', 'Cancelar'}, 1, 3);

                switch userSelection
                    case 'Todos'
                        specData = app.mainApp.specData;
                    case 'Cancelar'
                        return
                end
            end

            fileFormats = { ...
                '*.csv;*.txt;*.json;*.xls;*.xlsx', '(*.csv,*.txt,*.json,*.xls,*.xlsx)'; ...
                '*.mat', 'Importa análise legada (*.mat)' ...
            };

            [fileFullPath, fileFolder, fileExt] = ui.Dialog(app.UIFigure, 'uigetfile', '', fileFormats, app.mainApp.General.fileFolder.lastVisited);
            if isempty(fileFullPath)
                return
            end
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onUpdateLastVisitedFolder', fileFolder)

            app.progressDialog.Visible = 'visible';

            try
                switch fileExt
                    case '.mat'
                        varsInFile = who('-file', fileFullPath);
                        if any(~ismember({'prj_Source', 'prj_specData'}, varsInFile))
                            missing = setdiff({'prj_Source', 'prj_specData'}, varsInFile);
                            error('Missing required variables: %s', strjoin(missing, ', '))
                        end
                        
                        load(fileFullPath, '-mat', 'prj_Source', 'prj_specData');
                        
                        if ~strcmp(class.Constants.appName, prj_Source)
                            error('File generated by a different application. Expected: %s. Found: %s.', class.Constants.appName, prj_Source)
                        end

                        rawTable = arrayfun(@(x) x.UserData.Emissions, prj_specData, UniformOutput=false);
                        rawTable = vertcat(rawTable{:});
                        rawTable = rawTable(:, {'Frequency', 'BW_kHz', 'Description'});
                        rawTable.Description = cellstr(rawTable.Description);

                    case '.json'
                        rawTable = struct2table(jsondecode(fileread(fileFullPath)), 'AsArray', true);

                        % A importação padrão decorre de arquivo com apenas informação básica 
                        % das emissões - frequência central, largura e descrição. No módulo 
                        % SIGNALANALYSIS, contudo, pode-se gerar um .JSON mais completo, com 
                        % informações da classificação. Por fugir da lógica padrão, essa importação 
                        % é conduzida em util.importSignalAnalysis.
                        if ~isempty(rawTable) && width(rawTable) > 3
                            util.importSignalAnalysis(specData, rawTable, app.mainApp.metaData, app.mainApp.projectData, app.mainApp.channelObj, app.mainApp.General, app.mainApp.rfDataHub)
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionAdded', app.Context)
                            
                            app.progressDialog.Visible = 'hidden';
                            return
                        end
                        
                    case '.csv'
                        rawTable = readtable(fileFullPath, "VariableNamingRule", "preserve");
                        if width(rawTable) > 3
                            rawTable = util.readFileGeneratedByRomes(fileFullPath);
                        end

                    otherwise
                        rawTable = readtable(fileFullPath, "VariableNamingRule", "preserve");
                end

                rawTable.Properties.VariableNames = {'Frequency', 'BandWidthkHz', 'Description'};

                if ~isempty(rawTable)
                    for ii = 1:numel(specData)
                        tempBandTable = rawTable;
                        
                        idxList = util.Detection.freq2idx(specData(ii), tempBandTable.Frequency * 1e+6);
                        invalidIdxs = ismember(idxList, [1, specData(ii).MetaData.DataPoints]);

                        if all(invalidIdxs)
                            continue
                        end

                        tempBandTable(invalidIdxs,:) = [];
                        idxList(invalidIdxs) = [];

                        freqList = tempBandTable.Frequency; % Em MHz
                        widthkHzList = tempBandTable.BandWidthkHz;
                        methodList = repmat({jsonencode(struct('Algorithm', 'ExternalFile'))}, numel(idxList), 1);
                        userCommentList = tempBandTable.Description;

                        if ~isempty(specData(ii)) && (isempty(specData(ii).Data) || (numel(specData(ii).Data{1}) ~= sum(specData(ii).RelatedFiles.NumSweeps)))
                            try
                                populateSpectrum(specData(ii), app.mainApp.metaData,  app.projectData, app.mainApp.channelObj, app.mainApp.General)
                            catch
                                continue
                            end
                        end

                        update(specData(ii), 'UserData:Emissions', 'Add', idxList, freqList, widthkHzList, methodList, userCommentList, app.mainApp.channelObj)
                    end

                    ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionAdded', app.Context)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: FlowEmissionsDataTips, 
        % ...and 1 other component
        function onEmissionsDataTipsOrRoiButtonClicked(app, event)
            
            specData = app.bandObj.SpecData;

            switch event.Source
                case app.FlowEmissionsDataTips
                        datatipHandles = findobj([app.UIAxes1, app.UIAxes2, app.UIAxes3], 'Type', 'DataTip');
                        if isempty(datatipHandles)
                            return
                        end
        
                        freqList = [];
                        for ii = 1:numel(datatipHandles)
                            freqList(end+1, 1) = double(round(datatipHandles(ii).X, 3)); % Em MHz
                        end
                        widthkHzList = ones(numel(freqList), 1) .* app.mainApp.General.context.PLAYBACK.detection.datatipBandWidthkHz;
                        idxList = freq2idx(app.bandObj, freqList .* 1e+6);
                        methodList = repmat({jsonencode(struct('Algorithm', 'Manual'))}, numel(freqList), 1);

                otherwise % app.FlowEmissionsDrawRoi
                    % Incluir uma emissõe por ROI demanda eliminar temporariamente 
                    % as interações com o plot.
                    if app.axesTool_DataTip.UserData.status
                        onAxesToolbarButtonClicked(app, struct('Source', app.axesTool_DataTip))
                    end
        
                    plot.axes.Interactivity.DefaultDisable([app.UIAxes1, app.UIAxes2, app.UIAxes3])
                    drawnow
                    
                    roiHandle = drawrectangle(app.UIAxes1, 'Color', [0.40,0.73,0.88], 'LineWidth', 1, 'Tag', 'emissionsTemp');
                    
                    % Reativa interações...
                    plot.axes.Interactivity.DefaultEnable([app.UIAxes1, app.UIAxes2, app.UIAxes3])
        
                    freqList = roiHandle.Position(1) + roiHandle.Position(3)/2; % Em MHz
                    widthkHzList = roiHandle.Position(3) * 1000; % Em kHz
                    idxList = freq2idx(app.bandObj, freqList .* 1e+6);
                    methodList = {jsonencode(struct('Algorithm', 'Manual'))};
        
                    % Se for desenhada uma ROI fora dos limites do eixo, a função 
                    % freq2idx retornará ela nas extremidades do eixo. Nesse caso, 
                    % exclui-se a ROI.
                    if ismember(idxList, [1, specData.MetaData.DataPoints])
                        delete(roiHandle)
                        return
                    end
            end

            app.progressDialog.Visible = 'visible';

            update(specData, 'UserData:Emissions', 'Add', idxList, freqList, widthkHzList, methodList, [], app.mainApp.channelObj)
            emissionAddedIdx = find(specData.UserData.Emissions.FrequencyIdx == idxList(1), 1);
            if ~isempty(emissionAddedIdx)
                app.emissionSelectedHash = specData.UserData.Emissions.Uuid(emissionAddedIdx);
            end
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionAdded', app.Context)

            app.progressDialog.Visible = 'hidden';

        end

        % Selection changed function: FlowEmissions
        function onEmissionsTableSelectionChanged(app, event)
            
            selectedRows = event.Selection;

            if isempty(selectedRows)
                app.FlowEmissions.Selection = event.PreviousSelection;
                return
            end

            if ~ismember(app.FlowEmissions.UserData.selectedRow, selectedRows)
                selectedRows = selectedRows(1);
                app.FlowEmissions.Selection = selectedRows;
                app.FlowEmissions.UserData.selectedRow = selectedRows;

                applyEmissionsTableStyle(app, true, selectedRows)
                updateEmissionsPlot(app)
            end
            
        end

        % Cell edit callback: FlowEmissions
        function onEmissionsTableCellEdit(app, event)
            
            % A tabela de emissões possui umas 10 colunas, mas aqui, no
            % modo PLAYBACK, apresenta-se apenas três delas: "Frequency",
            % "BandWidthkHz" e "Description". As duas primeiras numéricas;
            % a outra, string.

            emissionIdx  = event.Indices(1);
            columnIdx    = event.Indices(2);
            previousData = event.PreviousData;
            newData      = event.NewData;

            if (isnumeric(newData) && (isnan(newData) || abs(newData-previousData) < 1e-3)) || ...
               (isstring(newData) && strtrim(newData) == strtrim(previousData))
                event.Source.Data{emissionIdx, columnIdx} = previousData;
                return
            end

            specData = app.bandObj.SpecData;

            switch columnIdx
                case {1, 2}
                    parameterName = 'Frequency|BandWidth';

                    frequency = event.Source.Data{emissionIdx, 'Frequency'};
                    [frequencyIdx, invalidIdx] = freq2idx(app.bandObj, frequency*1e+6);
                    if invalidIdx
                        event.Source.Data{emissionIdx, columnIdx} = previousData;
                        return
                    end

                    bandWidthkHz = event.Source.Data{emissionIdx, 'BandWidthkHz'};
                    frequencyLimits = [ ...
                        frequency - bandWidthkHz/2000, ...
                        frequency + bandWidthkHz/2000 ...
                    ];

                    % Ajusta a largura ocupada, fixando a frequência, de forma 
                    % a não ultrapassar os limites das faixas.
                    if frequencyLimits(1) < app.bandObj.FreqStart
                        bandWidthkHz = 2000 * abs(frequency - app.bandObj.FreqStart);
                    elseif frequencyLimits(2) > app.bandObj.FreqStop
                        bandWidthkHz = 2000 * abs(frequency - app.bandObj.FreqStop);
                    end

                    complementaryArgs = {frequencyIdx, frequency, bandWidthkHz, app.mainApp.channelObj};
                
                otherwise
                    parameterName = 'Description';
                    complementaryArgs = {newData, app.mainApp.channelObj};
            end

            updateEmissionTable(app, specData, 'Edit', emissionIdx, parameterName, complementaryArgs{:})
            
        end

        % Image clicked function: tool_LayoutLeft, tool_LayoutRight
        function onToolbarPanelVisibilityChanged(app, event)
            
            event.Source.UserData.status = ~event.Source.UserData.status;

            switch event.Source
                case app.tool_LayoutLeft
                    if event.Source.UserData.status
                        app.tool_LayoutLeft.ImageSource = 'layout-sidebar-left.svg';
                        app.AxesContainer.Layout.Column(1) = 4;
                        app.AxesToolbar.Layout.Column = 5;
                        app.LeftPanel.Visible = 'on';
                    else
                        app.tool_LayoutLeft.ImageSource = 'layout-sidebar-left-off.svg';
                        app.AxesContainer.Layout.Column(1) = 1;
                        app.AxesToolbar.Layout.Column = [2, 4];
                        app.LeftPanel.Visible = 'off';
                    end

                case app.tool_LayoutRight
                    if event.Source.UserData.status
                        app.tool_LayoutRight.ImageSource = 'layout-sidebar-right.svg';
                        app.AxesContainer.Layout.Column(2) = 6;
                        app.AxesAnnotation.Layout.Column = 6;
                        app.RightPanel.Visible = 'on';
                    else
                        app.tool_LayoutRight.ImageSource = 'layout-sidebar-right-off.svg';
                        app.AxesContainer.Layout.Column(2) = 8;
                        app.AxesAnnotation.Layout.Column = [6 8];
                        app.RightPanel.Visible = 'off';
                    end
            end

        end

        % Image clicked function: tool_LoopControl, tool_Play
        function onToolbarPlaybackControlChanged(app, event)
            
            switch event.Source
                case app.tool_Play
                    togglePlayback(app)

                %---------------------------------------------------------%
                case app.tool_LoopControl
                     app.tool_LoopControl.UserData.loopMode = ~ app.tool_LoopControl.UserData.loopMode;

                     if app.tool_LoopControl.UserData.loopMode
                         app.tool_LoopControl.ImageSource = 'playback-loop-36px-gray.png';
                     else
                         app.tool_LoopControl.ImageSource = 'playback-straight-36px-gray.png';
                    end
            end

        end

        % Callback function: tool_TimestampSlider, tool_TimestampSlider
        function onToolbarTimelineSliderChanging(app, event)
            
            nSweeps = app.bandObj.NumSweeps;            
            app.sweepTimeIdx = round(event.Value/100 * nSweeps);
            
            if app.sweepTimeIdx < 1
                app.sweepTimeIdx = 1;
            elseif app.sweepTimeIdx > nSweeps
                app.sweepTimeIdx = nSweeps;
            end

            if ~app.plotUpdateEvent
                if ~app.plotHandles.clearWrite.Visible
                    app.plotHandles.clearWrite.Visible = true;
                end
                app.plotHandles.clearWrite.YData = app.bandObj.SpecData.Data{2}(:, app.sweepTimeIdx)';
                
                updatePersistencePlot(app, 'Update')

                if app.axesTool_waterfall.UserData.status && ~isempty(app.plotHandles.waterfallTime)
                    plot.draw2D.OrdinaryLineUpdate('waterfallTime', app.plotHandles.waterfallTime, app.bandObj, app.sweepTimeIdx);
                end

                updateTimestamp(app, app.sweepTimeIdx, nSweeps, app.bandObj.SpecData.Data{1}(app.sweepTimeIdx))
            end
            
        end

        % Image clicked function: tool_GenerateReport
        function onToolbarGeneralReportButtonClicked(app, event)
            
            context = app.Context;
            issue = app.projectData.modules.(context).ui.issue;
            reportVersion = app.projectData.modules.(context).ui.reportVersion;
            flowIdxs = find(arrayfun(@(x) x.UserData.ReportInclude, app.mainApp.specData));

            % <VALIDAÇÕES>
            criticalWarningMsg = '';
            if ~validateReportRequirements(app.projectData, context, 'reportModel')
                criticalWarningMsg = 'Pendente escolha do modelo de relatório.';

            elseif isempty(flowIdxs)
                criticalWarningMsg = 'Inclua ao menos um fluxo espectral na lista de fluxos a processar.';

            elseif app.plotUpdateEvent
                criticalWarningMsg = 'Interrompa o playback antes de inicializar a análise para gerar o relatório.';
            end

            if ~isempty(criticalWarningMsg)
                ui.Dialog(app.UIFigure, 'warning', criticalWarningMsg);
                return
            end

            % Avalia se todos os dados de níveis de todos os fluxos sob
            % análise já foram lidos...
            invalidCache = false;
            for ii = flowIdxs
                specData = app.mainApp.specData(ii);
                if ~isempty(specData) 
                    if isempty(specData.Data) || (numel(specData.Data{1}) ~= sum(specData.RelatedFiles.NumSweeps))
                        if ~invalidCache
                            invalidCache = true;
    
                            msgQuestion = 'Ao menos um fluxo espectral sob análise ainda não teve seus dados lidos.<br><br>Deseja que o app carregue automaticamente os dados pendentes?';
                            userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                            if userSelection == "Não"
                                return
                            end
                        end
    
                        requestVisibilityChange(app.progressDialog, 'visible', 'locked')
    
                        try
                            populateSpectrum(specData, app.mainApp.metaData, app.mainApp.projectData, app.mainApp.channelObj, app.mainApp.General)
                            
                            relatedHases = specData.UserData.OccupancyComputationMode.RelatedHashes;
                            if ~isempty(relatedHases)
                                relatedHashIdxs = find(ismember({app.mainApp.specData.Hash}, relatedHases));
                                populateSpectrum(app.mainApp.specData(relatedHashIdxs), app.mainApp.metaData, app.mainApp.projectData, app.mainApp.channelObj, app.mainApp.General)
                            end
        
                        catch ME
                            ui.Dialog(app.UIFigure, 'warning', ME.message);
                            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
                            return
                        end

                        requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
                    end

                    initializeUnsetProperties(specData, app.mainApp.channelObj, app.mainApp.General)
                end
            end

            nonCriticalWarningMsg = {};
            if ~validateReportRequirements(app.projectData, context, 'issue')
                nonCriticalWarningMsg{end+1} = sprintf('• O número da inspeção "%.0f" é inválido.', issue);
            end

            if ~validateReportRequirements(app.projectData, context, 'unit')
                nonCriticalWarningMsg{end+1} = '• Unidade geradora do documento precisa ser selecionada.';
            end

            if app.mainApp.General.context.PLAYBACK.detection.enableEmissionClassification
                invalidClassification = false;
                for ii = flowIdxs
                    specData = app.mainApp.specData(ii);
                    if ismember('Pendente identificação', arrayfun(@(x) x.UserModified.EmissionType, specData.UserData.Emissions.Classification, 'UniformOutput', false))
                        invalidClassification = true;
                        break
                    end
                end
    
                if invalidClassification
                    nonCriticalWarningMsg{end+1} = '• Há ao menos uma emissão ainda <font style="color: red;">Pendente identificação</font>, o que inviabiliza a geração da versão definitiva do relatório.';
                end
            end

            if isempty(nonCriticalWarningMsg)
                switch reportVersion
                    case 'Definitiva'
                        msgQuestion = sprintf('Confirma que se trata de monitoração relacionada à Atividade de Inspeção nº %.0f?', issue);
                        userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                        if userSelection == "Não"
                            return
                        end
                        
                    case 'Preliminar'
                        % ...
                end

            else
                switch reportVersion
                    case 'Definitiva'
                        msgInfo = sprintf([ ...
                                'Foi(ram) identificado(s) a(s) pendência(s):<br>%s' ...
                                '<br><br>' ...
                                '<b>Essa(s) pendência(s) precisa(m) ser resolvida(s) ' ...
                                'antes de ser gerada a versão "Definitiva" do relatório</b>.' ...
                            ], strjoin(nonCriticalWarningMsg, '<br>') ...
                        );
                        ui.Dialog(app.UIFigure, 'warning', msgInfo);
                        return

                    case 'Preliminar'
                        msgQuestion = sprintf([ ...
                                'Foi(ram) identificado(s) a(s) pendência(s):<br>%s' ...
                                '<br><br>' ...
                                '<b>Continuar mesmo assim?</b>' ...
                            ], strjoin(nonCriticalWarningMsg, '<br>') ...
                        );
                        selection = ui.Dialog(app.UIFigure, "uiconfirm", msgQuestion, {'Sim', 'Não'}, 1, 2);
                        if strcmp(selection, 'Não')
                            return
                        end
                end
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            reportDispatchOperation(app, 'onReportGenerate', flowIdxs)
            % </PROCESSO>

        end

        % Image clicked function: tool_UploadFinalFile
        function onToolbarUploadFinalFileButtonClicked(app, event)
            
            % <VALIDAÇÕES>
            context = app.Context;            
            system = app.projectData.modules.(context).ui.system;
            issue = app.projectData.modules.(context).ui.issue;
            generatedHtmlFilePath = getGeneratedDocumentFileName(app.projectData, '.html', context);

            msg = '';
            if isempty(generatedHtmlFilePath)
                msg = 'A versão definitiva do relatório ainda não foi gerada.';
            elseif ~isfile(generatedHtmlFilePath)
                msg = sprintf('O arquivo "%s" não foi encontrado.', generatedHtmlFilePath);
            elseif ~isfolder(app.mainApp.General.fileFolder.DataHub_POST)
                msg = 'Pendente mapear pasta do Sharepoint';
            elseif ~validateReportRequirements(app.projectData, context, 'issue')
                msg = sprintf('O número da inspeção "%.0f" é inválido.', issue);
            elseif ~validateReportRequirements(app.projectData, context, 'unit')
                msg = 'Unidade geradora do documento precisa ser selecionada.';
            elseif isempty(system)
                msg = 'Ambiente do eFiscaliza precisa ser selecionado.';
            end

            if ~isempty(msg)
                ui.Dialog(app.UIFigure, 'warning', msg);
                return
            end

            flowIdxs = find(arrayfun(@(x) x.UserData.ReportInclude, app.mainApp.specData));
            specData = app.mainApp.specData(flowIdxs);

            storedReportHash  = app.projectData.modules.(context).generatedFiles.id;
            currentReportHash = model.ProjectBase.computeReportHash(specData);

            if ~isequal(storedReportHash, currentReportHash)
                [~, generatedHtmlFileName, generatedHtmlFileExt] = fileparts(generatedHtmlFilePath);
                msgQuestion = sprintf([ ...
                    'O relatório indicado a seguir foi gerado com base em ' ...
                    'um conjunto específico de arquivos e anotações.<br>%s<br><br>' ...
                    '<i>Hash</i> no momento da geração:<br>' ...
                    '%s<br><br>' ...
                    '<i>Hash</i> atual (após alterações):<br>' ...
                    '%s<br><br>' ...
                    'Isso indica que um arquivo diferente foi selecionado ' ...
                    'ou que alguma anotação foi modificada desde a geração ' ...
                    'do relatório.<br><br>' ...
                    '<b>Deseja continuar com o <i>upload</i> mesmo assim?</b>' ...
                ], [generatedHtmlFileName, generatedHtmlFileExt], textFormatGUI.cellstr2Bullets(strsplit(storedReportHash, ' - ')), textFormatGUI.cellstr2Bullets(strsplit(currentReportHash, ' - ')));
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);

                if strcmp(userSelection, 'Não')
                    return
                end
            end

            uploadedFiles = getUploadedFiles(app.projectData, context, system, issue);
            if ~isempty(uploadedFiles)
                uploadedStatus = extractAfter({uploadedFiles.status}, 'Documento cadastrado no SEI sob o nº ');

                if isscalar(uploadedStatus)
                    uploadedStatus = uploadedStatus{1};
                else                    
                    uploadedStatus = strjoin([{strjoin(uploadedStatus(1:end-1), ', ')}, uploadedStatus(end)], ' e ');
                end

                msgQuestion = sprintf([ ...
                    'Já foi realizado <i>upload</i> para o SEI de relatório relacionado ' ...
                    'à Atividade de Inspeção nº %d - SEI nº %s.<br><br>' ...
                    'Deseja realizar um novo <i>upload</i> para o SEI?' ...
                ], issue, uploadedStatus);
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);

                if strcmp(userSelection, 'Não')
                    return
                end
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            reportDispatchOperation(app, 'onUploadArtifacts')
            % </PROCESSO>

        end

        % Menu selected function: ContextMenuAddChannelAsEmission, 
        % ...and 4 other components
        function onContextMenuChannelsOptionClicked(app, event)
            
            if isempty(app.FlowChannel.Items)
                return
            end

            specData = app.bandObj.SpecData;
            channels = [];

            channelLibIndex = specData.UserData.ChannelLibraryRelatedIndexes;
            channelUserDefined = specData.UserData.ChannelUserDefined;

            if ~isempty(channelLibIndex)
                channels = app.mainApp.channelObj.Channel(channelLibIndex);
            end
            channels = [channels; channelUserDefined'];

            switch event.Source
                case app.ContextMenuAddDetectionLimits
                    channels = channels(app.FlowChannel.ValueIndex, :);
                    if isempty(channels)
                        return
                    end

                    requestedBandLimits = struct( ...
                        'XLim1', min(arrayfun(@(x) x.Band(1), channels)), ...
                        'XLim2', max(arrayfun(@(x) x.Band(2), channels)) ...
                    );

                    ipcMainMatlabOpenPopupApp(app.mainApp, app, 'DetectionLimits', app.Context, requestedBandLimits)
                    return

                case app.ContextMenuAddChannelAsEmission
                    channels = channels(app.FlowChannel.ValueIndex, :);
                    if isempty(channels)
                        return
                    end

                    for ii = 1:numel(channels)
                        freqList = channels(ii).FreqList;
                        if isempty(freqList)
                            freqList = (channels(ii).FirstChannel:channels(ii).StepWidth:channels(ii).LastChannel)';
                        end
                        [idxList, invalidIndex] = freq2idx(app.bandObj, freqList .* 1e+6, 'OnlyCheck');

                        freqList(invalidIndex)  = [];
                        idxList(invalidIndex) = [];

                        if ~isempty(idxList)
                            numEmissions = numel(idxList);

                            widthMHz = channels(ii).ChannelBW;
                            if widthMHz < 0
                                widthMHz = 0;
                            end
                            widthkHzList = widthMHz * 1000 * ones(numEmissions, 1); % Em kHz

                            methodList = repmat({jsonencode(struct('Algorithm', 'Manual'))}, numEmissions, 1);

                            update(specData, 'UserData:Emissions', 'Add', idxList, freqList, widthkHzList, methodList, [], app.mainApp.channelObj)
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionAdded')
                        end
                    end
                    return

                case app.ContextMenuChannelInfo
                    channelIdxs = app.FlowChannel.ValueIndex;
                    if ~isscalar(channelIdxs)
                        return
                    end
                    
                    channelLibCount = numel(channelLibIndex);
                    channelLibMask = channelIdxs <= channelLibCount;

                    channelLibIdxsToView = channelLibIndex(channelIdxs(channelLibMask));
                    userDefinedIdxsToView = mod(channelIdxs(~channelLibMask), channelLibCount);
                    
                    channelSpec = [];
                    if ~isempty(channelLibIdxsToView)
                        channelSpec = app.mainApp.channelObj.Channel(channelLibIdxsToView);
                    elseif ~isempty(userDefinedIdxsToView)
                        channelSpec = channelUserDefined(userDefinedIdxsToView);
                    end

                    if ~isempty(channelSpec)
                        channelDetails = util.HtmlTextGenerator.getSelectedChannelMetaData(channelSpec);
                        ui.Dialog(app.UIFigure, 'none', channelDetails);
                    end
                    return

                case app.ContextMenuDeleteChannel
                    channelIdxs = app.FlowChannel.ValueIndex;
                    if isempty(channelIdxs)
                        return
                    end
                    
                    channelLibCount = numel(channelLibIndex);
                    channelLibMask = channelIdxs <= channelLibCount;

                    channelLibIdxsToDelete = channelLibIndex(channelIdxs(channelLibMask));
                    userDefinedIdxsToDelete = mod(channelIdxs(~channelLibMask), channelLibCount);

                    if ~isempty(channelLibIdxsToDelete)
                        update(specData, 'UserData:Channel', 'ChannelLibIndex:Delete', channelLibIdxsToDelete)
                    end

                    if ~isempty(userDefinedIdxsToDelete)
                        update(specData, 'UserData:Channel', 'ChannelUserDefined:Delete', userDefinedIdxsToDelete)
                    end

                case app.ContextMenuDeleteChannels
                    update(specData, 'UserData:Channel', 'DeleteAll')
            end

            updateUIControlsState(app, specData)
            updateUIPanelContent(app)
            updateChannelsPlot(app)

        end

        % Menu selected function: ContextMenuDeleteEmission, 
        % ...and 1 other component
        function onContextMenuEmissionOptionClicked(app, event)
            
            specData = app.bandObj.SpecData;

            switch event.Source
                case app.ContextMenuWhoIsEmission
                    [~, emissionIdx] = findSpecDataIndex(app);
                    emissionDetails = util.HtmlTextGenerator.getSelectedEmissionMetaData(specData, emissionIdx, app.Context, [], app.mainApp.General);
                    ui.Dialog(app.UIFigure, 'none', emissionDetails);
                    return

                case app.ContextMenuDeleteEmission
                    selectedRows = app.FlowEmissions.Selection;
                    updateEmissionTable(app, specData, 'Delete', selectedRows)
            end

            emissions = specData.UserData.Emissions;
            app.plotHandles.clearWrite.MarkerIndices = emissions.FrequencyIdx;

        end

        % Window key press function: UIFigure
        function onKeyPressPlaybackControl(app, event)
             
            handlePlaybackKey(app, event.Key, event.Modifier)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @onKeyPressPlaybackControl, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_LayoutLeft = uiimage(app.Toolbar);
            app.tool_LayoutLeft.ImageClickedFcn = createCallbackFcn(app, @onToolbarPanelVisibilityChanged, true);
            app.tool_LayoutLeft.ImageSource = 'layout-sidebar-left.svg';
            app.tool_Separator1 = uiimage(app.Toolbar);
            app.tool_Separator1.Enable = 'off';
            app.tool_Separator1.ImageSource = 'LineV.svg';
            app.tool_Play = uiimage(app.Toolbar);
            app.tool_Play.ImageClickedFcn = createCallbackFcn(app, @onToolbarPlaybackControlChanged, true);
            app.tool_Play.Enable = 'off';
            app.tool_Play.ImageSource = 'playback-play-16px-gray.png';
            app.tool_LoopControl = uiimage(app.Toolbar);
            app.tool_LoopControl.ImageClickedFcn = createCallbackFcn(app, @onToolbarPlaybackControlChanged, true);
            app.tool_LoopControl.Enable = 'off';
            app.tool_LoopControl.ImageSource = 'playback-loop-36px-gray.png';
            app.tool_TimestampSlider = uislider(app.Toolbar);
            app.tool_TimestampSlider.MajorTicks = [0 50 100];
            app.tool_TimestampSlider.ValueChangedFcn = createCallbackFcn(app, @onToolbarTimelineSliderChanging, true);
            app.tool_TimestampSlider.ValueChangingFcn = createCallbackFcn(app, @onToolbarTimelineSliderChanging, true);
            app.tool_TimestampSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.tool_TimestampSlider.Enable = 'off';
            app.tool_TimestampLabel = uilabel(app.Toolbar);
            app.tool_TimestampLabel.Text = '';
            app.tool_OpenPopupMerge = uiimage(app.Toolbar);
            app.tool_OpenPopupMerge.ImageClickedFcn = createCallbackFcn(app, @onOpenPopupApp, true);
            app.tool_OpenPopupMerge.Enable = 'off';
            app.tool_OpenPopupMerge.ImageSource = 'Merge_18.png';
            app.tool_Separator2 = uiimage(app.Toolbar);
            app.tool_Separator2.Enable = 'off';
            app.tool_Separator2.ImageSource = 'LineV.svg';
            app.tool_OpenPopupProject = uiimage(app.Toolbar);
            app.tool_OpenPopupProject.ImageClickedFcn = createCallbackFcn(app, @onOpenPopupApp, true);
            app.tool_OpenPopupProject.ImageSource = 'organization-20px-black.svg';
            app.tool_GenerateReport = uiimage(app.Toolbar);
            app.tool_GenerateReport.ImageClickedFcn = createCallbackFcn(app, @onToolbarGeneralReportButtonClicked, true);
            app.tool_GenerateReport.Enable = 'off';
            app.tool_GenerateReport.ImageSource = 'Publish_HTML_16.png';
            app.tool_UploadFinalFile = uiimage(app.Toolbar);
            app.tool_UploadFinalFile.ImageClickedFcn = createCallbackFcn(app, @onToolbarUploadFinalFileButtonClicked, true);
            app.tool_UploadFinalFile.Enable = 'off';
            app.tool_UploadFinalFile.ImageSource = 'up-20px.png';
            app.tool_Separator3 = uiimage(app.Toolbar);
            app.tool_Separator3.Enable = 'off';
            app.tool_Separator3.ImageSource = 'LineV.svg';
            app.tool_LayoutRight = uiimage(app.Toolbar);
            app.tool_LayoutRight.ImageClickedFcn = createCallbackFcn(app, @onToolbarPanelVisibilityChanged, true);
            app.tool_LayoutRight.ImageSource = 'layout-sidebar-right-off.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.LeftPanel = uigridlayout(app.Document);
            app.SpectrumFlowList = uidropdown(app.LeftPanel);
            app.SpectrumFlowList.Items = {};
            app.SpectrumFlowList.ValueChangedFcn = createCallbackFcn(app, @onFlowDropDownValueChanged, true);
            app.SpectrumFlowList.Value = {};
            app.FlowPanelLabel = uilabel(app.LeftPanel);
            app.FlowPanelLabel.Interpreter = 'html';
            app.FlowPanelLabel.Text = 'ATRIBUTOS DO FLUXO ESPECTRAL<br><font style="font-size: 11px;"><i>Metadados</i></font>';
            app.FlowAttributesPanelVisibleIdx = uilabel(app.LeftPanel);
            app.FlowAttributesPanelVisibleIdx.Text = '1/4';
            app.FlowAttributesPanelLeftBtn = uiimage(app.LeftPanel);
            app.FlowAttributesPanelLeftBtn.ImageClickedFcn = createCallbackFcn(app, @onFlowPanelViewChanged, true);
            app.FlowAttributesPanelLeftBtn.Enable = 'off';
            app.FlowAttributesPanelLeftBtn.ImageSource = 'triangle-left.svg';
            app.FlowAttributesPanelRightBtn = uiimage(app.LeftPanel);
            app.FlowAttributesPanelRightBtn.ImageClickedFcn = createCallbackFcn(app, @onFlowPanelViewChanged, true);
            app.FlowAttributesPanelRightBtn.ImageSource = 'triangle-right.svg';
            app.FlowPanel = uipanel(app.LeftPanel);
            app.FlowPanelGrid = uigridlayout(app.FlowPanel);
            app.FlowMetadata = uilabel(app.FlowPanelGrid);
            app.FlowMetadata.Interpreter = 'html';
            app.FlowMetadata.Text = '';
            app.FlowChannelLabel = uilabel(app.FlowPanelGrid);
            app.FlowChannelLabel.Text = 'CANAIS';
            app.FlowChannelRefresh = uiimage(app.FlowPanelGrid);
            app.FlowChannelRefresh.ImageClickedFcn = createCallbackFcn(app, @onChannelRefreshButtonClicked, true);
            app.FlowChannelRefresh.Visible = 'off';
            app.FlowChannelRefresh.ImageSource = 'Refresh_18.png';
            app.FlowChannelFileImport = uiimage(app.FlowPanelGrid);
            app.FlowChannelFileImport.ImageClickedFcn = createCallbackFcn(app, @onChannelFileImportButtonlicked, true);
            app.FlowChannelFileImport.Enable = 'off';
            app.FlowChannelFileImport.ImageSource = 'Import_16.png';
            app.FlowChannel = uilistbox(app.FlowPanelGrid);
            app.FlowChannel.Items = {};
            app.FlowChannel.Multiselect = 'on';
            app.FlowChannel.ValueChangedFcn = createCallbackFcn(app, @onChannelSelectionChanged, true);
            app.FlowChannel.Value = {};
            app.FlowDetectionLabel = uilabel(app.FlowPanelGrid);
            app.FlowDetectionLabel.Text = 'LIMITES DE DETECÇÃO';
            app.FlowDetectionLimitsEdit = uiimage(app.FlowPanelGrid);
            app.FlowDetectionLimitsEdit.ImageClickedFcn = createCallbackFcn(app, @onOpenPopupApp, true);
            app.FlowDetectionLimitsEdit.Enable = 'off';
            app.FlowDetectionLimitsEdit.ImageSource = 'Edit_32.png';
            app.FlowDetectionLimits = uilistbox(app.FlowPanelGrid);
            app.FlowDetectionLimits.Items = {};
            app.FlowDetectionLimits.Value = {};
            app.FlowEmissionsLabel = uilabel(app.FlowPanelGrid);
            app.FlowEmissionsLabel.Text = 'EMISSÕES';
            app.FlowEmissionsFileImport = uiimage(app.FlowPanelGrid);
            app.FlowEmissionsFileImport.ImageClickedFcn = createCallbackFcn(app, @onEmissionsFileImportButtonClicked, true);
            app.FlowEmissionsFileImport.Enable = 'off';
            app.FlowEmissionsFileImport.ImageSource = 'Import_16.png';
            app.FlowEmissionsDataTips = uiimage(app.FlowPanelGrid);
            app.FlowEmissionsDataTips.ImageClickedFcn = createCallbackFcn(app, @onEmissionsDataTipsOrRoiButtonClicked, true);
            app.FlowEmissionsDataTips.Enable = 'off';
            app.FlowEmissionsDataTips.ImageSource = 'wave-datatip-22px.svg';
            app.FlowEmissionsDrawRoi = uiimage(app.FlowPanelGrid);
            app.FlowEmissionsDrawRoi.ImageClickedFcn = createCallbackFcn(app, @onEmissionsDataTipsOrRoiButtonClicked, true);
            app.FlowEmissionsDrawRoi.Enable = 'off';
            app.FlowEmissionsDrawRoi.ImageSource = 'wave-roi-22px.svg';
            app.FlowEmissionsAdd = uiimage(app.FlowPanelGrid);
            app.FlowEmissionsAdd.ImageClickedFcn = createCallbackFcn(app, @onOpenPopupApp, true);
            app.FlowEmissionsAdd.Enable = 'off';
            app.FlowEmissionsAdd.ImageSource = 'search-sparkle.svg';
            app.FlowEmissions = uitable(app.FlowPanelGrid);
            app.FlowEmissions.ColumnName = {'FREQUÊNCIA|(MHz)'; 'LARGURA|(kHz)'; 'INFORMAÇÕES|ADICIONAIS'};
            app.FlowEmissions.RowName = {};
            app.FlowEmissions.SelectionType = 'row';
            app.FlowEmissions.ColumnEditable = true;
            app.FlowEmissions.CellEditCallback = createCallbackFcn(app, @onEmissionsTableCellEdit, true);
            app.FlowEmissions.SelectionChangedFcn = createCallbackFcn(app, @onEmissionsTableSelectionChanged, true);
            app.FlowOccupancyLabel = uilabel(app.FlowPanelGrid);
            app.FlowOccupancyLabel.Text = 'OCUPAÇÃO';
            app.FlowOccupancyEdit = uiimage(app.FlowPanelGrid);
            app.FlowOccupancyEdit.ImageClickedFcn = createCallbackFcn(app, @onOpenPopupApp, true);
            app.FlowOccupancyEdit.Enable = 'off';
            app.FlowOccupancyEdit.ImageSource = 'Edit_32.png';
            app.FlowOccupancy = uilistbox(app.FlowPanelGrid);
            app.FlowOccupancy.Items = {};
            app.FlowOccupancy.Value = {};
            app.RightPanel = uigridlayout(app.Document);
            app.RightPanel.Visible = 'off';
            app.GeneralPanelIcon = uiimage(app.RightPanel);
            app.GeneralPanelIcon.ImageSource = 'DriveTestDensity_32.png';
            app.GeneralPanelLabel = uilabel(app.RightPanel);
            app.GeneralPanelLabel.Text = 'EIXOS GRÁFICOS';
            app.GeneralPanel = uipanel(app.RightPanel);
            app.GeneralPanelGrid = uigridlayout(app.GeneralPanel);
            app.LayoutRatioLabel = uilabel(app.GeneralPanelGrid);
            app.LayoutRatioLabel.Text = 'Razão de aspecto:';
            app.LayoutRatio = uidropdown(app.GeneralPanelGrid);
            app.LayoutRatio.Items = {'1:0:0'};
            app.LayoutRatio.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.LayoutRatio.Tag = 'layoutRatio';
            app.LayoutRatio.Value = '1:0:0';
            app.LimitsPanelLabel = uilabel(app.GeneralPanelGrid);
            app.LimitsPanelLabel.Text = 'Limites de frequência e nível:';
            app.LimitsRefresh = uiimage(app.GeneralPanelGrid);
            app.LimitsRefresh.ImageClickedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsRefresh.Tag = 'limitsXYRefresh';
            app.LimitsRefresh.Visible = 'off';
            app.LimitsRefresh.ImageSource = 'Refresh_18.png';
            app.LimitsXLimLabel = uilabel(app.GeneralPanelGrid);
            app.LimitsXLimLabel.Text = 'MHz  ';
            app.LimitsXLim1 = uispinner(app.GeneralPanelGrid);
            app.LimitsXLim1.ValueDisplayFormat = '%.3f';
            app.LimitsXLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsXLim1.Tag = 'limitsX';
            app.LimitsXLim2 = uispinner(app.GeneralPanelGrid);
            app.LimitsXLim2.ValueDisplayFormat = '%.3f';
            app.LimitsXLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsXLim2.Tag = 'limitsX';
            app.LimitsXLim2.Value = 1;
            app.LimitsYLimLabel = uilabel(app.GeneralPanelGrid);
            app.LimitsYLimLabel.Text = 'dB  ';
            app.LimitsYLim1 = uispinner(app.GeneralPanelGrid);
            app.LimitsYLim1.Step = 5;
            app.LimitsYLim1.ValueDisplayFormat = '%.1f';
            app.LimitsYLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsYLim1.Tag = 'limitsY';
            app.LimitsYLim2 = uispinner(app.GeneralPanelGrid);
            app.LimitsYLim2.Step = 5;
            app.LimitsYLim2.ValueDisplayFormat = '%.1f';
            app.LimitsYLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.LimitsYLim2.Tag = 'limitsY';
            app.LimitsYLim2.Value = 1;
            app.PersistencePanelIcon = uiimage(app.RightPanel);
            app.PersistencePanelIcon.ImageSource = 'persistence-36px.png';
            app.PersistencePanelLabel = uilabel(app.RightPanel);
            app.PersistencePanelLabel.Text = 'PERSISTÊNCIA';
            app.PersistencePanel = uipanel(app.RightPanel);
            app.PersistencePanelGrid = uigridlayout(app.PersistencePanel);
            app.PersistenceInterpolationLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceInterpolationLabel.Text = 'Interpolação:';
            app.PersistenceInterpolation = uidropdown(app.PersistencePanelGrid);
            app.PersistenceInterpolation.Items = {'nearest', 'bilinear'};
            app.PersistenceInterpolation.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceInterpolation.Tag = 'persistenceInterpolation';
            app.PersistenceInterpolation.Enable = 'off';
            app.PersistenceInterpolation.Value = 'nearest';
            app.PersistenceWindowSizeLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceWindowSizeLabel.Text = 'Tamanho janela:';
            app.PersistenceWindowSizeValue = uilabel(app.PersistencePanelGrid);
            app.PersistenceWindowSizeValue.Text = 'full';
            app.PersistenceWindowSize = uidropdown(app.PersistencePanelGrid);
            app.PersistenceWindowSize.Items = {'128', '256', '512', 'full'};
            app.PersistenceWindowSize.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceWindowSize.Tag = 'persistenceWindowSize';
            app.PersistenceWindowSize.Enable = 'off';
            app.PersistenceWindowSize.Value = 'full';
            app.PersistenceColormapLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceColormapLabel.Text = 'Mapa de cores:';
            app.PersistenceColormap = uidropdown(app.PersistencePanelGrid);
            app.PersistenceColormap.Items = {'winter', 'parula', 'turbo'};
            app.PersistenceColormap.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceColormap.Tag = 'persistenceColormap';
            app.PersistenceColormap.Enable = 'off';
            app.PersistenceColormap.Value = 'winter';
            app.PersistenceTransparencyLabel = uilabel(app.PersistencePanelGrid);
            app.PersistenceTransparencyLabel.Text = 'Transparência:';
            app.PersistenceTransparency = uispinner(app.PersistencePanelGrid);
            app.PersistenceTransparency.Step = 0.05;
            app.PersistenceTransparency.Limits = [0.2 1];
            app.PersistenceTransparency.ValueDisplayFormat = '%.2f';
            app.PersistenceTransparency.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.PersistenceTransparency.Tag = 'persistenceTransparency';
            app.PersistenceTransparency.Enable = 'off';
            app.PersistenceTransparency.Value = 1;
            app.PersistenceCLim_Label = uilabel(app.PersistencePanelGrid);
            app.PersistenceCLim_Label.Text = 'Limites de intensidade (%):';
            app.PersistenceCLimRefresh = uiimage(app.PersistencePanelGrid);
            app.PersistenceCLimRefresh.ImageClickedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.PersistenceCLimRefresh.Tag = 'limitsPersistenceRefresh';
            app.PersistenceCLimRefresh.Visible = 'off';
            app.PersistenceCLimRefresh.ImageSource = 'Refresh_18.png';
            app.PersistenceCLim1 = uispinner(app.PersistencePanelGrid);
            app.PersistenceCLim1.Step = 0.1;
            app.PersistenceCLim1.Limits = [0 Inf];
            app.PersistenceCLim1.ValueDisplayFormat = '%.3f';
            app.PersistenceCLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.PersistenceCLim1.Tag = 'limitsPersistence';
            app.PersistenceCLim1.Enable = 'off';
            app.PersistenceCLim1.Value = 0.1;
            app.PersistenceCLim2 = uispinner(app.PersistencePanelGrid);
            app.PersistenceCLim2.Limits = [0 Inf];
            app.PersistenceCLim2.ValueDisplayFormat = '%.3f';
            app.PersistenceCLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.PersistenceCLim2.Tag = 'limitsPersistence';
            app.PersistenceCLim2.Enable = 'off';
            app.PersistenceCLim2.Value = 1;
            app.WaterfallPanelIcon = uiimage(app.RightPanel);
            app.WaterfallPanelIcon.ImageSource = 'waterfall-22px.png';
            app.WaterfallPanelLabel = uilabel(app.RightPanel);
            app.WaterfallPanelLabel.Text = 'WATERFALL';
            app.WaterfallPanel = uipanel(app.RightPanel);
            app.WaterfallPanelGrid = uigridlayout(app.WaterfallPanel);
            app.WaterfallFunctionLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallFunctionLabel.Text = 'Renderização:';
            app.WaterfallFunction = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallFunction.Items = {'image', 'mesh'};
            app.WaterfallFunction.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallFunction.Tag = 'waterfallFunction';
            app.WaterfallFunction.Enable = 'off';
            app.WaterfallFunction.Value = 'image';
            app.WaterfallDecimationLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallDecimationLabel.Text = 'Decimação:';
            app.WaterfallDecimationValue = uilabel(app.WaterfallPanelGrid);
            app.WaterfallDecimationValue.Text = 'auto';
            app.WaterfallDecimation = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallDecimation.Items = {'auto', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.WaterfallDecimation.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallDecimation.Tag = 'waterfallDecimation';
            app.WaterfallDecimation.Enable = 'off';
            app.WaterfallDecimation.Value = 'auto';
            app.WaterfallColormapLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallColormapLabel.Text = 'Mapa de cores:';
            app.WaterfallColormap = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallColormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.WaterfallColormap.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallColormap.Tag = 'waterfallColormap';
            app.WaterfallColormap.Enable = 'off';
            app.WaterfallColormap.Value = 'winter';
            app.WaterfallMeshStyleLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallMeshStyleLabel.Text = 'Linhas da superfície:';
            app.WaterfallMeshStyle = uidropdown(app.WaterfallPanelGrid);
            app.WaterfallMeshStyle.Items = {'row', 'both'};
            app.WaterfallMeshStyle.ValueChangedFcn = createCallbackFcn(app, @onDisplayPlotConfigChanged, true);
            app.WaterfallMeshStyle.Tag = 'waterfallMeshStyle';
            app.WaterfallMeshStyle.Enable = 'off';
            app.WaterfallMeshStyle.Value = 'row';
            app.WaterfallCLimLabel = uilabel(app.WaterfallPanelGrid);
            app.WaterfallCLimLabel.Text = 'Limites de nível (dB):';
            app.WaterfallCLimRefresh = uiimage(app.WaterfallPanelGrid);
            app.WaterfallCLimRefresh.ImageClickedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.WaterfallCLimRefresh.Tag = 'limitsWaterfallRefresh';
            app.WaterfallCLimRefresh.Visible = 'off';
            app.WaterfallCLimRefresh.ImageSource = 'Refresh_18.png';
            app.WaterfallCLim1 = uispinner(app.WaterfallPanelGrid);
            app.WaterfallCLim1.Step = 5;
            app.WaterfallCLim1.RoundFractionalValues = 'on';
            app.WaterfallCLim1.ValueDisplayFormat = '%.0f';
            app.WaterfallCLim1.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.WaterfallCLim1.Tag = 'limitsWaterfall';
            app.WaterfallCLim1.Enable = 'off';
            app.WaterfallCLim2 = uispinner(app.WaterfallPanelGrid);
            app.WaterfallCLim2.Step = 5;
            app.WaterfallCLim2.RoundFractionalValues = 'on';
            app.WaterfallCLim2.ValueDisplayFormat = '%.0f';
            app.WaterfallCLim2.ValueChangedFcn = createCallbackFcn(app, @onAxesLimitsConfigChanged, true);
            app.WaterfallCLim2.Tag = 'limitsWaterfall';
            app.WaterfallCLim2.Enable = 'off';
            app.WaterfallCLim2.Value = 1;
            app.AxesContainer = uipanel(app.Document);
            app.AxesAnnotation = uilabel(app.Document);
            app.AxesAnnotation.Text = '';
            app.AxesToolbar = uigridlayout(app.Document);
            app.axesTool_RestoreView = uiimage(app.AxesToolbar);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_Pan = uiimage(app.AxesToolbar);
            app.axesTool_Pan.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_Pan.ImageSource = 'pan-32px.png';
            app.axesTool_ExportGraphics = uiimage(app.AxesToolbar);
            app.axesTool_ExportGraphics.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarExportGraphicsClicked, true);
            app.axesTool_ExportGraphics.ImageSource = 'screen-cut.svg';
            app.axesTool_Separator1 = uiimage(app.AxesToolbar);
            app.axesTool_Separator1.Enable = 'off';
            app.axesTool_Separator1.ImageSource = 'LineV.svg';
            app.axesTool_crearWrite = uiimage(app.AxesToolbar);
            app.axesTool_crearWrite.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_crearWrite.Tag = 'minHold';
            app.axesTool_crearWrite.Enable = 'off';
            app.axesTool_crearWrite.ImageSource = 'eye-closed-16px.svg';
            app.axesTool_minHold = uiimage(app.AxesToolbar);
            app.axesTool_minHold.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_minHold.Tag = 'minHold';
            app.axesTool_minHold.Enable = 'off';
            app.axesTool_minHold.ImageSource = 'MinHold_32.png';
            app.axesTool_average = uiimage(app.AxesToolbar);
            app.axesTool_average.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_average.Tag = 'average';
            app.axesTool_average.Enable = 'off';
            app.axesTool_average.ImageSource = 'Average_32.png';
            app.axesTool_maxHold = uiimage(app.AxesToolbar);
            app.axesTool_maxHold.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_maxHold.Tag = 'maxHold';
            app.axesTool_maxHold.Enable = 'off';
            app.axesTool_maxHold.ImageSource = 'MaxHold_32.png';
            app.axesTool_emissions = uiimage(app.AxesToolbar);
            app.axesTool_emissions.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_emissions.Enable = 'off';
            app.axesTool_emissions.ImageSource = 'wave-roi-22px-red.svg';
            app.axesTool_persistence = uiimage(app.AxesToolbar);
            app.axesTool_persistence.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_persistence.Tag = 'persistence';
            app.axesTool_persistence.Enable = 'off';
            app.axesTool_persistence.ImageSource = 'persistence-36px.png';
            app.axesTool_Separator2 = uiimage(app.AxesToolbar);
            app.axesTool_Separator2.Enable = 'off';
            app.axesTool_Separator2.ImageSource = 'LineV.svg';
            app.axesTool_occupancy = uiimage(app.AxesToolbar);
            app.axesTool_occupancy.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_occupancy.Tag = 'occupancy';
            app.axesTool_occupancy.Enable = 'off';
            app.axesTool_occupancy.ImageSource = 'Occupancy_32.png';
            app.axesTool_Separator3 = uiimage(app.AxesToolbar);
            app.axesTool_Separator3.Enable = 'off';
            app.axesTool_Separator3.ImageSource = 'LineV.svg';
            app.axesTool_waterfall = uiimage(app.AxesToolbar);
            app.axesTool_waterfall.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_waterfall.Tag = 'waterfall';
            app.axesTool_waterfall.Enable = 'off';
            app.axesTool_waterfall.ImageSource = 'waterfall-22px.png';
            app.axesTool_DataTip = uiimage(app.AxesToolbar);
            app.axesTool_DataTip.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.axesTool_DataTip.Enable = 'off';
            app.axesTool_DataTip.ImageSource = 'datatip-20px.png';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.ContextMenuChannels = uicontextmenu(app.UIFigure);
            app.ContextMenuChannelInfo = uimenu(app.ContextMenuChannels);
            app.ContextMenuChannelInfo.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuChannelsOptionClicked, true);
            app.ContextMenuChannelInfo.Enable = 'off';
            app.ContextMenuChannelInfo.Text = '❓ Informações adicionais';
            app.ContextMenuDeleteChannel = uimenu(app.ContextMenuChannels);
            app.ContextMenuDeleteChannel.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuChannelsOptionClicked, true);
            app.ContextMenuDeleteChannel.Enable = 'off';
            app.ContextMenuDeleteChannel.Separator = 'on';
            app.ContextMenuDeleteChannel.Text = '❌ Excluir';
            app.ContextMenuDeleteChannels = uimenu(app.ContextMenuChannels);
            app.ContextMenuDeleteChannels.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuChannelsOptionClicked, true);
            app.ContextMenuDeleteChannels.Enable = 'off';
            app.ContextMenuDeleteChannels.Text = '🚫 Excluir tudo';
            app.ContextMenuAddDetectionLimits = uimenu(app.ContextMenuChannels);
            app.ContextMenuAddDetectionLimits.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuChannelsOptionClicked, true);
            app.ContextMenuAddDetectionLimits.Enable = 'off';
            app.ContextMenuAddDetectionLimits.Separator = 'on';
            app.ContextMenuAddDetectionLimits.Text = '📏 Adiciona limites à detecção';
            app.ContextMenuAddChannelAsEmission = uimenu(app.ContextMenuChannels);
            app.ContextMenuAddChannelAsEmission.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuChannelsOptionClicked, true);
            app.ContextMenuAddChannelAsEmission.Enable = 'off';
            app.ContextMenuAddChannelAsEmission.Text = '📶 Adiciona canais como emissões';
            % Assign app.ContextMenuChannels
            app.FlowChannel.ContextMenu = app.ContextMenuChannels;
            app.ContextMenuEmissions = uicontextmenu(app.UIFigure);
            app.ContextMenuWhoIsEmission = uimenu(app.ContextMenuEmissions);
            app.ContextMenuWhoIsEmission.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuEmissionOptionClicked, true);
            app.ContextMenuWhoIsEmission.Enable = 'off';
            app.ContextMenuWhoIsEmission.Text = '🏷️ Classificação';
            app.ContextMenuDeleteEmission = uimenu(app.ContextMenuEmissions);
            app.ContextMenuDeleteEmission.MenuSelectedFcn = createCallbackFcn(app, @onContextMenuEmissionOptionClicked, true);
            app.ContextMenuDeleteEmission.Enable = 'off';
            app.ContextMenuDeleteEmission.Separator = 'on';
            app.ContextMenuDeleteEmission.Text = '❌ Excluir';
            % Assign app.ContextMenuEmissions
            app.FlowEmissions.ContextMenu = app.ContextMenuEmissions;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winPlayback(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end