classdef winSignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DockModule                     matlab.ui.container.GridLayout
        dockModule_Close               matlab.ui.control.Image
        dockModule_Undock              matlab.ui.control.Image
        Toolbar                        matlab.ui.container.GridLayout
        PanelLeftVisibility            matlab.ui.control.Image
        PanelBottomVisibility          matlab.ui.control.Image
        tool_Separator                 matlab.ui.control.Image
        tool_ShowGlobalExceptionList   matlab.ui.control.Image
        tool_ExportJSONFile            matlab.ui.control.Image
        tool_EmissionReportListLimit   matlab.ui.control.CheckBox
        SelectedEmissionGrid           matlab.ui.container.GridLayout
        SelectedEmissionPanel          matlab.ui.container.Panel
        SelectedEmissionPanelGrid      matlab.ui.container.GridLayout
        ClassificationRefresh          matlab.ui.control.Image
        LOG                            matlab.ui.control.Label
        LOGLabel                       matlab.ui.control.Label
        AdditionalDescription          matlab.ui.control.EditField
        AdditionalDescriptionLabel     matlab.ui.control.Label
        TXLocationPanel                matlab.ui.container.Panel
        TXLocationGrid                 matlab.ui.container.GridLayout
        TXAntennaHeight                matlab.ui.control.NumericEditField
        TXAntennaHeightLabel           matlab.ui.control.Label
        TXLongitude                    matlab.ui.control.NumericEditField
        TXLongitudeLabel               matlab.ui.control.Label
        TXLatitude                     matlab.ui.control.NumericEditField
        TXLatitudeLabel                matlab.ui.control.Label
        TXLocationEditionGrid          matlab.ui.container.GridLayout
        TXLocationEditCancel           matlab.ui.control.Image
        TXLocationEditConfirm          matlab.ui.control.Image
        TXLocationEditMode             matlab.ui.control.Image
        TXLocationPanelLabel           matlab.ui.control.Label
        RiskLevel                      matlab.ui.control.DropDown
        RiskLevelIcon                  matlab.ui.control.Label
        RiskLevelLabel                 matlab.ui.control.Label
        Compliance                     matlab.ui.control.DropDown
        ComplianceLabel                matlab.ui.control.Label
        EmissionType                   matlab.ui.control.DropDown
        EmissionTypeLabel              matlab.ui.control.Label
        StationID                      matlab.ui.control.EditField
        StationIDLabel                 matlab.ui.control.Label
        Regulatory                     matlab.ui.control.DropDown
        RegulatoryLabel                matlab.ui.control.Label
        EmissionTitle                  matlab.ui.control.Label
        SelectedEmissionLabel          matlab.ui.control.Label
        SelectedEmissionIcon           matlab.ui.control.Image
        Document                       matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        RFLinkWarning                  matlab.ui.control.Image
        AxesToolbar                    matlab.ui.container.GridLayout
        AxesPanButton                  matlab.ui.control.Image
        AxesRestoreViewButton          matlab.ui.control.Image
        AxesContainer                  matlab.ui.container.Panel
        UITableLabel                   matlab.ui.control.Label
        UITableIcon                    matlab.ui.control.Image
        ContextMenu                    matlab.ui.container.ContextMenu
        contextmenu_TruncateItem       matlab.ui.container.Menu
        contextmenu_ChannelEmission    matlab.ui.container.Menu
        contextmenu_TruncateEmission   matlab.ui.container.Menu
        contextmenu_NonTruncateEmission  matlab.ui.container.Menu
        contextmenu_PredictionWarning  matlab.ui.container.Menu
        contextmenu_DeleteEmission     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'SIGNALANALYSIS'
        AppHandleNameInBase
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
        popupContainer

        SubTabGroup = struct('Children', -1, 'UserData', [])

        % Handles dos eixos cartesianos utilizados por este módulo.
        UIAxes1
        UIAxes2

        % Informações relacionadas ao specData selecionado, com atalhos para 
        % os principais metadados e a definição dos limites dos eixos x, y e 
        % z dos eixos cartesianos.
        bandObj

        % Armazena limites padrão dos eixos cartesianos computados em método 
        % de model.Band (app.bandObj).
        restoreView = struct( ...
            'ID', {}, ...
            'xLim', {}, ...
            'yLim', {}, ...
            'cLim', {} ...
        )
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        emissionsTable
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    case 'onPredictionWarningToogleRequested'
                        selectedRow = app.UITable.UserData.selectedRow;
                        [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
                        specData = app.mainApp.specData(flowIdx);

                        update(specData, 'UserData:Emissions', 'Edit', 'AlertClassificationMismatch', emissionIdx)
                        applyInitialLayout(app)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, varargin)
            try
                switch class(callingApp)
                    case {'winAppAnalise', 'winAppAnalise_exported'}
                        eventName = varargin{1};

                        switch eventName
                            case {'onFileListAdded', ...
                                  'onFileListRemoved', ...
                                  'onFileFilterChanged', ...
                                  'onProjectLoad', ...
                                  'onFlowMergeRequested', ...
                                  'onReportFlowListChanged', ...
                                  'onFlowUnlockRequested', ...
                                  'onLocationChanged', ...
                                  'onEmissionAdded', ...
                                  'onEmissionParameterValueChanged', ...
                                  'onEmissionTruncatedValueChanged', ...
                                  'onEmissionChannelChanged', ...
                                  'onEmissionDeleted', ...
                                  'onDetectionSubBandsChanged', ...
                                  'onCalibrationCurveApplied'}
                                applyInitialLayout(app)

                            case 'onEmissionSelectionChanged'
                                if ~isempty(app.emissionsTable)
                                    flowIdx = varargin{2};
                                    emissionIdx = varargin{3};
    
                                    previousSelection = app.UITable.UserData.selectedRow;
                                    currentSelection = find(app.emissionsTable.flowIdx == flowIdx & app.emissionsTable.emissionIdx == emissionIdx, 1);
                                    
                                    if ~isempty(currentSelection) && ~isequal(previousSelection, currentSelection)
                                        app.UITable.Selection = currentSelection;
                                        app.UITable.UserData.selectedRow = currentSelection;
                                        scroll(app.UITable, 'row', currentSelection)

                                        onUITableSelectionChanged(app, struct('Selection', currentSelection, 'PreviousSelection', previousSelection))
                                    end
                                end

                            otherwise
                                error('auxApp:winSignalAnalysis:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end

                    otherwise
                        error('auxApp:winSignalAnalysis:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end
            
            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            appName = class(app);
            switch tabIndex
                case 1
                    elToModify = { 
                        app.UITable;
                        app.AxesToolbar;
                        app.ClassificationRefresh;
                        app.RiskLevel;
                        app.TXLocationEditMode;
                        app.TXLocationEditConfirm;
                        app.TXLocationEditCancel;
                        app.tool_ExportJSONFile;
                        app.tool_ShowGlobalExceptionList;
                        app.PanelBottomVisibility;
                        app.PanelLeftVisibility;
                        app.RFLinkWarning;
                        app.dockModule_Undock;
                        app.dockModule_Close;
                        app.EmissionTitle
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.UITable.UserData.id, 'tableMultiline', struct('status', true, 'verticalCenter', false), 'tableTextAlignment', [struct('columnId', 4, 'textAlign', 'right'), struct('columnId', 5, 'textAlign', 'right'), struct('columnId', 7, 'textAlign', 'justify')]), ...
                            struct('appName', appName, 'dataTag', app.AxesToolbar.UserData.id, 'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                            struct('appName', appName, 'dataTag', app.ClassificationRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Retorna à classificação automática')), ...
                            struct('appName', appName, 'dataTag', app.RiskLevel.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Potencial lesivo do indício de irregularidade:<br>• Baixo: sem ação automática.<br>• Médio: alerta ao centralizador para abertura de demanda planejada.<br>• Alto: alerta ao centralizador e à FIGF para abertura de demanda imediata.')), ...
                            struct('appName', appName, 'dataTag', app.TXLocationEditMode.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Alterna visibilidade do painel de edição')), ...
                            struct('appName', appName, 'dataTag', app.TXLocationEditConfirm.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Confirma edição, recriando perfil de terreno')), ...
                            struct('appName', appName, 'dataTag', app.TXLocationEditCancel.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Cancela edição')), ...
                            struct('appName', appName, 'dataTag', app.tool_ExportJSONFile.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exporta arquivo com informações das emissões')), ...
                            struct('appName', appName, 'dataTag', app.tool_ShowGlobalExceptionList.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Mostra lista global de exceções')), ...
                            struct('appName', appName, 'dataTag', app.PanelBottomVisibility.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Alterna visibilidade do painel inferior')), ...
                            struct('appName', appName, 'dataTag', app.PanelLeftVisibility.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Alterna visibilidade do painel à direita')), ...
                            struct('appName', appName, 'dataTag', app.RFLinkWarning.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Evidenciada obstrução total da 1ª Zona de Fresnel')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Undock.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.dockModule_Close.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                    try
                        ui.TextView.startup(app.jsBackDoor, app.LOG, appName, struct('class', {{'textview--wordbreak'}}));
                    catch
                    end

                otherwise
                    % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            app.bandObj = model.Band('appAnalise:SIGNALANALYSIS', app.mainApp);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            addStyle(app.UITable, uistyle("Interpreter", "html"))
            app.UITable.UserData.selectedRow = [];
            app.UITable.UserData.selectedRowStyleIdx = [];

            app.AxesContainer.UserData.emissionTag = '';
            app.AxesContainer.UserData.rfLinkWarning = false;

            app.AxesPanButton.UserData.status = false;
            app.TXLocationEditMode.UserData.status = false;
            app.PanelBottomVisibility.UserData.status = true;

            initializeAxes(app)
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            if app.tool_EmissionReportListLimit.Value
                flowIdxs = find(arrayfun(@(x) x.UserData.ReportInclude, app.mainApp.specData));
            else
                flowIdxs = 1:numel(app.mainApp.specData);
            end

            selectedRow = app.UITable.UserData.selectedRow;
            updateTable(app, flowIdxs, selectedRow)
            focus(app.UITable)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initializeAxes(app)
            hParent = tiledlayout(app.AxesContainer, 1, 3, "Padding", "compact", "TileSpacing", "compact");
            set(hParent.Title, 'Color', [.8,.8,.8], 'FontSize', 7)
            
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'YAxisLocation', "right", 'Clipping', 'off'});
            app.UIAxes2.Layout.Tile = 2;
            app.UIAxes2.Layout.TileSpan = [1 2];
            app.UIAxes2.XAxis.TickLabelFormat = '%.1f';

            app.UIAxes1 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'Box', 'on', 'TickDir', 'none'});
            
            xlabel(app.UIAxes1, 'Frequência (MHz)')
            ylabel(app.UIAxes1, 'Nível (dB)')
            xlabel(app.UIAxes2, 'Distância (km)')
            ylabel(app.UIAxes2, 'Elevação (m)')

            plot.axes.Interactivity.DefaultCreation([app.UIAxes1, app.UIAxes2], [dataTipInteraction, regionZoomInteraction, rulerPanInteraction])
        end

        %-----------------------------------------------------------------%
        function updateTable(app, flowIdxs, selectedRow)
            calculateEmissionPrediction(app, flowIdxs)
            app.emissionsTable = util.createEmissionsTable(app.mainApp.specData, flowIdxs, 'SIGNALANALYSIS: GUI', app.mainApp.General);
    
            if isempty(app.emissionsTable)
                selectedRow = [];
            else
                if isempty(selectedRow)
                    selectedRow = 1;
                elseif selectedRow > height(app.emissionsTable)
                    selectedRow = height(app.emissionsTable);
                end
            end

            requestVisibilityChange(app.progressDialog, 'visible', 'unlocked')
    
            columnNames = { ...
                'Frequency', ...
                'Truncated', ...
                'BandWidthkHz', ...
                'LevelRange', ...
                'FCORange', ...
                'Prediction', ...
                'RFDataHubDescription', ...
                'Distance' ...
            };

            set(app.UITable, 'Data', app.emissionsTable(:, columnNames), 'Selection', selectedRow)
            app.UITable.UserData.selectedRow = selectedRow;
            app.UITable.UserData.selectedRowStyleIdx = [];

            applyTableStyle(app, 'clearCellStyles')
            applyTableStyle(app, 'emissionIndicators')
            applyTableStyle(app, 'selectedRow')

            updateSelectedEmissionFormAndPlot(app)
            pause(.100)
    
            requestVisibilityChange(app.progressDialog, 'hidden', 'unlocked')
        end

        %-----------------------------------------------------------------%
        function applyTableStyle(app, style)
            switch style
                case 'clearCellStyles'
                    cellStylesIdxs = find(app.UITable.StyleConfigurations.Target == "cell");
                    if ~isempty(cellStylesIdxs)
                        removeStyle(app.UITable, cellStylesIdxs)
                    end

                case 'emissionIndicators'
                    % Destaca registros que tiveram a sua classificação editada...
                    editedEmissionIdxs = [];

                    for ii = 1:height(app.emissionsTable)
                        if ~isequal( ...
                            rmfield(app.emissionsTable.Classification(ii).AutoSuggested, 'AlertClassificationMismatch'), ...
                            rmfield(app.emissionsTable.Classification(ii).UserModified,  'AlertClassificationMismatch') ...
                        )
                            editedEmissionIdxs = [editedEmissionIdxs; ii];
                        end
                    end
                    
                    if ~isempty(editedEmissionIdxs)
                        cellList = [editedEmissionIdxs, ones(numel(editedEmissionIdxs), 1)];
                        addStyle(app.UITable, uistyle('Icon', 'edit.svg',  'IconAlignment', 'leftmargin'), 'cell', cellList) 
                    end

                    % Destaca registros cujas frequências centrais não foram
                    % truncados.
                    nonTruncatedIdxs = find(~app.emissionsTable.IsTruncated);

                    if ~isempty(nonTruncatedIdxs)
                        cellList = [nonTruncatedIdxs, 2*ones(numel(nonTruncatedIdxs), 1)];
                        addStyle(app.UITable, uistyle('Icon', 'wave.svg',  'IconAlignment', 'leftmargin'), 'cell', cellList) 
                    end

                    % Destaca registros cuja diferença entre os níveis preditos 
                    % e o medido são superiores à tolerância.
                    predictionWarningIdxs = find(app.emissionsTable.PredictionWarning);

                    if ~isempty(predictionWarningIdxs)
                        cellList = [predictionWarningIdxs, 6*ones(numel(predictionWarningIdxs), 1)];
                        addStyle(app.UITable, uistyle('FontColor', 'white', 'BackgroundColor', 'red'), 'cell', cellList) 
                    end
        
                    % Destaca registros que apresentam valores inválidos de 
                    % estações.
                    invalidStationNumberIdxs = find(cellfun(@(x) isequal(x, -1), arrayfun(@(x) x.UserModified.Station, app.emissionsTable.Classification, 'UniformOutput', false)));

                    if ~isempty(invalidStationNumberIdxs)
                        cellList = [invalidStationNumberIdxs, 7*ones(numel(invalidStationNumberIdxs), 1)];
                        addStyle(app.UITable, uistyle('FontColor', 'white', 'BackgroundColor', 'red'), 'cell', cellList) 
                    end

                case 'selectedRow'
                    % Destaca célula selecionada...
                    if ~isempty(app.emissionsTable)
                        selectedRow = app.UITable.UserData.selectedRow;
                        selectedRowStyleIdx = app.UITable.UserData.selectedRowStyleIdx;

                        if isempty(selectedRowStyleIdx)
                            selectedRowStyleIdx = height(app.UITable.StyleConfigurations) + 1;
                        else
                            removeStyle(app.UITable, selectedRowStyleIdx)
                        end

                        addStyle(app.UITable, uistyle('Icon', 'eye.svg', 'IconAlignment', 'leftmargin'), 'cell', [selectedRow, 1])
                        app.UITable.UserData.selectedRowStyleIdx = selectedRowStyleIdx;
                        drawnow
                    end
            end
        end

        %-----------------------------------------------------------------%
        function updateSelectedEmissionFormAndPlot(app)
            if ~isempty(app.emissionsTable)
                selectedRow = app.UITable.UserData.selectedRow;
                [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
                specData = app.mainApp.specData(flowIdx);

                % Verifica se o handle para o app continua ativo no workspace
                % base do MATLAB, possibilitando que clicks no ui.TextView sejam 
                % capturados corretamente.
                appHandleNameInBase = app.AppHandleNameInBase;
                if isempty(appHandleNameInBase) || ~evalin('base', sprintf('exist("%s", "var") && isa(%s, "%s") && isvalid(%s)', appHandleNameInBase, appHandleNameInBase, class(app), appHandleNameInBase))
                    app.AppHandleNameInBase = ui.Table.exportAppHandleToBaseWorkspace(app);
                end

                [htmlContent1, ...
                 htmlContent2, ...
                 userDescription, ...
                 stationInfo] = util.HtmlTextGenerator.getSelectedEmissionMetaData(specData, emissionIdx, app.Context, app.AppHandleNameInBase, app.mainApp.General);
    
                ui.TextView.update(app.EmissionTitle, htmlContent1);
                ui.TextView.update(app.LOG, htmlContent2);
                set(app.AdditionalDescription, 'Value', userDescription, 'UserData', userDescription) 
    
                % CONTROL PANEL

                app.ClassificationRefresh.Visible = ~isequal( ...
                    rmfield(specData.UserData.Emissions.Classification(emissionIdx).AutoSuggested, 'AlertClassificationMismatch'), ...
                    rmfield(specData.UserData.Emissions.Classification(emissionIdx).UserModified,  'AlertClassificationMismatch') ...
                );
                
                app.Regulatory.Value = specData.UserData.Emissions.Classification(emissionIdx).UserModified.Regulatory;
                updateRegulatoryStyle(app)

                app.StationID.Value = num2str(specData.UserData.Emissions.Classification(emissionIdx).UserModified.Station);
                updateStationIdStyle(app)

                app.EmissionType.Value = specData.UserData.Emissions.Classification(emissionIdx).UserModified.EmissionType;
                updateEmissionTypeStyle(app)

                app.Compliance.Value = specData.UserData.Emissions.Classification(emissionIdx).UserModified.Irregular;
                updateComplianceStyle(app)

                app.RiskLevel.Value = specData.UserData.Emissions.Classification(emissionIdx).UserModified.RiskLevel;
    
                % PLOT CONFIG PANEL
                app.TXLocationEditConfirm.UserData.details = stationInfo;
                updateCoordinatesPanelContent(app)
    
                % PLOT
                updatePlot(app, selectedRow, flowIdx, emissionIdx)
                
                app.SelectedEmissionPanelGrid.Visible = 1;
                app.tool_ExportJSONFile.Enable = 1;

            else
                ui.TextView.update(app.EmissionTitle, '');
                
                cla(app.UIAxes1)
                cla(app.UIAxes2)
                
                app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', [0, 1], 'yLim', [0, 1], 'cLim', 'auto');
                app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', [0, 1], 'yLim', [0, 1], 'cLim', 'auto');
                onAxesToolbarButtonClicked(app, struct('Source', app.AxesRestoreViewButton))
                
                ylabel(app.UIAxes1, 'Nível (dB)')
                app.UIAxes1.Parent.Title.String = newline;
                
                app.RFLinkWarning.Visible = false;
                app.ClassificationRefresh.Visible = 0;
                app.SelectedEmissionPanelGrid.Visible = 0;
                app.tool_ExportJSONFile.Enable = 0;

                app.AxesContainer.UserData.emissionTag = '';
                app.AxesContainer.UserData.rfLinkWarning = false;
            end

            updateRiskLevelStyle(app)
        end

        %-----------------------------------------------------------------%
        function updateRegulatoryStyle(app)
            switch app.Regulatory.Value
                case {'Licenciada', 'Licenciada UTE'}
                    set(app.EmissionType, 'Value', 'Fundamental', 'Enable', 0)
                    app.EmissionTypeLabel.Enable   = 0;    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                otherwise % {'Não licenciada', 'Não passível de licenciamento'}
                    app.EmissionType.Enable = 1;
                    app.EmissionTypeLabel.Enable = 1;
                    app.StationID.Value = '-1';
    
                    switch app.Regulatory.Value
                        case 'Não licenciada'
                            app.Compliance.Items = {'Sim'};

                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                    end
            end
        end

        %-----------------------------------------------------------------%
        function updateStationIdStyle(app)
            if app.StationID.Value == "-1"
                set(app.StationID, 'BackgroundColor', [1,0,0], 'FontColor', [1,1,1])
            else
                set(app.StationID, 'BackgroundColor', [1,1,1], 'FontColor', [0,0,0])
            end
        end

        %-----------------------------------------------------------------%
        function updateEmissionTypeStyle(app)
            % Migrado do ordinário "set" p/ "addStyle" porque o "set" não
            % renderiza a cor da fonte até que o mouse passe sobre o
            % componente, mesmo inserindo drawnow ou pause(.100).
            % set(app.EmissionType, 'BackgroundColor', [1,1,1], 'FontColor', [0,0,0])

            removeStyle(app.EmissionType)
            if contains(app.EmissionType.Value, 'Pendente', 'IgnoreCase', true)
                backgroundColor = [1,0,0];
                fontColor       = [1,1,1];
            else
                backgroundColor = [1,1,1];
                fontColor       = [0,0,0];
            end
            addStyle(app.EmissionType, uistyle('BackgroundColor', backgroundColor, 'FontColor', fontColor))
        end

        %-----------------------------------------------------------------%
        function updateComplianceStyle(app)
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevelLabel, 'Enable', 0)
            else
                set(app.RiskLevel,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevelLabel, 'Enable', 1)
            end
        end

        %-----------------------------------------------------------------%
        function updateRiskLevelStyle(app)
            app.RiskLevelIcon.Visible = isequal(app.RiskLevel.Value, 'Alto');
        end

        %-----------------------------------------------------------------%
        function updateCoordinatesPanelStyle(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end            

            switch editionStatus
                case 'on'
                    app.TXLocationEditMode.ImageSource = 'Edit_32Filled.png';
                    app.TXLocationEditMode.UserData.status = true;

                    app.TXLocationEditionGrid.ColumnWidth(end-1:end) = {18, 18};
                    app.TXLocationEditConfirm.Enable = 1;
                    app.TXLocationEditCancel.Enable  = 1;
                    set(findobj(app.TXLocationGrid.Children, 'Type', 'uinumericeditfield'), 'Editable', 1)

                case 'off'
                    app.TXLocationEditMode.ImageSource = 'Edit_32.png';
                    app.TXLocationEditMode.UserData.status = false;

                    app.TXLocationEditionGrid.ColumnWidth(end-1:end) = {0, 0};
                    app.TXLocationEditConfirm.Enable = 0;
                    app.TXLocationEditCancel.Enable  = 0;
                    set(findobj(app.TXLocationGrid.Children, 'Type', 'uinumericeditfield'), 'Editable', 0)

                    updateCoordinatesPanelContent(app)
            end
        end

        %-----------------------------------------------------------------%
        function updateCoordinatesPanelContent(app)
            stationInfo = app.TXLocationEditConfirm.UserData.details;

            app.TXLatitude.Value  = double(stationInfo.Latitude);
            app.TXLongitude.Value = double(stationInfo.Longitude);

            if stationInfo.AntennaHeight > 0
                app.TXAntennaHeight.Value = stationInfo.AntennaHeight;
            else
                app.TXAntennaHeight.Value = app.mainApp.General.context.RFDATAHUB.tx.defaultHeight;
            end
        end

        %-----------------------------------------------------------------%
        function updatePlot(app, selectedRow, flowIdx, emissionIdx)
            if ~app.PanelBottomVisibility.UserData.status
                return
            end

            specData = app.mainApp.specData(flowIdx);
            emissionTag = util.HtmlTextGenerator.createTag('Emission', specData.UserData.Emissions.Frequency(emissionIdx), specData.UserData.Emissions.BandWidthkHz(emissionIdx));
            
            if strcmp(app.AxesContainer.UserData.emissionTag, emissionTag)
                return
            end

            createSpectrumPlot(app, specData, emissionIdx, emissionTag)
            createRFLinkPlot(app, selectedRow, flowIdx)
            app.AxesContainer.UserData.emissionTag = emissionTag;
        end

        %-----------------------------------------------------------------%
        function createSpectrumPlot(app, specData, emissionIdx, emissionTag)
            % Atualiza bandObj...
            updateSpectrumInfo(app.bandObj, specData, emissionIdx);
            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', app.bandObj.XLimits, 'yLim', app.bandObj.YLimitsLevel, 'cLim', 'auto');
            
            % Limpa eixo, atualizando labels e limites...
            cla(app.UIAxes1)
            ylabel(app.UIAxes1, sprintf('Nível (%s)', app.bandObj.LevelUnit))
            set(app.UIAxes1, 'XLim', app.restoreView(1).xLim, 'YLim', app.restoreView(1).yLim)
            app.UIAxes1.Parent.Title.String = sprintf('%s\n%.3f – %.3f MHz @ %s', specData.Receiver, specData.MetaData.FreqStart/1e6, specData.MetaData.FreqStop/1e6, emissionTag);

            % Plot "minHold", "average" e "maxHold"
            for plotTag = ["minHold", "average", "maxHold"]
                eval(sprintf('hLine = plot.draw2D.OrdinaryLine(app.UIAxes1, "%s", app.bandObj, []);', plotTag))
                plot.datatip.Template(hLine, "Frequency+Level", app.bandObj.LevelUnit)
            end

            % Plot "ROI"
            plot.draw2D.rectangularROI(app.UIAxes1, app.bandObj, specData.UserData.Emissions, emissionIdx, 'emissionROI', {'EdgeAlpha', 0, 'InteractionsAllowed', 'none'})
        end

        %-----------------------------------------------------------------%
        function createRFLinkPlot(app, selectedRow, flowIdx)
            try
                specData = app.mainApp.specData(flowIdx);

                % OBJETOS TX e RX
                [txObj, rxObj] = getRFLinkObjects(app, specData, selectedRow);
    
                % ELEVAÇÃO DO LINK TX-RX
                % Validação que possibilitahabilitar o bloqueio de tela apenas 
                % se a informação de elevação não estiver em cache.
                if ~IsCached(app.mainApp.elevationObj, txObj, rxObj, app.mainApp.General.elevation.pointCount)
                    requestVisibilityChange(app.progressDialog, 'visible', 'unlocked')
                end

                [wayPoints3D, msgWarning] = Get(app.mainApp.elevationObj, txObj, rxObj, app.mainApp.General.elevation.pointCount, app.mainApp.General.elevation.forceRefresh, app.mainApp.General.elevation.provider);
                if ~isempty(msgWarning)
                    ui.Dialog(app.UIFigure, 'warning', msgWarning);
                end
    
                % PLOT: RFLink
                plot.RFLink(app.UIAxes2, txObj, rxObj, wayPoints3D, 'dark')
                app.UIAxes2.PickableParts = "visible";
                app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', app.UIAxes2.XLim, 'yLim', app.UIAxes2.YLim, 'cLim', 'auto');

                app.RFLinkWarning.Visible = ~isempty(findobj(app.UIAxes2.Children, 'Tag', 'FirstObstruction'));
                app.AxesContainer.UserData.rfLinkWarning = app.RFLinkWarning.Visible;
                
            catch ME
                cla(app.UIAxes2)
                app.UIAxes2.PickableParts = "none";

                if exist('msgWarning', 'var') && ~isempty(msgWarning)
                    msgText = { ...
                        'PERFIL DE TERRENO ENTRE RECEPTOR';  ...
                        'E PROVÁVEL EMISSOR INDISPONÍVEL';  ...
                        sprintf('(%s)', msgWarning) ...
                    };
                else
                    msgText = { ...
                        'PERFIL DE TERRENO ENTRE RECEPTOR';  ...
                        'E PROVÁVEL EMISSOR É LIMITADO ÀS';  ...
                        'ESTAÇÕES INCLUÍDAS NO RFDATAHUB';   ...
                        '(EXCETO VISUALIZAÇÃO TEMPORÁRIA)' ...
                    };
                end

                msgTextHandle = text(app.UIAxes2, mean(app.UIAxes2.XLim), mean(app.UIAxes2.YLim), msgText, 'BackgroundColor', [.8,.8,.8], 'HorizontalAlignment', 'center', 'FontSize', 10);
                msgTextHandle.Units = 'normalized';
                
                app.RFLinkWarning.Visible = false;
                app.AxesContainer.UserData.rfLinkWarning = false;
            end

            app.TXLocationEditConfirm.Enable = 0;

            if strcmp(app.progressDialog.Visible, 'visible')
                requestVisibilityChange(app.progressDialog, 'hidden', 'unlocked')
            end
        end

        %-----------------------------------------------------------------%
        function fetchEmissionUpdate(app, triggeredComponent, varargin)
            selectedRow = app.UITable.UserData.selectedRow;
            [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
            specData = app.mainApp.specData(flowIdx);

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')

            % Se a alteração for apenas a descrição (campo textual), então
            % não é necessário reinicializar dados relacionados à predição.
            if triggeredComponent ~= app.AdditionalDescription
                predictionResults = specData.UserData.Emissions.AuxAppData(emissionIdx).SignalAnalysis;
                if ~isempty(predictionResults)
                    specData.UserData.Emissions.AuxAppData(emissionIdx).SignalAnalysis = [];
                end
            end

            switch triggeredComponent
                case app.ClassificationRefresh
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified = specData.UserData.Emissions.Classification(emissionIdx).AutoSuggested;

                case app.StationID
                    stationInfo = varargin{1};

                    switch stationInfo.Service 
                        case -1
                            newRegulatory = 'Não licenciada';
                            newCompliance = 'Sim';
                            newRiskLevel  = 'Baixo';
                        otherwise
                            newRegulatory = 'Licenciada';
                            newCompliance = 'Não';
                            newRiskLevel  = '-';
                    end

                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Service       = stationInfo.Service;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Station       = stationInfo.Station;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Latitude      = stationInfo.Latitude;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Longitude     = stationInfo.Longitude;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.AntennaHeight = stationInfo.AntennaHeight;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Description   = stationInfo.Description;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Details       = stationInfo.Details;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Distance      = stationInfo.Distance;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Regulatory    = newRegulatory;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.EmissionType  = 'Fundamental';                        
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Irregular     = newCompliance;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.RiskLevel     = newRiskLevel;

                case app.AdditionalDescription
                    userDescription = app.AdditionalDescription.Value;
                    if strcmp(userDescription, specData.UserData.Emissions.Description(emissionIdx))
                        return
                    end

                    specData.UserData.Emissions.Description(emissionIdx) = userDescription;
                    
                case app.TXLocationEditConfirm % Latitude | Longitude | AntennaHeight
                    update(specData, 'UserData:Emissions', 'Edit', 'Latitude+Longitude+AntennaHeight', emissionIdx, app.TXLatitude.Value, app.TXLongitude.Value, app.TXAntennaHeight.Value)

                otherwise
                    oldRegulatory = specData.UserData.Emissions.Classification(emissionIdx).UserModified.Regulatory;
                    newRegulatory = app.Regulatory.Value;

                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Regulatory    = app.Regulatory.Value;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.EmissionType  = app.EmissionType.Value;                        
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.Irregular     = app.Compliance.Value;
                    specData.UserData.Emissions.Classification(emissionIdx).UserModified.RiskLevel     = app.RiskLevel.Value;

                    if newRegulatory ~= "Licenciada"
                        specData.UserData.Emissions.Classification(emissionIdx).UserModified.Service     = int16(-1);
                        specData.UserData.Emissions.Classification(emissionIdx).UserModified.Station     = int32(-1);
                        specData.UserData.Emissions.Classification(emissionIdx).UserModified.Description = '[EXC]';
                        specData.UserData.Emissions.Classification(emissionIdx).UserModified.Details     = '';

                        if oldRegulatory == "Licenciada"
                            specData.UserData.Emissions.Classification(emissionIdx).UserModified.Latitude      = -1;
                            specData.UserData.Emissions.Classification(emissionIdx).UserModified.Longitude     = -1;
                            specData.UserData.Emissions.Classification(emissionIdx).UserModified.AntennaHeight = 0;
                            specData.UserData.Emissions.Classification(emissionIdx).UserModified.Distance      = -1;
                        end
                    end
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onEmissionParameterValueChanged', app.Context)
            applyInitialLayout(app)

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')
        end

        %-----------------------------------------------------------------%
        function [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow)
            flowIdx = app.emissionsTable.flowIdx(selectedRow);
            emissionIdx = app.emissionsTable.emissionIdx(selectedRow);
        end

        %-----------------------------------------------------------------%
        function [txSite, rxSite] = getRFLinkObjects(app, specData, selectedRow)
            % txSite e rxSite estão como struct, mas basta mudar para "txsite" e 
            % "rxsite" que eles poderão ser usados em predições, uma vez que os 
            % campos da estrutura são idênticos às propriedades dos objetos.
            if (app.TXLatitude.Value == -1) && (app.TXLongitude.Value == -1)
                error('winSignalAnalysis:RFLinkObjects:UnexpectedEmptyIndex', 'Unexpected empty index')
            end

            % TX
            txSite = struct( ...
                'Name', 'TX', ...
                'TransmitterFrequency', double(app.UITable.Data.Truncated(selectedRow) * 1e+6), ...
                'Latitude', app.TXLatitude.Value, ...
                'Longitude', app.TXLongitude.Value, ...
                'AntennaHeight', app.TXAntennaHeight.Value ...
            );

            % RX
            rxSite = struct( ...
                'Name', 'RX', ...
                'Latitude', specData.GPS.Latitude,  ...
                'Longitude', specData.GPS.Longitude, ...
                'AntennaHeight', calculateAntennaHeight(specData, 1, 10) ...
            );
        end

        %-----------------------------------------------------------------%
        function calculateEmissionPrediction(app, flowIdxs)
            for ii = flowIdxs
                for jj = 1:height(app.mainApp.specData(ii).UserData.Emissions)
                    specData = app.mainApp.specData(ii);
                    emissionIdx = jj;

                    predictionResult = specData.UserData.Emissions.AuxAppData(emissionIdx).SignalAnalysis;
                    if ~isempty(predictionResult) && isfield(predictionResult, 'IsCalculated') && predictionResult.IsCalculated
                        continue
                    end

                    try
                        [txObj, rxObj, stationSignature] = util.Propagation.buildRFLinkObjects(specData, emissionIdx, app.mainApp.General.context.RFDATAHUB.tx.defaultHeight);
            
                        [wayPoints3D, msgWarning] = Get(app.mainApp.elevationObj, txObj, rxObj, app.mainApp.General.elevation.pointCount, app.mainApp.General.elevation.forceRefresh, app.mainApp.General.elevation.provider);
                        if isempty(wayPoints3D)
                            error(msgWarning)
                        end
            
                        predictionResult = util.Propagation.calculateEmissionPrediction(specData, emissionIdx, txObj, rxObj, wayPoints3D, stationSignature);
                        update(specData, 'UserData:Emissions', 'AuxAppData:SignalAnalysis', emissionIdx, predictionResult)
                    catch
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function onDockModuleGroupButtonClicked(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection changed function: UITable
        function onUITableSelectionChanged(app, event)

            selectedRows = event.Selection;

            if isempty(selectedRows)
                app.UITable.Selection = event.PreviousSelection;
                return
            end

            if ~ismember(app.UITable.UserData.selectedRow, selectedRows)
                selectedRows = selectedRows(1);
                app.UITable.Selection = selectedRows;
                app.UITable.UserData.selectedRow = selectedRows;

                applyTableStyle(app, 'selectedRow')
                updateSelectedEmissionFormAndPlot(app)
            end
            
        end

        % Menu selected function: contextmenu_DeleteEmission, 
        % ...and 3 other components
        function onUITableContextMenuClicked(app, event)
            
            if isempty(app.UITable.Selection)
                return
            end

            requestVisibilityChange(app.progressDialog, 'visible', 'locked')

            selectedRows = app.UITable.Selection;
            eventStatus = false;

            for selectedRow = flip(selectedRows)
                [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
                specData = app.mainApp.specData(flowIdx);
    
                switch event.Source
                    case app.contextmenu_DeleteEmission
                        update(specData, 'UserData:Emissions', 'Delete', emissionIdx)
                        eventName = 'onEmissionDeleted';
                        eventStatus = true;
    
                    case {app.contextmenu_TruncateEmission, app.contextmenu_NonTruncateEmission}
                        isTruncated = specData.UserData.Emissions.IsTruncated(emissionIdx);

                        if ((event.Source == app.contextmenu_TruncateEmission) && isTruncated) || ...
                           ((event.Source == app.contextmenu_NonTruncateEmission) && ~isTruncated)

                            continue
                        end

                        update(specData, 'UserData:Emissions', 'Edit', 'IsTruncated', emissionIdx, ~isTruncated, app.mainApp.channelObj)
                        eventName = 'onEmissionTruncatedValueChanged';
                        eventStatus = true;

                    case app.contextmenu_PredictionWarning
                        update(specData, 'UserData:Emissions', 'Edit', 'AlertClassificationMismatch', emissionIdx)
                        eventName = '';
                        eventStatus = true;
                end
            end

            if eventStatus
                if ~isempty(eventName)
                    ipcMainMatlabCallsHandler(app.mainApp, app, eventName, app.Context)
                end

                applyInitialLayout(app)
            end

            requestVisibilityChange(app.progressDialog, 'hidden', 'locked')

        end

        % Menu selected function: contextmenu_ChannelEmission
        function onOpenPopupApp(app, event)
            
            if isempty(app.UITable.Selection)
                return
            end

            selectedRow = app.UITable.UserData.selectedRow;
            [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
            ipcMainMatlabOpenPopupApp(app.mainApp, app, 'EmissionChannel', app.Context, flowIdx, emissionIdx)

        end

        % Value changed function: Regulatory
        function onRegulatoryValueChanged(app, event)
            
            switch event.Value
                case 'Licenciada'
                    app.Regulatory.Value = event.PreviousValue;
    
                    msgWarning = [ ...
                        'A alteração da situação de uma emissão para "Licenciada" deve ser feita diretamente no ' ...
                        'campo "Estação / ID", inserindo o número da estação ou a ID do registro no RFDataHub. ' ...
                        'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro ' ...
                        'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.' ...
                    ];
                    ui.Dialog(app.UIFigure, 'warning', msgWarning);
                    return

                otherwise % case {'Licenciada UTE', 'Não licenciada', 'Não passível de licenciamento'}
                    userDescription = strtrim(app.AdditionalDescription.Value);
                    if isempty(userDescription)
                        app.Regulatory.Value = event.PreviousValue;
    
                        msgWarning = [ ...
                            'O campo "Informações adicionais" não pode ficar vazio para registros ' ...
                            'relacionados a uma estação "Licenciada UTE", "Não licenciada" ou "Não passível de licenciamento".<br><br>' ...
                            'Edite o campo "Informações adicionais" antes de alterar a "Situação".' ...
                        ];
                        ui.Dialog(app.UIFigure, 'warning', msgWarning);
                        return
                    end
            end

            updateRegulatoryStyle(app)
            onOthersParametersValueChanged(app, struct('Source', app.Compliance))
            
        end

        % Value changed function: StationID
        function onStationIdValueChanged(app, event)
            
            try
                app.StationID.Value = strtrim(app.StationID.Value);
                if isempty(regexp(app.StationID.Value, '^\#?\d+$', 'once'))
                    error([ ...
                        'Esse campo deve ser preenchido com o número da estação ou a ID do registro no RFDataHub. ' ...
                        'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro ' ...
                        'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.' ...
                    ])
                end
                
                selectedRow = app.UITable.UserData.selectedRow;
                [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
                specData = app.mainApp.specData(flowIdx);

                receiverLatitude  = specData.GPS.Latitude;
                receiverLongitude = specData.GPS.Longitude;
                stationInfo = model.RFDataHub.query(app.mainApp.rfDataHub, app.StationID.Value, receiverLatitude, receiverLongitude);

                % Caso a estação não conste em RFDataHub, o método query
                % chamado anteriormente retornará um erro. Mas caso
                % encontre, confirma-se com o usuário a edição.
                msgQuestion = util.HtmlTextGenerator.checkEmissionEditConfirmation(specData, emissionIdx, stationInfo);
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);

                switch userSelection
                    case 'Sim'
                        fetchEmissionUpdate(app, app.StationID, stationInfo)

                    case 'Não'
                        app.StationID.Value = num2str(specData.UserData.Emissions.Classification(emissionIdx).UserModified.Station);
                end

            catch ME
                app.StationID.Value = num2str(specData.UserData.Emissions.Classification(emissionIdx).UserModified.Station);
                ui.Dialog(app.UIFigure, 'warning', getReport(ME));
            end
            
        end

        % Value changed function: EmissionType
        function onEmissionTypeValueChanged(app, event)
            
            regulatoryValue = app.Regulatory.Value;
            userDescription = strtrim(app.AdditionalDescription.Value);

            if ~strcmp(regulatoryValue, 'Licenciada') && isempty(userDescription)
                app.EmissionType.Value = event.PreviousValue;

                msgWarning = [ ...
                    'O campo "Informações adicionais" não pode ficar vazio para registros ' ...
                    'relacionados a uma estação "Licenciada UTE", "Não licenciada" ou "Não passível de licenciamento".<br><br>' ...
                    'Edite o campo "Informações adicionais" antes de alterar o "Tipo de emissão".' ...
                ];
                ui.Dialog(app.UIFigure, 'warning', msgWarning);
                return
            end

            onOthersParametersValueChanged(app, struct('Source', app.EmissionType))
            
        end

        % Callback function: AdditionalDescription, ClassificationRefresh, 
        % ...and 2 other components
        function onOthersParametersValueChanged(app, event)
            
            switch event.Source
                case app.Compliance
                    updateComplianceStyle(app)

                case app.EmissionType
                    updateEmissionTypeStyle(app)

                case app.RiskLevel
                    updateRiskLevelStyle(app)

                case app.AdditionalDescription
                    userDescription = strtrim(app.AdditionalDescription.Value);
                    if isempty(userDescription) && ~strcmp(app.Regulatory.Value, 'Licenciada')
                        app.AdditionalDescription.Value = event.PreviousValue;
    
                        msgWarning = [ ...
                            'O campo "Informações adicionais" não pode ficar vazio para registros ' ...
                            'relacionados a uma estação "Licenciada UTE", "Não licenciada" ou "Não passível de licenciamento".' ...
                        ];
                        ui.Dialog(app.UIFigure, 'warning', msgWarning);
                        return
                    end

                    app.AdditionalDescription.Value = userDescription;
            end

            fetchEmissionUpdate(app, event.Source)

        end

        % Callback function: TXLatitude, TXLocationEditCancel, 
        % ...and 3 other components
        function onCoordinatesPanelButtonClicked(app, event)
            
            switch event.Source
                case app.TXLocationEditMode
                    app.TXLocationEditMode.UserData.status = ~app.TXLocationEditMode.UserData.status;
        
                    if app.TXLocationEditMode.UserData.status
                        updateCoordinatesPanelStyle(app, 'on')
                        focus(app.TXLatitude)        
                    else
                        updateCoordinatesPanelStyle(app, 'off')
                    end

                case app.TXLocationEditConfirm
                    fetchEmissionUpdate(app, event.Source)
                    updateCoordinatesPanelStyle(app, 'off')

                case app.TXLocationEditCancel
                    updateCoordinatesPanelStyle(app, 'off')

                case app.TXLatitude
                    focus(app.TXLongitude)

                case app.TXLongitude
                    focus(app.TXAntennaHeight)
            end

        end

        % Image clicked function: AxesPanButton, AxesRestoreViewButton
        function onAxesToolbarButtonClicked(app, event)
            
            switch event.Source
                case app.AxesRestoreViewButton
                    plot.axes.Interactivity.CustomRestoreViewFcn(app.UIAxes1, app.UIAxes2, app, false)
                
                case app.AxesPanButton
                    app.AxesPanButton.UserData.status = ~app.AxesPanButton.UserData.status;
                    if app.AxesPanButton.UserData.status
                        app.AxesPanButton.ImageSource = 'pan-filled-32px.png';
                    else
                        app.AxesPanButton.ImageSource = 'pan-32px.png';
                    end

                    plot.axes.Interactivity.CustomPanFcn(struct('Value', app.AxesPanButton.UserData.status), app.UIAxes1, app.UIAxes2);

                case app.TXLocationEditMode
                    app.TXLocationEditMode.UserData.status = ~app.TXLocationEditMode.UserData.status;
        
                    if app.TXLocationEditMode.UserData.status
                        updateCoordinatesPanelStyle(app, 'on')
                        focus(app.TXLatitude)        
                    else
                        updateCoordinatesPanelStyle(app, 'off')
                    end
            end

        end

        % Value changed function: tool_EmissionReportListLimit
        function onToolbarCheckBoxValueChanged(app, event)
            
            applyInitialLayout(app)
            
        end

        % Image clicked function: PanelBottomVisibility, 
        % ...and 3 other components
        function onToolbarButtonClicked(app, event)

            switch event.Source
                %---------------------------------------------------------%
                case app.tool_ShowGlobalExceptionList
                    exceptionGlobalList = app.mainApp.channelObj.Exception;

                    if isempty(exceptionGlobalList)
                        msgWarning  = 'Lista global de exceções, registrada no arquivo "ChannelLib.json", está vazia.';
                        iconWarning = 'warning';
                    else
                        globalEmissiontList = {};
                        for ii = 1:height(exceptionGlobalList)
                            globalEmissiontList{end+1} = sprintf('&emsp;•&thinsp;%s', sprintf('<b>%.3f MHz</b>: "%s"', exceptionGlobalList.FreqCenter(ii), exceptionGlobalList.Description{ii}));
                        end

                        msgWarning  = sprintf('%s\n%s', 'Lista global de exceções:', strjoin(globalEmissiontList, '\n'));
                        iconWarning = 'none';
                    end

                    ui.Dialog(app.UIFigure, iconWarning, msgWarning);

                %---------------------------------------------------------%
                case app.tool_ExportJSONFile
                    nameFormatMap = {'*.json', '(*.json)'};
                    defaultName   = appEngine.util.DefaultFileName(app.mainApp.General.fileFolder.userPath, [class.Constants.appName '_Emissions'], -1);
                    fileFullPath  = ui.Dialog(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
                    if isempty(fileFullPath)
                        return
                    end

                    if app.tool_EmissionReportListLimit.Value
                        flowIdxs = find(arrayfun(@(x) x.UserData.ReportInclude, app.mainApp.specData));
                    else
                        flowIdxs = 1:numel(app.mainApp.specData);
                    end

                    try
                        specData = app.mainApp.specData(flowIdxs);
                        jsonFileContent = util.exportSignalAnalysis(specData);
                        writematrix(jsonFileContent,  fileFullPath, 'FileType', 'text', 'QuoteStrings', 'none', 'WriteMode', 'overwrite', 'Encoding', 'UTF-8')

                    catch ME
                        ui.Dialog(app.UIFigure, 'error', ME.message);
                    end

                %---------------------------------------------------------%
                case app.PanelBottomVisibility
                    app.PanelBottomVisibility.UserData.status = ~app.PanelBottomVisibility.UserData.status;
                    
                    if app.PanelBottomVisibility.UserData.status
                        app.UITable.Layout.Row = 3;
                        set([app.AxesContainer, app.AxesToolbar], 'Visible', 'on')
                        app.RFLinkWarning.Visible = app.AxesContainer.UserData.rfLinkWarning;
                        app.PanelBottomVisibility.ImageSource = 'layout-panel.svg';

                        % Atualiza o plot, caso necessário...
                        selectedRow = app.UITable.UserData.selectedRow;
                        [flowIdx, emissionIdx] = getEmissionIndexes(app, selectedRow);
                        updatePlot(app, selectedRow, flowIdx, emissionIdx)

                    else
                        app.UITable.Layout.Row = [3 6];
                        set([app.AxesContainer, app.AxesToolbar, app.RFLinkWarning], 'Visible', 'off')
                        app.PanelBottomVisibility.ImageSource = 'layout-panel-off.svg';
                    end

                %---------------------------------------------------------%
                case app.PanelLeftVisibility
                    if app.SelectedEmissionGrid.Visible
                        app.SelectedEmissionGrid.Visible = 0;
                        app.Document.Layout.Column = [2, numel(app.GridLayout.ColumnWidth)-2];
                        app.PanelLeftVisibility.ImageSource = 'layout-sidebar-right-off.svg';
                    else
                        app.SelectedEmissionGrid.Visible = 1;
                        app.Document.Layout.Column = 2;
                        app.PanelLeftVisibility.ImageSource = 'layout-sidebar-right.svg';
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Document = uigridlayout(app.GridLayout);
            app.UITableIcon = uiimage(app.Document);
            app.UITableIcon.ImageSource = 'Detection_18.png';
            app.UITableLabel = uilabel(app.Document);
            app.UITableLabel.Text = 'EMISSÕES RELACIONADAS AOS FLUXOS ESPECTRAIS';
            app.AxesContainer = uipanel(app.Document);
            app.AxesToolbar = uigridlayout(app.Document);
            app.AxesRestoreViewButton = uiimage(app.AxesToolbar);
            app.AxesRestoreViewButton.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.AxesRestoreViewButton.ImageSource = 'Home_18.png';
            app.AxesPanButton = uiimage(app.AxesToolbar);
            app.AxesPanButton.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarButtonClicked, true);
            app.AxesPanButton.ImageSource = 'pan-32px.png';
            app.RFLinkWarning = uiimage(app.Document);
            app.RFLinkWarning.Visible = 'off';
            app.RFLinkWarning.ImageSource = 'warning.svg';
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'FREQUÊNCIA|(MHz)'; 'FREQUÊNCIA|CANAL (MHz)'; 'LARGURA|(kHz)'; 'NÍVEL|(Δ)'; 'OCUPAÇÃO|(Δ)'; 'PREDIÇÃO'; 'PROVÁVEL EMISSOR|(Entidade+Fistel+Serviço+Estação+Localidade)'; 'DISTÂNCIA|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @onUITableSelectionChanged, true);
            app.SelectedEmissionGrid = uigridlayout(app.GridLayout);
            app.SelectedEmissionIcon = uiimage(app.SelectedEmissionGrid);
            app.SelectedEmissionIcon.ImageSource = 'selected-row-16px.png';
            app.SelectedEmissionLabel = uilabel(app.SelectedEmissionGrid);
            app.SelectedEmissionLabel.Text = 'EMISSÃO SELECIONADA';
            app.SelectedEmissionPanel = uipanel(app.SelectedEmissionGrid);
            app.SelectedEmissionPanelGrid = uigridlayout(app.SelectedEmissionPanel);
            app.EmissionTitle = uilabel(app.SelectedEmissionPanelGrid);
            app.EmissionTitle.Interpreter = 'html';
            app.EmissionTitle.Text = '';
            app.RegulatoryLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.RegulatoryLabel.Text = 'Situação:';
            app.Regulatory = uidropdown(app.SelectedEmissionPanelGrid);
            app.Regulatory.Items = {'Licenciada', 'Licenciada UTE', 'Não licenciada', 'Não passível de licenciamento'};
            app.Regulatory.ValueChangedFcn = createCallbackFcn(app, @onRegulatoryValueChanged, true);
            app.Regulatory.Value = 'Licenciada';
            app.StationIDLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.StationIDLabel.Text = 'Estação / Id:';
            app.StationID = uieditfield(app.SelectedEmissionPanelGrid, 'text');
            app.StationID.ValueChangedFcn = createCallbackFcn(app, @onStationIdValueChanged, true);
            app.EmissionTypeLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.EmissionTypeLabel.Text = 'Tipo de emissão:';
            app.EmissionType = uidropdown(app.SelectedEmissionPanelGrid);
            app.EmissionType.Items = {'Fundamental', 'Harmônico de fundamental', 'Produto de intermodulação', 'Espúrio', 'Não identificado', 'Não se manifestou', 'Pendente identificação'};
            app.EmissionType.ValueChangedFcn = createCallbackFcn(app, @onEmissionTypeValueChanged, true);
            app.EmissionType.Value = 'Pendente identificação';
            app.ComplianceLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.ComplianceLabel.Text = 'Indício de irregularidade?';
            app.Compliance = uidropdown(app.SelectedEmissionPanelGrid);
            app.Compliance.Items = {'Não', 'Sim'};
            app.Compliance.ValueChangedFcn = createCallbackFcn(app, @onOthersParametersValueChanged, true);
            app.Compliance.Value = 'Não';
            app.RiskLevelLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.RiskLevelLabel.Text = 'Potencial lesivo:';
            app.RiskLevelIcon = uilabel(app.SelectedEmissionPanelGrid);
            app.RiskLevelIcon.Visible = 'off';
            app.RiskLevelIcon.Text = '❗';
            app.RiskLevel = uidropdown(app.SelectedEmissionPanelGrid);
            app.RiskLevel.Items = {'-', 'Baixo', 'Médio', 'Alto'};
            app.RiskLevel.ValueChangedFcn = createCallbackFcn(app, @onOthersParametersValueChanged, true);
            app.RiskLevel.Value = '-';
            app.TXLocationPanelLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.TXLocationPanelLabel.Text = 'Características de instalação:';
            app.TXLocationEditionGrid = uigridlayout(app.SelectedEmissionPanelGrid);
            app.TXLocationEditMode = uiimage(app.TXLocationEditionGrid);
            app.TXLocationEditMode.ImageClickedFcn = createCallbackFcn(app, @onCoordinatesPanelButtonClicked, true);
            app.TXLocationEditMode.ImageSource = 'Edit_32.png';
            app.TXLocationEditConfirm = uiimage(app.TXLocationEditionGrid);
            app.TXLocationEditConfirm.ImageClickedFcn = createCallbackFcn(app, @onCoordinatesPanelButtonClicked, true);
            app.TXLocationEditConfirm.Enable = 'off';
            app.TXLocationEditConfirm.ImageSource = 'Ok_32Green.png';
            app.TXLocationEditCancel = uiimage(app.TXLocationEditionGrid);
            app.TXLocationEditCancel.ImageClickedFcn = createCallbackFcn(app, @onCoordinatesPanelButtonClicked, true);
            app.TXLocationEditCancel.Enable = 'off';
            app.TXLocationEditCancel.ImageSource = 'Delete_32Red.png';
            app.TXLocationPanel = uipanel(app.SelectedEmissionPanelGrid);
            app.TXLocationGrid = uigridlayout(app.TXLocationPanel);
            app.TXLatitudeLabel = uilabel(app.TXLocationGrid);
            app.TXLatitudeLabel.Text = 'Latitude:';
            app.TXLatitude = uieditfield(app.TXLocationGrid, 'numeric');
            app.TXLatitude.Limits = [-90 90];
            app.TXLatitude.ValueDisplayFormat = '%.6f';
            app.TXLatitude.ValueChangedFcn = createCallbackFcn(app, @onCoordinatesPanelButtonClicked, true);
            app.TXLatitude.Editable = 'off';
            app.TXLatitude.Value = -1;
            app.TXLongitudeLabel = uilabel(app.TXLocationGrid);
            app.TXLongitudeLabel.Text = 'Longitude:';
            app.TXLongitude = uieditfield(app.TXLocationGrid, 'numeric');
            app.TXLongitude.Limits = [-180 180];
            app.TXLongitude.ValueDisplayFormat = '%.6f';
            app.TXLongitude.ValueChangedFcn = createCallbackFcn(app, @onCoordinatesPanelButtonClicked, true);
            app.TXLongitude.Editable = 'off';
            app.TXLongitude.Value = -1;
            app.TXAntennaHeightLabel = uilabel(app.TXLocationGrid);
            app.TXAntennaHeightLabel.Text = 'Altura (m):';
            app.TXAntennaHeight = uieditfield(app.TXLocationGrid, 'numeric');
            app.TXAntennaHeight.Limits = [0 Inf];
            app.TXAntennaHeight.ValueDisplayFormat = '%.1f';
            app.TXAntennaHeight.Editable = 'off';
            app.TXAntennaHeight.Value = 10;
            app.AdditionalDescriptionLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.AdditionalDescriptionLabel.Text = 'Informações adicionais:';
            app.AdditionalDescription = uieditfield(app.SelectedEmissionPanelGrid, 'text');
            app.AdditionalDescription.ValueChangedFcn = createCallbackFcn(app, @onOthersParametersValueChanged, true);
            app.LOGLabel = uilabel(app.SelectedEmissionPanelGrid);
            app.LOGLabel.Text = 'OCORRÊNCIAS';
            app.LOG = uilabel(app.SelectedEmissionPanelGrid);
            app.LOG.Interpreter = 'html';
            app.LOG.Text = '';
            app.ClassificationRefresh = uiimage(app.SelectedEmissionPanelGrid);
            app.ClassificationRefresh.ImageClickedFcn = createCallbackFcn(app, @onOthersParametersValueChanged, true);
            app.ClassificationRefresh.Visible = 'off';
            app.ClassificationRefresh.ImageSource = 'Refresh_18.png';
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_EmissionReportListLimit = uicheckbox(app.Toolbar);
            app.tool_EmissionReportListLimit.ValueChangedFcn = createCallbackFcn(app, @onToolbarCheckBoxValueChanged, true);
            app.tool_EmissionReportListLimit.Text = 'Exibir apenas emissões dos fluxos espectrais selecionados para o relatório.';
            app.tool_ExportJSONFile = uiimage(app.Toolbar);
            app.tool_ExportJSONFile.ImageClickedFcn = createCallbackFcn(app, @onToolbarButtonClicked, true);
            app.tool_ExportJSONFile.ImageSource = 'Export_16.png';
            app.tool_ShowGlobalExceptionList = uiimage(app.Toolbar);
            app.tool_ShowGlobalExceptionList.ImageClickedFcn = createCallbackFcn(app, @onToolbarButtonClicked, true);
            app.tool_ShowGlobalExceptionList.ImageSource = 'exceptionList_18.png';
            app.tool_Separator = uiimage(app.Toolbar);
            app.tool_Separator.Enable = 'off';
            app.tool_Separator.ImageSource = 'LineV.svg';
            app.PanelBottomVisibility = uiimage(app.Toolbar);
            app.PanelBottomVisibility.ImageClickedFcn = createCallbackFcn(app, @onToolbarButtonClicked, true);
            app.PanelBottomVisibility.ImageSource = 'layout-panel.svg';
            app.PanelLeftVisibility = uiimage(app.Toolbar);
            app.PanelLeftVisibility.ImageClickedFcn = createCallbackFcn(app, @onToolbarButtonClicked, true);
            app.PanelLeftVisibility.ImageSource = 'layout-sidebar-right.svg';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'auxApp.winSignalAnalysis';
            app.contextmenu_TruncateItem = uimenu(app.ContextMenu);
            app.contextmenu_TruncateItem.Text = '✏️ Editar';
            app.contextmenu_ChannelEmission = uimenu(app.contextmenu_TruncateItem);
            app.contextmenu_ChannelEmission.MenuSelectedFcn = createCallbackFcn(app, @onOpenPopupApp, true);
            app.contextmenu_ChannelEmission.Text = 'Canal';
            app.contextmenu_TruncateEmission = uimenu(app.contextmenu_TruncateItem);
            app.contextmenu_TruncateEmission.MenuSelectedFcn = createCallbackFcn(app, @onUITableContextMenuClicked, true);
            app.contextmenu_TruncateEmission.Separator = 'on';
            app.contextmenu_TruncateEmission.Text = 'Truncar frequência';
            app.contextmenu_NonTruncateEmission = uimenu(app.contextmenu_TruncateItem);
            app.contextmenu_NonTruncateEmission.MenuSelectedFcn = createCallbackFcn(app, @onUITableContextMenuClicked, true);
            app.contextmenu_NonTruncateEmission.Text = 'Não truncar';
            app.contextmenu_PredictionWarning = uimenu(app.contextmenu_TruncateItem);
            app.contextmenu_PredictionWarning.MenuSelectedFcn = createCallbackFcn(app, @onUITableContextMenuClicked, true);
            app.contextmenu_PredictionWarning.Separator = 'on';
            app.contextmenu_PredictionWarning.Text = 'Predição';
            app.contextmenu_DeleteEmission = uimenu(app.ContextMenu);
            app.contextmenu_DeleteEmission.MenuSelectedFcn = createCallbackFcn(app, @onUITableContextMenuClicked, true);
            app.contextmenu_DeleteEmission.Separator = 'on';
            app.contextmenu_DeleteEmission.Text = '❌ Excluir';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSignalAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end